classdef InputParametersAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionalInputParametersPanel    matlab.ui.container.Panel
        CartInterp                      matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        CreateLog                       matlab.ui.control.CheckBox
        DataCast                        matlab.ui.control.CheckBox
        DataRecast                      matlab.ui.control.CheckBox
        DisplayMask                     matlab.ui.control.CheckBox
        LogScale                        matlab.ui.control.CheckBox
        MeshPlot                        matlab.ui.control.CheckBox
        MovieArgs                       matlab.ui.control.CheckBox
        RecordMovie                     matlab.ui.control.CheckBox
        MovieName                       matlab.ui.control.CheckBox
        MovieType                       matlab.ui.control.CheckBox
        PlotFreq                        matlab.ui.control.CheckBox
        PlotLayout                      matlab.ui.control.CheckBox
        PlotPML                         matlab.ui.control.CheckBox
        PlotScale                       matlab.ui.control.CheckBox
        PlotSim                         matlab.ui.control.CheckBox
        PMLAlpha                        matlab.ui.control.CheckBox
        PMLInside                       matlab.ui.control.CheckBox
        PMLSize                         matlab.ui.control.CheckBox
        Smooth                          matlab.ui.control.CheckBox
        SaveToDisk                      matlab.ui.control.CheckBox
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_7                      matlab.ui.control.DropDown
        DropDown_8                      matlab.ui.control.DropDown
        DropDown_11                     matlab.ui.control.DropDown
        DropDown_13                     matlab.ui.control.DropDown
        DropDown_14                     matlab.ui.control.DropDown
        DropDown_15                     matlab.ui.control.DropDown
        DropDown_17                     matlab.ui.control.DropDown
        DropDown_19                     matlab.ui.control.DropDown
        loadButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        plotFreq                        matlab.ui.control.Spinner
        minSpinnerLabel                 matlab.ui.control.Label
        minSpinner                      matlab.ui.control.Spinner
        maxSpinnerLabel                 matlab.ui.control.Label
        maxSpinner                      matlab.ui.control.Spinner
        Spinner_2                       matlab.ui.control.Spinner
        Spinner_3                       matlab.ui.control.Spinner
        EditField_3                     matlab.ui.control.EditField
        sourcep0Label                   matlab.ui.control.Label
        sourcep0DropDown                matlab.ui.control.DropDown
        mediumsound_speedDropDownLabel  matlab.ui.control.Label
        mediumsound_speedDropDown       matlab.ui.control.DropDown
        mediumdensityDropDownLabel      matlab.ui.control.Label
        mediumdensityDropDown           matlab.ui.control.DropDown
        ExportButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp 
        Args 
        Mask % sensor.mask
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.UIFigure.Name = 'Optional input parameters for k-wave PA signal simulation';
            app.CallingApp = MainApp;
        end

        % Value changed function: CartInterp
        function CartInterpValueChanged(app, event)
            value = app.CartInterp.Value;
            if value
                app.DropDown.Visible = 'On';
            else
                app.DropDown.Visible = 'Off';
                app.DropDown.Value = 'linear';
            end
        end

        % Value changed function: CreateLog
        function CreateLogValueChanged(app, event)
            value = app.CreateLog.Value;
            if value
                app.DropDown_2.Visible = 'On';
            else
                app.DropDown_2.Visible = 'Off';
                app.DropDown_2.Value = 'false';
            end
        end

        % Value changed function: DataCast
        function DataCastValueChanged(app, event)
            value = app.DataCast.Value;
            if value
                app.DropDown_3.Visible = 'On';
            else
                app.DropDown_3.Visible = 'Off';
                app.DropDown_3.Value = 'off';
            end
        end

        % Value changed function: DataRecast
        function DataRecastValueChanged(app, event)
            value = app.DataRecast.Value;
            if value
                app.DropDown_4.Visible = 'On';
            else
                app.DropDown_4.Visible = 'Off';
                app.DropDown_4.Value = 'false';
            end
        end

        % Value changed function: DisplayMask
        function DisplayMaskValueChanged(app, event)
            value = app.DisplayMask.Value;
            if value
                app.DropDown_5.Visible = 'On';
            else
                app.DropDown_5.Visible = 'Off';
                app.DropDown_5.Value = 'sensor.mask';
                app.loadButton.Visible = 'Off';
            end
        end

        % Value changed function: LogScale
        function LogScaleValueChanged(app, event)
            value = app.LogScale.Value;
            if value
                app.DropDown_6.Visible = 'On';
            else
                app.DropDown_6.Visible = 'Off';
                app.DropDown_6.Value = 'false';
            end
        end

        % Value changed function: MeshPlot
        function MeshPlotValueChanged(app, event)
            value = app.MeshPlot.Value;
            if value
                app.DropDown_7.Visible = 'On';
            else
                app.DropDown_7.Visible = 'Off';
                app.DropDown_7.Value = 'false';
            end
        end

        % Value changed function: RecordMovie
        function RecordMovieValueChanged(app, event)
            value = app.RecordMovie.Value;
            if value
                app.DropDown_8.Visible = 'On';
            else
                app.DropDown_8.Visible = 'Off';
                app.DropDown_8.Value = 'false';
            end
        end

        % Value changed function: MovieArgs
        function MovieArgsValueChanged(app, event)
            value = app.MovieArgs.Value;
            if value
                app.EditField.Visible = 'On';
            else
                app.EditField.Visible = 'Off';
            end
        end

        % Value changed function: MovieName
        function MovieNameValueChanged(app, event)
            value = app.MovieName.Value;
            if value
                app.EditField_2.Visible = 'On';
            else
                app.EditField_2.Visible = 'Off';
            end
        end

        % Value changed function: MovieType
        function MovieTypeValueChanged(app, event)
            value = app.MovieType.Value;
            if value
                app.DropDown_11.Visible = 'On';
            else
                app.DropDown_11.Visible = 'Off';
                app.DropDown_11.Value = 'frame';
            end
        end

        % Value changed function: PlotFreq
        function PlotFreqValueChanged(app, event)
            value = app.PlotFreq.Value;
            if value
                app.plotFreq.Visible = 'On';
            else
                app.plotFreq.Visible = 'Off';
                app.plotFreq.Value = 10;
            end
        end

        % Value changed function: PlotLayout
        function PlotLayoutValueChanged(app, event)
            value = app.PlotLayout.Value;
            if value
                app.DropDown_13.Visible = 'On';
            else
                app.DropDown_13.Visible = 'Off';
                app.DropDown_13.Value = 'false';
            end
        end

        % Value changed function: PlotPML
        function PlotPMLValueChanged(app, event)
            value = app.PlotPML.Value;
            if value
                app.DropDown_14.Visible = 'On';
            else
                app.DropDown_14.Visible = 'Off';
                app.DropDown_14.Value = 'false';
            end
        end

        % Value changed function: PlotScale
        function PlotScaleValueChanged(app, event)
            value = app.PlotScale.Value;
            if value
                app.DropDown_15.Visible = 'On';
            else
                app.DropDown_15.Visible = 'Off';
                app.DropDown_15.Value = 'auto';
                app.maxSpinner.Visible = 'Off';
                app.minSpinner.Visible = 'Off';
            end
        end

        % Value changed function: PlotSim
        function PlotSimValueChanged(app, event)
            value = app.PlotSim.Value;
            if value
                app.DropDown_17.Visible = 'On';
            else
                app.DropDown_17.Visible = 'Off';
                app.DropDown_17.Value = 'true';
            end
        end

        % Value changed function: PMLAlpha
        function PMLAlphaValueChanged(app, event)
            value = app.PMLAlpha.Value;
            if value
                app.Spinner_2.Visible = 'On';
            else
                app.Spinner_2.Visible = 'Off';
                app.Spinner_2.Value = 2;
            end
        end

        % Value changed function: PMLInside
        function PMLInsideValueChanged(app, event)
            value = app.PMLInside.Value;
            if value
                app.DropDown_19.Visible = 'On';
            else
                app.DropDown_19.Visible = 'Off';
                app.DropDown_19.Value = 'true';
            end
        end

        % Value changed function: PMLSize
        function PMLSizeValueChanged(app, event)
            value = app.PMLSize.Value;
            if value
                app.Spinner_3.Visible = 'On';
            else
                app.Spinner_3.Visible = 'Off';
                app.Spinner_3.Value = 20;
            end
        end

        % Value changed function: SaveToDisk
        function SaveToDiskValueChanged(app, event)
            value = app.SaveToDisk.Value;
            if value
                app.EditField_3.Visible = 'On';
            else
                app.EditField_3.Visible = 'Off';
%                 app.EditField_3.Value = '';
            end
        end

        % Value changed function: Smooth
        function SmoothValueChanged(app, event)
            value = app.Smooth.Value;
            if value
                app.mediumdensityDropDown.Visible = 'On';
                app.mediumsound_speedDropDown.Visible = 'On';
                app.sourcep0DropDown.Visible = 'On';
            else
                app.mediumdensityDropDown.Visible = 'Off';
                app.mediumdensityDropDown.Value = 'false';
                app.mediumsound_speedDropDown.Visible = 'Off';
                app.mediumsound_speedDropDown.Value = 'false';
                app.sourcep0DropDown.Visible = 'Off';
                app.sourcep0DropDown.Value = 'true';
            end
        end

        % Value changed function: DropDown_15
        function DropDown_15ValueChanged(app, event)
            value = app.DropDown_15.Value;
            if strcmp(value,'set')
                app.maxSpinner.Visible = 'On';
                app.minSpinner.Visible = 'On';
            else
                app.maxSpinner.Visible = 'Off';
                app.minSpinner.Visible = 'Off';
                app.maxSpinner.Value = 1;
                app.minSpinner.Value = -1;
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            if strcmp(value,'load')
                app.loadButton.Visible = 'On';
            else
                app.loadButton.Visible = 'Off';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.Args = {};
            if app.CartInterp.Value
                if strcmp(app.DropDown.Value,'linear')
                    app.Args = cat(2, app.Args, 'CartInterp','linear');
                else
                    app.Args = cat(2, app.Args, 'CartInterp','nearest');
                end
            end
            
            if app.CreateLog.Value
                if strcmp(app.DropDown_2.Value,'false')
                    app.Args = cat(2, app.Args, 'CreateLog',false);
                else
                    app.Args = cat(2, app.Args, 'CreateLog',true);
                end
            end
            
            if app.DataCast.Value
                value = app.DropDown_3.Value;
                switch value
                    case 'off'
                        app.Args = cat(2, app.Args, 'DataCast','off');
                    case 'single'
                        app.Args = cat(2, app.Args, 'DataCast','single');
                    case 'gpuArray-single'
                        app.Args = cat(2, app.Args, 'DataCast','gpuArray-single');
                    case 'gpuArray-double'
                        app.Args = cat(2, app.Args, 'DataCast','gpuArray-double');
                end
            end
            
            if app.DataRecast.Value
                if strcmp(app.DropDown_4.Value,'false')
                    app.Args = cat(2, app.Args, 'DataRecast',false);
                else
                    app.Args = cat(2, app.Args, 'DataRecast',true);
                end
            end
            
            if app.DisplayMask.Value
                app.Args = cat(2, app.Args, 'DisplayMask',app.Mask);
            end
            
            if app.LogScale.Value
                if strcmp(app.DropDown_6.Value,'false')
                    app.Args = cat(2, app.Args, 'LogScale',false);
                else
                    app.Args = cat(2, app.Args, 'LogScale',true);
                end
            end
            
            if app.MeshPlot.Value
                if strcmp(app.DropDown_7.Value,'false')
                    app.Args = cat(2, app.Args, 'CartInterp',false);
                else
                    app.Args = cat(2, app.Args, 'CartInterp',true);
                end
            end
            
            if app.RecordMovie.Value
                if strcmp(app.DropDown_8.Value,'false')
                    app.Args = cat(2, app.Args, 'RecordMovie',false);
                else
                    app.Args = cat(2, app.Args, 'RecordMovie',true);
                end
            end
            
            if app.MovieArgs.Value
                app.Args = cat(2, app.Args, 'MovieArgs',{app.EditField.Value});
            end
            
            if app.MovieName.Value
                if ~app.EditField_2.Value
                    app.Args = cat(2, app.Args, 'MovieName',app.EditField_2.Value);
                end
            end
            
            if app.MovieType.Value
                if strcmp(app.DropDown_11.Value,'frame')
                    app.Args = cat(2, app.Args, 'MovieType','frame');
                else
                    app.Args = cat(2, app.Args, 'MovieType','image');
                end
            end
            
            if app.PlotFreq.Value
                app.Args = cat(2, app.Args, 'PlotFreq',app.plotFreq.Value);
            end
            
            if app.PlotLayout.Value
                if strcmp(app.DropDown_13.Value,'false')
                    app.Args = cat(2, app.Args, 'PlotLayout',false);
                else
                    app.Args = cat(2, app.Args, 'PlotLayout',true);
                end
            end
            
            if app.PlotPML.Value
                if strcmp(app.DropDown_14.Value,'false')
                    app.Args = cat(2, app.Args, 'PlotPML',false);
                else
                    app.Args = cat(2, app.Args, 'PlotPML',true);
                end
            end
            
            if app.PlotScale.Value
                if strcmp(app.DropDown.Value,'auto')
                else
                    app.Args = cat(2, app.Args, 'PlotScale',[app.minSpinner.Value,app.maxSpinner.Value]);
                end
            end
            
            if app.PlotSim.Value
                if strcmp(app.DropDown_17.Value,'true')
                    app.Args = cat(2, app.Args, 'PlotSim',true);
                else
                    app.Args = cat(2, app.Args, 'PlotSim',false);
                end
            end
            
            if app.PMLAlpha.Value
                app.Args = cat(2, app.Args, 'PMLAlpha',app.Spinner_2.Value);
            end
            
            if app.PMLInside.Value
                if strcmp(app.DropDown_19.Value,'true')
                    app.Args = cat(2, app.Args, 'PMLInside',true);
                else
                    app.Args = cat(2, app.Args, 'PMLInside',false);
                end
            end
            
            if app.PMLSize.Value
                app.Args = cat(2, app.Args, 'PMLSize',app.Spinner_3.Value);
            end
            
            if app.SaveToDisk.Value
                app.Args = cat(2, app.Args, 'SaveToDisk',app.EditField_3.Value);
            end
            
            if app.Smooth.Value
                app.Args = cat(2, app.Args, 'Smooth',[strcmp(app.sourcep0DropDown.Value,'true'),strcmp(app.mediumsound_speedDropDown.Value,'true'),strcmp(app.mediumdensityDropDown.Value,'true')]);
            end
            % Call main app's public function
            UpdateArgs(app.CallingApp, app.Args);
            
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'PatLab_Logo_1.png';
            app.OptionalInputParametersPanel = uipanel(app.UIFigure);
            app.OptionalInputParametersPanel.Title = 'Optional Input Parameters:';
            app.CartInterp = uicheckbox(app.OptionalInputParametersPanel);
            app.CartInterp.ValueChangedFcn = createCallbackFcn(app, @CartInterpValueChanged, true);
            app.CartInterp.Tooltip = {''};
            app.CartInterp.Text = 'Interpolation mode used to extract the pressure when a Cartesian sensor mask is given.';
            app.DropDown = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown.Items = {'linear', 'nearest'};
            app.DropDown.Visible = 'off';
            app.DropDown.Tooltip = {''};
            app.DropDown.Value = 'linear';
            app.CreateLog = uicheckbox(app.OptionalInputParametersPanel);
            app.CreateLog.ValueChangedFcn = createCallbackFcn(app, @CreateLogValueChanged, true);
            app.CreateLog.Tooltip = {''};
            app.CreateLog.Text = 'Command line output is saved using the diary function with a date and time stamped filename.';
            app.DataCast = uicheckbox(app.OptionalInputParametersPanel);
            app.DataCast.ValueChangedFcn = createCallbackFcn(app, @DataCastValueChanged, true);
            app.DataCast.Tooltip = {''};
            app.DataCast.Text = 'Data type that variables are cast to before computation.';
            app.DataRecast = uicheckbox(app.OptionalInputParametersPanel);
            app.DataRecast.ValueChangedFcn = createCallbackFcn(app, @DataRecastValueChanged, true);
            app.DataRecast.Tooltip = {''};
            app.DataRecast.Text = 'Output data is cast back to double precision.';
            app.DisplayMask = uicheckbox(app.OptionalInputParametersPanel);
            app.DisplayMask.ValueChangedFcn = createCallbackFcn(app, @DisplayMaskValueChanged, true);
            app.DisplayMask.Tooltip = {''};
            app.DisplayMask.Text = 'Binary matrix overlayed onto the animated simulation display.';
            app.LogScale = uicheckbox(app.OptionalInputParametersPanel);
            app.LogScale.ValueChangedFcn = createCallbackFcn(app, @LogScaleValueChanged, true);
            app.LogScale.Tooltip = {''};
            app.LogScale.Text = 'Pressure field is log compressed before display.';
            app.MeshPlot = uicheckbox(app.OptionalInputParametersPanel);
            app.MeshPlot.ValueChangedFcn = createCallbackFcn(app, @MeshPlotValueChanged, true);
            app.MeshPlot.Tooltip = {'2D Simulations Only'; ''};
            app.MeshPlot.Text = 'Mesh is used in place of imagesc to plot the pressure field.';
            app.MovieArgs = uicheckbox(app.OptionalInputParametersPanel);
            app.MovieArgs.ValueChangedFcn = createCallbackFcn(app, @MovieArgsValueChanged, true);
            app.MovieArgs.Tooltip = {''};
            app.MovieArgs.Text = 'Settings for movie2avi. Parameters must be given as {param, value, ...} pairs within a cell array.';
            app.RecordMovie = uicheckbox(app.OptionalInputParametersPanel);
            app.RecordMovie.ValueChangedFcn = createCallbackFcn(app, @RecordMovieValueChanged, true);
            app.RecordMovie.Tooltip = {''};
            app.RecordMovie.Text = 'Displayed image frames are captured and stored as a movie using movie2avi.';
            app.MovieName = uicheckbox(app.OptionalInputParametersPanel);
            app.MovieName.ValueChangedFcn = createCallbackFcn(app, @MovieNameValueChanged, true);
            app.MovieName.Tooltip = {''};
            app.MovieName.Text = 'Name of the movie produced.';
            app.MovieType = uicheckbox(app.OptionalInputParametersPanel);
            app.MovieType.ValueChangedFcn = createCallbackFcn(app, @MovieTypeValueChanged, true);
            app.MovieType.Tooltip = {'2D Simulations Only'};
            app.MovieType.Text = 'Image frames are captured using getframe (`frame'') or im2frame (`image'').';
            app.PlotFreq = uicheckbox(app.OptionalInputParametersPanel);
            app.PlotFreq.ValueChangedFcn = createCallbackFcn(app, @PlotFreqValueChanged, true);
            app.PlotFreq.Tooltip = {''};
            app.PlotFreq.Text = 'Number of iterations which must pass before the simulation plot is updated.';
            app.PlotLayout = uicheckbox(app.OptionalInputParametersPanel);
            app.PlotLayout.ValueChangedFcn = createCallbackFcn(app, @PlotLayoutValueChanged, true);
            app.PlotLayout.Tooltip = {''};
            app.PlotLayout.Text = 'Plots are produced of the initial simulation layout (initial pressure, sound speed,density).';
            app.PlotPML = uicheckbox(app.OptionalInputParametersPanel);
            app.PlotPML.ValueChangedFcn = createCallbackFcn(app, @PlotPMLValueChanged, true);
            app.PlotPML.Tooltip = {''};
            app.PlotPML.Text = 'Perfectly matched layer is shown in the simulation plots. ';
            app.PlotScale = uicheckbox(app.OptionalInputParametersPanel);
            app.PlotScale.ValueChangedFcn = createCallbackFcn(app, @PlotScaleValueChanged, true);
            app.PlotScale.Tooltip = {''};
            app.PlotScale.Text = '[min, max] values used to control the scaling for imagesc (visualisation).';
            app.PlotSim = uicheckbox(app.OptionalInputParametersPanel);
            app.PlotSim.ValueChangedFcn = createCallbackFcn(app, @PlotSimValueChanged, true);
            app.PlotSim.Tooltip = {''};
            app.PlotSim.Text = 'Simulation iterations are progressively plotted.';
            app.PMLAlpha = uicheckbox(app.OptionalInputParametersPanel);
            app.PMLAlpha.ValueChangedFcn = createCallbackFcn(app, @PMLAlphaValueChanged, true);
            app.PMLAlpha.Tooltip = {''};
            app.PMLAlpha.Text = 'Absorption within the perfectly matched layer in Nepers per metre.';
            app.PMLInside = uicheckbox(app.OptionalInputParametersPanel);
            app.PMLInside.ValueChangedFcn = createCallbackFcn(app, @PMLInsideValueChanged, true);
            app.PMLInside.Tooltip = {''};
            app.PMLInside.Text = 'Perfectly matched layer is inside or outside the grid. ';
            app.PMLSize = uicheckbox(app.OptionalInputParametersPanel);
            app.PMLSize.ValueChangedFcn = createCallbackFcn(app, @PMLSizeValueChanged, true);
            app.PMLSize.Tooltip = {''};
            app.PMLSize.Text = 'Size of the perfectly matched layer in grid points.';
            app.Smooth = uicheckbox(app.OptionalInputParametersPanel);
            app.Smooth.ValueChangedFcn = createCallbackFcn(app, @SmoothValueChanged, true);
            app.Smooth.Tooltip = {''};
            app.Smooth.Text = 'Controlling to use smooth before computation.';
            app.SaveToDisk = uicheckbox(app.OptionalInputParametersPanel);
            app.SaveToDisk.ValueChangedFcn = createCallbackFcn(app, @SaveToDiskValueChanged, true);
            app.SaveToDisk.Tooltip = {'3D Simulations Only'};
            app.SaveToDisk.Text = 'Save to disk. String containing a filename (including pathname if required).';
            app.DropDown_2 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_2.Items = {'false', 'true'};
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Tooltip = {''};
            app.DropDown_2.Value = 'false';
            app.DropDown_3 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_3.Items = {'off', 'single', 'gpuArray-single', 'gpuArray-double'};
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Tooltip = {'GPU inputs re-'; 'quire the MATLAB Parallel Comput-'; 'ing Toolbox R2012a or later.'};
            app.DropDown_3.Value = 'off';
            app.DropDown_4 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_4.Items = {'false', 'true'};
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Tooltip = {'If set to false, sensor_data will'; 'be returned in the data format set using the `DataCast'' option.'};
            app.DropDown_4.Value = 'false';
            app.DropDown_5 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_5.Items = {'sensor.mask'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Visible = 'off';
            app.DropDown_5.Tooltip = {''};
            app.DropDown_5.Value = 'sensor.mask';
            app.DropDown_6 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_6.Items = {'false', 'true'};
            app.DropDown_6.Visible = 'off';
            app.DropDown_6.Tooltip = {''};
            app.DropDown_6.Value = 'false';
            app.DropDown_7 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_7.Items = {'false', 'true'};
            app.DropDown_7.Visible = 'off';
            app.DropDown_7.Tooltip = {'When `MeshPlot'' is'; 'set to true, the default display mask'; 'is set to `off'''};
            app.DropDown_7.Value = 'false';
            app.DropDown_8 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_8.Items = {'false', 'true'};
            app.DropDown_8.Visible = 'off';
            app.DropDown_8.Tooltip = {''};
            app.DropDown_8.Value = 'false';
            app.DropDown_11 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_11.Items = {'frame', 'image'};
            app.DropDown_11.Visible = 'off';
            app.DropDown_11.Tooltip = {'If set to `image'', the size'; 'of the movie will depend on the size of'; 'the simulation grid.'};
            app.DropDown_11.Value = 'frame';
            app.DropDown_13 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_13.Items = {'false', 'true'};
            app.DropDown_13.Visible = 'off';
            app.DropDown_13.Tooltip = {''};
            app.DropDown_13.Value = 'false';
            app.DropDown_14 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_14.Items = {'false', 'true'};
            app.DropDown_14.Visible = 'off';
            app.DropDown_14.Tooltip = {'If set to false, the'; 'PML is not displayed.'};
            app.DropDown_14.Value = 'false';
            app.DropDown_15 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_15.Items = {'auto', 'set'};
            app.DropDown_15.ValueChangedFcn = createCallbackFcn(app, @DropDown_15ValueChanged, true);
            app.DropDown_15.Visible = 'off';
            app.DropDown_15.Tooltip = {'If'; 'set to ''auto'', a symmetric plot scale'; 'is chosen automatically for each plot'; 'frame.'};
            app.DropDown_15.Value = 'auto';
            app.DropDown_17 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_17.Items = {'true', 'false'};
            app.DropDown_17.Visible = 'off';
            app.DropDown_17.Tooltip = {''};
            app.DropDown_17.Value = 'true';
            app.DropDown_19 = uidropdown(app.OptionalInputParametersPanel);
            app.DropDown_19.Items = {'true', 'false'};
            app.DropDown_19.Visible = 'off';
            app.DropDown_19.Tooltip = {'If set to false, the input'; 'grids are enlarged by PMLSize before'; 'running the simulation.'};
            app.DropDown_19.Value = 'true';
            app.loadButton = uibutton(app.OptionalInputParametersPanel, 'push');
            app.loadButton.Visible = 'off';
            app.loadButton.Tooltip = {'Elements'; 'set to 1 within the display mask are'; 'set to black within the display.'};
            app.loadButton.Text = 'load';
            app.EditField = uieditfield(app.OptionalInputParametersPanel, 'text');
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.OptionalInputParametersPanel, 'text');
            app.EditField_2.Visible = 'off';
            app.EditField_2.Tooltip = {'default is date-time'};
            app.plotFreq = uispinner(app.OptionalInputParametersPanel);
            app.plotFreq.Visible = 'off';
            app.plotFreq.Tooltip = {'default is 10'};
            app.plotFreq.Value = 10;
            app.minSpinnerLabel = uilabel(app.OptionalInputParametersPanel);
            app.minSpinnerLabel.Visible = 'off';
            app.minSpinnerLabel.Text = 'min';
            app.minSpinner = uispinner(app.OptionalInputParametersPanel);
            app.minSpinner.Visible = 'off';
            app.minSpinner.Value = -1;
            app.maxSpinnerLabel = uilabel(app.OptionalInputParametersPanel);
            app.maxSpinnerLabel.Visible = 'off';
            app.maxSpinnerLabel.Text = 'max';
            app.maxSpinner = uispinner(app.OptionalInputParametersPanel);
            app.maxSpinner.Visible = 'off';
            app.maxSpinner.Value = 1;
            app.Spinner_2 = uispinner(app.OptionalInputParametersPanel);
            app.Spinner_2.Visible = 'off';
            app.Spinner_2.Tooltip = {'default is 2'};
            app.Spinner_2.Value = 2;
            app.Spinner_3 = uispinner(app.OptionalInputParametersPanel);
            app.Spinner_3.Visible = 'off';
            app.Spinner_3.Tooltip = {'By default, the PML is'; 'added evenly to all sides of the grid,'; 'however, both PMLSize and PMLAlpha'; 'can be given as N element arrays to'; 'specify the properties in each Carte-'; 'sian direction. To remove the PML,'; 'set the appropriate PMLAlpha to zero'; 'rather than forcing the PML to be of'; 'zero size.'};
            app.Spinner_3.Value = 20;
            app.EditField_3 = uieditfield(app.OptionalInputParametersPanel, 'text');
            app.EditField_3.Visible = 'off';
            app.EditField_3.Tooltip = {''};
            app.sourcep0Label = uilabel(app.OptionalInputParametersPanel);
            app.sourcep0Label.Enable = 'off';
            app.sourcep0Label.Text = 'source.p0';
            app.sourcep0DropDown = uidropdown(app.OptionalInputParametersPanel);
            app.sourcep0DropDown.Items = {'false', 'true'};
            app.sourcep0DropDown.Visible = 'off';
            app.sourcep0DropDown.Value = 'true';
            app.mediumsound_speedDropDownLabel = uilabel(app.OptionalInputParametersPanel);
            app.mediumsound_speedDropDownLabel.Enable = 'off';
            app.mediumsound_speedDropDownLabel.Text = 'medium.sound_speed';
            app.mediumsound_speedDropDown = uidropdown(app.OptionalInputParametersPanel);
            app.mediumsound_speedDropDown.Items = {'false', 'true'};
            app.mediumsound_speedDropDown.Visible = 'off';
            app.mediumsound_speedDropDown.Value = 'false';
            app.mediumdensityDropDownLabel = uilabel(app.OptionalInputParametersPanel);
            app.mediumdensityDropDownLabel.Enable = 'off';
            app.mediumdensityDropDownLabel.Text = 'medium.density';
            app.mediumdensityDropDown = uidropdown(app.OptionalInputParametersPanel);
            app.mediumdensityDropDown.Items = {'false', 'true'};
            app.mediumdensityDropDown.Visible = 'off';
            app.mediumdensityDropDown.Value = 'false';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputParametersAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end