classdef PlotGUI_15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotApp                         matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ColorControlPanel               matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        AllLabel                        matlab.ui.control.Label
        ColormapSelection               matlab.ui.control.DropDown
        ColormapDropDown_2Label         matlab.ui.control.Label
        FramenumberSpinner_2Label       matlab.ui.control.Label
        FramenumberSpinner              matlab.ui.control.Spinner
        ContrastBrightnessControlPanel  matlab.ui.container.Panel
        WindowLevelButton               matlab.ui.control.Button
        FramenumberSpinnerLabel         matlab.ui.control.Label
        FramenumberSpinner_2            matlab.ui.control.Spinner
        MagnificationSpinnerLabel       matlab.ui.control.Label
        MagnificationSpinner            matlab.ui.control.Spinner
        VolumeViewPanel                 matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        SliceViewPanel                  matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        NormalizedMultisliceviewPanel   matlab.ui.container.Panel
        PlotButton_3                    matlab.ui.control.Button
        HistogramPanel                  matlab.ui.container.Panel
        FramenumberSpinner_3Label       matlab.ui.control.Label
        FramenumberSpinner_3            matlab.ui.control.Spinner
        HistogramUi                     matlab.ui.control.UIAxes
        Panel_fig                       matlab.ui.container.Panel
    end

    properties (Access = private)
        Axes % Description
        CallingApp
        inputImage
    end
    methods (Access = private)
        function updateFigures(app, inputImage)
%             Max = max(inputImage(:));
%             Min = min(inputImage(:));
            numSlice = size(inputImage,3); %number of slices
%             iter = 0;
%             color = get(app.PlotApp,'Color');
            if numSlice ==1
                t = tiledlayout(app.Panel_fig, 1, 1);
                % Access first subplot-tile for plotting
                ax = nexttile(t);
                imshow(inputImage,'Parent', ax, 'DisplayRange',[]);
            elseif numSlice ==2
                t = tiledlayout(app.Panel_fig, 1, 2);
                % Access first subplot-tile for plotting
                ax(1) = nexttile(t);
                imshow(inputImage(:,:,1),'Parent', ax(1), 'DisplayRange',[]);
                % Access second subplot-tile for plotting
                ax(2) = nexttile(t);
                imshow(inputImage(:,:,2),'Parent', ax(2), 'DisplayRange',[]);
            elseif numSlice ==3
                t = tiledlayout(app.Panel_fig, 1, 3);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif numSlice ==4
                t = tiledlayout(app.Panel_fig, 2, 2);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif numSlice ==5 || numSlice ==6
                t = tiledlayout(app.Panel_fig, 2, 3);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
                
            elseif numSlice ==7 || numSlice ==8
                t = tiledlayout(app.Panel_fig, 2, 4);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (9<=numSlice)&&(numSlice<=12)
                t = tiledlayout(app.Panel_fig, 3, 4);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (13<=numSlice)&&(numSlice<=15)
                t = tiledlayout(app.Panel_fig, 3, 5);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (16<=numSlice)&&(numSlice<=20)
                t = tiledlayout(app.Panel_fig, 4, 5);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (21<=numSlice)&&(numSlice<=24)
                t = tiledlayout(app.Panel_fig, 4, 6);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (25<=numSlice)&&(numSlice<=30)
                t = tiledlayout(app.Panel_fig, 5, 6);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            elseif (31<=numSlice)&&(numSlice<=42)
                t = tiledlayout(app.Panel_fig, 6, 7);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
                elseif (43<=numSlice)
                t = tiledlayout(app.Panel_fig, 7, 8);
                for i=1:numSlice
                    ax(i) = nexttile(t);
                    imshow(inputImage(:,:,i),'Parent', ax(i), 'DisplayRange',[]);
                end
            end
            t.Padding = 'none';
            t.TileSpacing = 'none';
            app.Axes=ax;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, recImage, title)
            app.PlotApp.Name = append('Display tools: ', string(title));
            app.CallingApp = MainApp;
            try
                app.inputImage = recImage;
                updateFigures(app, recImage)
                FramenumberSpinner_3ValueChanged(app)
            catch ME
                % If problem reading image, display error message
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: ColormapSelection, 
        % FramenumberSpinner
        function ColormapSelectionValueChanged(app, event)
            Colmap = app.ColormapSelection.Value;
            value = app.CheckBox.Value;
%             try
                if value
                    for i =1:size(app.Axes,2)
                        colormap(app.Axes(i), Colmap)
                    end
                else
                    colormap(app.Axes(app.FramenumberSpinner.Value), Colmap)
                end
%             catch ME
%                 uialert(app.PlotApp, ME.message, 'Image Error');
%                 return;
%             end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                app.FramenumberSpinner.Enable = 'Off';
            else
                app.FramenumberSpinner.Enable = 'On';
            end
        end

        % Button pushed function: WindowLevelButton
        function WindowLevelButtonPushed(app, event)
            try
                iSlice = app.FramenumberSpinner_2.Value;
                im=app.inputImage(:,:,iSlice);
                im= uint8(255 * mat2gray(im)); 
                figure(112),clf(112)
                imshow(im, 'DisplayRange',[],'InitialMagnification', app.MagnificationSpinner.Value);
                ax = gca;
                %Create a colorbar
                c_b=cell(1,2);c_b{1,1}='vert';
                c_b{1,2}=colorbar(c_b{1,1},'peer',ax);
                enabled=true;
                default_caxis_values=[50 250];
                speed_multiplier=1;
                legacy_colorbar=c_b;
                enableTagTrigger=true;
                WindowLevel(ax,enabled,default_caxis_values,speed_multiplier,legacy_colorbar,enableTagTrigger)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            try
                im=app.inputImage;
                volumeViewer(im)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            try
                im=app.inputImage;
                im= uint8(255 * mat2gray(im));
                figure; 
                sliceViewer(im)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            try
                imdisp(app.inputImage)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: FramenumberSpinner_3
        function FramenumberSpinner_3ValueChanged(app, event)
            try
                iSlice = app.FramenumberSpinner_3.Value;
                im=app.inputImage(:,:,iSlice);
                 histogram(app.HistogramUi, im, 'FaceColor','g','EdgeColor', 'none');
           catch ME
               uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotApp = uifigure('Visible', 'off');
            app.PlotApp.Name = 'MATLAB App';
            app.Panel = uipanel(app.PlotApp);
            app.ColorControlPanel = uipanel(app.Panel);
            app.ColorControlPanel.Title = 'Color Control:';
            app.CheckBox = uicheckbox(app.ColorControlPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.AllLabel = uilabel(app.ColorControlPanel);
            app.AllLabel.Text = 'All:';
            app.ColormapSelection = uidropdown(app.ColorControlPanel);
            app.ColormapSelection.Items = {'gray', 'jet', 'parula', 'autumn', 'bone', 'colorcube', 'cool', 'copper', 'flag', 'hot', 'hsv', 'lines', 'pink', 'prism', 'spring', 'summer', 'white', 'winter'};
            app.ColormapSelection.ValueChangedFcn = createCallbackFcn(app, @ColormapSelectionValueChanged, true);
            app.ColormapSelection.Tooltip = {'Valid for 3D surface dispaly'};
            app.ColormapSelection.Value = 'gray';
            app.ColormapDropDown_2Label = uilabel(app.ColorControlPanel);
            app.ColormapDropDown_2Label.Text = 'Colormap:';
            app.FramenumberSpinner_2Label = uilabel(app.ColorControlPanel);
            app.FramenumberSpinner_2Label.Text = 'Frame number:';
            app.FramenumberSpinner = uispinner(app.ColorControlPanel);
            app.FramenumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ColormapSelectionValueChanged, true);
            app.FramenumberSpinner.Enable = 'off';
            app.FramenumberSpinner.Value = 1;
            app.ContrastBrightnessControlPanel = uipanel(app.Panel);
            app.ContrastBrightnessControlPanel.Title = 'Contrast & Brightness Control:';
            app.WindowLevelButton = uibutton(app.ContrastBrightnessControlPanel, 'push');
            app.WindowLevelButton.ButtonPushedFcn = createCallbackFcn(app, @WindowLevelButtonPushed, true);
            app.WindowLevelButton.Text = 'Window Level';
            app.FramenumberSpinnerLabel = uilabel(app.ContrastBrightnessControlPanel);
            app.FramenumberSpinnerLabel.Text = 'Frame number:';
            app.FramenumberSpinner_2 = uispinner(app.ContrastBrightnessControlPanel);
            app.FramenumberSpinner_2.Value = 1;
            app.MagnificationSpinnerLabel = uilabel(app.ContrastBrightnessControlPanel);
            app.MagnificationSpinnerLabel.Text = 'Magnification:';
            app.MagnificationSpinner = uispinner(app.ContrastBrightnessControlPanel);
            app.MagnificationSpinner.Value = 500;
            app.VolumeViewPanel = uipanel(app.Panel);
            app.VolumeViewPanel.Title = 'Volume View:';
            app.PlotButton = uibutton(app.VolumeViewPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'sliceomatic'};
            app.PlotButton.Text = 'Plot';
            app.SliceViewPanel = uipanel(app.Panel);
            app.SliceViewPanel.Title = 'Slice View:';
            app.PlotButton_2 = uibutton(app.SliceViewPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.NormalizedMultisliceviewPanel = uipanel(app.Panel);
            app.NormalizedMultisliceviewPanel.Title = 'Normalized Multislice view:';
            app.PlotButton_3 = uibutton(app.NormalizedMultisliceviewPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Tooltip = {'imdisp'};
            app.PlotButton_3.Text = 'Plot';
            app.HistogramPanel = uipanel(app.Panel);
            app.HistogramPanel.Title = 'Histogram:';
            app.FramenumberSpinner_3Label = uilabel(app.HistogramPanel);
            app.FramenumberSpinner_3Label.Text = 'Frame number:';
            app.FramenumberSpinner_3 = uispinner(app.HistogramPanel);
            app.FramenumberSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FramenumberSpinner_3ValueChanged, true);
            app.FramenumberSpinner_3.Value = 1;
            app.HistogramUi = uiaxes(app.HistogramPanel);
            title(app.HistogramUi, 'Histogram')
            xlabel(app.HistogramUi, 'Intensity Value')
            ylabel(app.HistogramUi, 'Number of Pixels')
            app.Panel_fig = uipanel(app.PlotApp);
            % Show the figure after all components are created
            app.PlotApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotGUI_15(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotApp)
        end
    end
end