classdef SimulatePAsignal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PAT_Rec_AddsOn_simulation      matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SaveDataButton                 matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        optionalInputArgs              matlab.ui.control.TextArea
        DefineOptionalInputParametersButton  matlab.ui.control.Button
        DefineDetectorPropertiesPanel  matlab.ui.container.Panel
        CenterfrequencyMHzLabel        matlab.ui.control.Label
        BandwidthLabel                 matlab.ui.control.Label
        Bandwidth                      matlab.ui.control.Spinner
        CenterFrequency                matlab.ui.control.Spinner
        DirectivityAngle               matlab.ui.control.Button
        DirectivitysizemmCheckBox      matlab.ui.control.CheckBox
        DirectivityangleCheckBox       matlab.ui.control.CheckBox
        RecordStartIndex               matlab.ui.control.Spinner
        RecordstartindexLabel          matlab.ui.control.Label
        FrequencyresponseCheckBox      matlab.ui.control.CheckBox
        averageacousticintensityCheckBox  matlab.ui.control.CheckBox
        timevaryingacousticintensityCheckBox  matlab.ui.control.CheckBox
        particlevelocityonnonstaggeredgridpointsCheckBox  matlab.ui.control.CheckBox
        minvelocityatallgridpointsCheckBox  matlab.ui.control.CheckBox
        maxvelocityatallgridpointsCheckBox  matlab.ui.control.CheckBox
        finalparticlevelocityfieldCheckBox  matlab.ui.control.CheckBox
        RMSparticlevelocityCheckBox    matlab.ui.control.CheckBox
        minparticlevelocityCheckBox    matlab.ui.control.CheckBox
        maxparticlevelocityCheckBox    matlab.ui.control.CheckBox
        particlevelocityCheckBox       matlab.ui.control.CheckBox
        minpressureatallgridpointsCheckBox  matlab.ui.control.CheckBox
        maxpressureatallgridpointsCheckBox  matlab.ui.control.CheckBox
        finalpressurefieldCheckBox     matlab.ui.control.CheckBox
        RMSpressureCheckBox            matlab.ui.control.CheckBox
        minpressureCheckBox            matlab.ui.control.CheckBox
        maxpressureCheckBox            matlab.ui.control.CheckBox
        acousticpressureCheckBox       matlab.ui.control.CheckBox
        AcousticparameterstorecordLabel  matlab.ui.control.Label
        DirectivitySize                matlab.ui.control.Spinner
        StartSimulationButton          matlab.ui.control.Button
        DefineComputationalGridPanel   matlab.ui.container.Panel
        ListeningTime                  matlab.ui.control.NumericEditField
        ListeningtimesLabel            matlab.ui.control.Label
        SamplingRate                   matlab.ui.control.NumericEditField
        SamplingrateMHzLabel_2         matlab.ui.control.Label
        DetectorPositionsInformationPanel  matlab.ui.container.Panel
        TransducersInfo                matlab.ui.control.TextArea
        GridsizezmmEditField           matlab.ui.control.NumericEditField
        GridsizezmmEditFieldLabel      matlab.ui.control.Label
        GridsizeymmEditField           matlab.ui.control.NumericEditField
        GridsizeymmEditFieldLabel      matlab.ui.control.Label
        GridsizexmmEditField           matlab.ui.control.NumericEditField
        GridsizexmmEditFieldLabel      matlab.ui.control.Label
        NumberofgridsyEditField        matlab.ui.control.NumericEditField
        NumberofgridsyLabel            matlab.ui.control.Label
        NumberofgridsxEditField        matlab.ui.control.NumericEditField
        NumberofgridsxEditFieldLabel   matlab.ui.control.Label
        CenterymmSpinner               matlab.ui.control.Spinner
        CenterymmSpinnerLabel          matlab.ui.control.Label
        CenterxmmSpinner               matlab.ui.control.Spinner
        CenterxmmSpinnerLabel          matlab.ui.control.Label
        ysizemmSpinner                 matlab.ui.control.Spinner
        ysizemmSpinnerLabel            matlab.ui.control.Label
        xsizemmSpinner                 matlab.ui.control.Spinner
        xsizemmSpinnerLabel            matlab.ui.control.Label
        NumberofpixelszEditField       matlab.ui.control.NumericEditField
        NumberofpixelszEditFieldLabel  matlab.ui.control.Label
        zsizemmSpinner                 matlab.ui.control.Spinner
        zsizemmSpinnerLabel            matlab.ui.control.Label
        CenterzmmSpinner               matlab.ui.control.Spinner
        CenterzmmLabel                 matlab.ui.control.Label
        DefineAcousticMediumPanel      matlab.ui.container.Panel
        AlphsCoeffButton               matlab.ui.control.Button
        BonaScalar                     matlab.ui.control.Spinner
        DensityAlphaFilterButton       matlab.ui.control.Button
        dispersionSpinner              matlab.ui.control.Spinner
        dispersionSpinnerLabel         matlab.ui.control.Label
        absorptionSpinner              matlab.ui.control.Spinner
        absorptionLabel                matlab.ui.control.Label
        AlphacoefficientDropDown       matlab.ui.control.DropDown
        AlphacoefficientDropDownLabel  matlab.ui.control.Label
        DensityLoadButton              matlab.ui.control.Button
        SOSDesignButton                matlab.ui.control.Button
        SOSLoadButton                  matlab.ui.control.Button
        SoS_ref                        matlab.ui.control.Spinner
        AlphafilterDropDown            matlab.ui.control.DropDown
        AlphafilterDropDownLabel       matlab.ui.control.Label
        AlphasignDropDown              matlab.ui.control.DropDown
        AlphasignDropDownLabel         matlab.ui.control.Label
        AlphamodeDropDown              matlab.ui.control.DropDown
        AlphamodeDropDownLabel         matlab.ui.control.Label
        RefSOSDropdown                 matlab.ui.control.DropDown
        ReferencesoundspeedusedinthekspaceoperatorDropDownLabel  matlab.ui.control.Label
        AlphapowerDropDown             matlab.ui.control.DropDown
        AlphapowerDropDownLabel        matlab.ui.control.Label
        NonlinearityDropDown           matlab.ui.control.DropDown
        NonlinearityDropDownLabel      matlab.ui.control.Label
        DensityDropDown                matlab.ui.control.DropDown
        DensityDropDownLabel           matlab.ui.control.Label
        SpeedofsoundDropDown           matlab.ui.control.DropDown
        SpeedofsoundDropDownLabel      matlab.ui.control.Label
        Density                        matlab.ui.control.Spinner
        AlphaPower                     matlab.ui.control.Spinner
        AlphaCoeff                     matlab.ui.control.Spinner
        SoS                            matlab.ui.control.Spinner
        DefineInitialPressureDistributionPanel  matlab.ui.container.Panel
        Panel_ShepLogan                matlab.ui.container.Panel
        CentercoordinatemmxSpinnerLabel_2  matlab.ui.control.Label
        magnitude_phantom_2            matlab.ui.control.Spinner
        MagnitudePaLabel_14            matlab.ui.control.Label
        CenterX_phantom_2              matlab.ui.control.Spinner
        CenterY_phantom_2              matlab.ui.control.Spinner
        ySpinner_phantom_2             matlab.ui.control.Label
        Width_phantom_2                matlab.ui.control.Spinner
        WidthmmLabel_5                 matlab.ui.control.Label
        Panel_LoadImage2D              matlab.ui.container.Panel
        LoadImageButton                matlab.ui.control.Button
        CentercoordinatemmxSpinnerLabel  matlab.ui.control.Label
        magnitude_phantom              matlab.ui.control.Spinner
        MagnitudePaLabel_13            matlab.ui.control.Label
        CenterX_phantom                matlab.ui.control.Spinner
        CenterY_phantom                matlab.ui.control.Spinner
        ySpinner_phantom               matlab.ui.control.Label
        Width_phantom                  matlab.ui.control.Spinner
        WidthmmLabel_4                 matlab.ui.control.Label
        Panel_Bar3D                    matlab.ui.container.Panel
        CenterY_Bar                    matlab.ui.control.Spinner
        yLabel_4                       matlab.ui.control.Label
        radius_Bar                     matlab.ui.control.Spinner
        RadiusmmLabel_5                matlab.ui.control.Label
        BarDirection                   matlab.ui.container.ButtonGroup
        zButton                        matlab.ui.control.RadioButton
        yButton                        matlab.ui.control.RadioButton
        xButton                        matlab.ui.control.RadioButton
        DirectionLabel                 matlab.ui.control.Label
        CenterZ_Bar                    matlab.ui.control.Spinner
        zLabel_2                       matlab.ui.control.Label
        magnitude_Bar                  matlab.ui.control.Spinner
        MagnitudePaLabel_12            matlab.ui.control.Label
        CenterX_Bar                    matlab.ui.control.Spinner
        CentermmxLabel_3               matlab.ui.control.Label
        length_Bar                     matlab.ui.control.Spinner
        LengthmmLabel_2                matlab.ui.control.Label
        Panel_Cube3D                   matlab.ui.container.Panel
        sizeZ_Cube3D                   matlab.ui.control.Spinner
        yLabel_3                       matlab.ui.control.Label
        CenterZ_Cube3D                 matlab.ui.control.Spinner
        zLabel                         matlab.ui.control.Label
        CenterY_Cube3D                 matlab.ui.control.Spinner
        yLabel_2                       matlab.ui.control.Label
        sizeY_Cube3D                   matlab.ui.control.Spinner
        yLabel                         matlab.ui.control.Label
        magnitude_Cube3D               matlab.ui.control.Spinner
        MagnitudePaLabel_11            matlab.ui.control.Label
        CenterX_Cube3D                 matlab.ui.control.Spinner
        CentermmxLabel_2               matlab.ui.control.Label
        sizeX_Cube3D                   matlab.ui.control.Spinner
        DimensionsmmxLabel             matlab.ui.control.Label
        Panel_Ball3D                   matlab.ui.container.Panel
        CenterZ_ball3d                 matlab.ui.control.Spinner
        zSpinner_ball3d                matlab.ui.control.Label
        magnitude_ball3d               matlab.ui.control.Spinner
        MagnitudePaLabel_10            matlab.ui.control.Label
        CenterX_ball3d                 matlab.ui.control.Spinner
        CentermmxLabel                 matlab.ui.control.Label
        CenterY_ball3d                 matlab.ui.control.Spinner
        ySpinner_ball3d                matlab.ui.control.Label
        radius_ball3d                  matlab.ui.control.Spinner
        RadiusmmLabel_4                matlab.ui.control.Label
        Panel_rect2D                   matlab.ui.container.Panel
        magnitude_Rect                 matlab.ui.control.Spinner
        MagnitudePaLabel_8             matlab.ui.control.Label
        Angle_rec2D                    matlab.ui.control.Spinner
        AngledegLabel                  matlab.ui.control.Label
        CenterX_rec2D                  matlab.ui.control.Spinner
        CentercoordinatemmxLabel_2     matlab.ui.control.Label
        CenterY_rec2D                  matlab.ui.control.Spinner
        ySpinner_rec2D                 matlab.ui.control.Label
        width_rec2D                    matlab.ui.control.Spinner
        WidthmmLabel_3                 matlab.ui.control.Label
        Length_rec2D                   matlab.ui.control.Spinner
        LengthmmLabel                  matlab.ui.control.Label
        Panel_Disk2D                   matlab.ui.container.Panel
        magnitude_disk                 matlab.ui.control.Spinner
        MagnitudePaLabel_7             matlab.ui.control.Label
        CenterX_disk2D                 matlab.ui.control.Spinner
        CentercoordinatemmxLabel       matlab.ui.control.Label
        CenterY_disk2D                 matlab.ui.control.Spinner
        ySpinner_disk2D                matlab.ui.control.Label
        radius_disk                    matlab.ui.control.Spinner
        RadiusmmLabel_3                matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        ClearallButton                 matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        ObjectDropDown_3D              matlab.ui.control.DropDown
        ObjectLabel                    matlab.ui.control.Label
        ObjectDropDown_2D              matlab.ui.control.DropDown
        ObjectLabel_2                  matlab.ui.control.Label
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        sensorsPositions
        Dval % Dimenssion
        
        initialsMask = []; % Description
        Mask % Description
        Nx
        Ny
        Nz % Description
        sensor_data % Description
        InputParameters % Description
        Args % Description
        SoSmap %Loaded or designed Speed of Sound
        InputSoSmap % Description
        DensityMap % Loaded Density
        AlphaCoeffMap % Loaded Alpha Coeff
        SoSrefMap % Loaded Speed of sound reference 
        directivityAngle % Loaded directivity Angle
        loadedImage % Description
        dirAngles
    end
    
    methods (Access = public)
        
         function UpdateArgs(app, importedArgs)
           app.Args =  importedArgs;
           out = cellfun(@num2str,importedArgs,'un',0);
           app.optionalInputArgs.Value = out;           
         end
         function UpdateSoSmap(app, importedSoS)
           app.SoSmap =  importedSoS; 
         end
         function UpdateLoadDirectivity(app, importedDirectivity)
             app.dirAngles = importedDirectivity;
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Loc)
        app.PAT_Rec_AddsOn_simulation.Name = 'Simulate Time Series Data';    
        app.CallingApp = MainApp;
        LocTemp = [];
        for i = 1:size(Loc,3)
            LocTemp = cat(1,LocTemp, Loc(:,:,i));
        end
        app.sensorsPositions = LocTemp;
        
        
        if size(LocTemp, 2)==2
            app.Dval = '2D';
            app.ObjectDropDown_3D.Visible = 'Off';
            app.ObjectDropDown_2D.Visible = 'On';
            app.CenterzmmLabel.Visible = 'Off';app.CenterzmmSpinner.Visible = 'Off';
            app.zsizemmSpinnerLabel.Visible = 'Off';app.zsizemmSpinner.Visible = 'Off';
            app.GridsizezmmEditFieldLabel.Visible = 'Off';app.GridsizezmmEditField.Visible = 'Off';
            app.NumberofpixelszEditFieldLabel.Visible = 'Off';app.NumberofpixelszEditField.Visible = 'Off';
            app.Panel_Disk2D.Visible = 'On';
            app.Panel_Ball3D.Visible = 'Off';
            app.CenterxmmSpinner.Value = round(mean(LocTemp(:,1)));
            app.CenterymmSpinner.Value = round(mean(LocTemp(:,2)));
            M = max(LocTemp(:,1)); %Max
            m = min(LocTemp(:,1)); %min
            xSize = ceil(1.02*(M-m));
            M = max(LocTemp(:,2)); %Max
            m = min(LocTemp(:,2)); %min
            ySize = ceil(1.02*(M-m));
            app.xsizemmSpinner.Value = max(xSize, ySize);
            app.ysizemmSpinner.Value = max(xSize, ySize);
            app.CenterX_disk2D.Value = round(mean(LocTemp(:,1)));
            app.CenterY_disk2D.Value = round(mean(LocTemp(:,2)));
            app.CenterX_rec2D.Value = round(mean(LocTemp(:,1)));
            app.CenterY_rec2D.Value = round(mean(LocTemp(:,2)));
            app.CenterX_phantom.Value = round(mean(LocTemp(:,1)));
            app.CenterY_phantom.Value = round(mean(LocTemp(:,2)));
            app.CenterX_phantom_2.Value = round(mean(LocTemp(:,1)));
            app.CenterY_phantom_2.Value = round(mean(LocTemp(:,2)));
            app.ImageAxes = uiaxes(app.Panel);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.ImageAxes.PlotBoxAspectRatio = [1.09701492537313 1 1];
            app.ImageAxes.XGrid = 'on';
            app.ImageAxes.XMinorGrid = 'on';
            app.ImageAxes.YGrid = 'on';
            app.ImageAxes.YMinorGrid = 'on';
            app.ImageAxes.ZGrid = 'on';
            app.ImageAxes.ZMinorGrid = 'on';
            app.ImageAxes.Position = [455,37,320,291];
            
            % Transducers Positions Information:
            cellArrayText{1} = sprintf('Dimensions size: %d', size(LocTemp, 2));
            cellArrayText{2} = sprintf('Number of sensors: %d', size(LocTemp, 1));
            cellArrayText{3} = sprintf('[X min,X max]: [%d,%d]', min(LocTemp(:,1)), max(LocTemp(:,1)));
            cellArrayText{4} = sprintf('[Y min,Y max]: [%d,%d]', min(LocTemp(:,2)), max(LocTemp(:,2)));
        elseif size(LocTemp, 2)==3
            app.Dval = '3D';
            app.ObjectDropDown_3D.Visible = 'On';
            app.ObjectDropDown_2D.Visible = 'Off';
            app.Panel_Disk2D.Visible = 'Off';
            app.Panel_Ball3D.Visible = 'On';
            app.CenterxmmSpinner.Value = mean(LocTemp(:,1));
            app.CenterymmSpinner.Value = mean(LocTemp(:,2));
            app.CenterzmmSpinner.Value = mean(LocTemp(:,3));
            M = max(LocTemp(:,1)); %Max
            m = min(LocTemp(:,1)); %min
            app.xsizemmSpinner.Value = ceil(1.02*(M-m));
            M = max(LocTemp(:,2)); %Max
            m = min(LocTemp(:,2)); %min
            app.ysizemmSpinner.Value = ceil(1.02*(M-m));
            M = max(LocTemp(:,3)); %Max
            m = min(LocTemp(:,3)); %min
            app.zsizemmSpinner.Value = ceil(1.02*(M-m));
            app.CenterX_ball3d.Value = mean(LocTemp(:,1));
            app.CenterY_ball3d.Value = mean(LocTemp(:,2));
            app.CenterZ_ball3d.Value = mean(LocTemp(:,3));
            
            % Transducers Positions Information:
            cellArrayText{1} = sprintf('Dimensions size: %d', size(LocTemp, 2));
            cellArrayText{2} = sprintf('Number of detectors: %d', size(LocTemp, 1));
            cellArrayText{3} = sprintf('[X min,X max]: [%d,%d]', min(LocTemp(:,1)), max(LocTemp(:,1)));
            cellArrayText{4} = sprintf('[Y min,Y max]: [%d,%d]', min(LocTemp(:,2)), max(LocTemp(:,2)));
            cellArrayText{5} = sprintf('[Z min,Z max]: [%d,%d]', min(LocTemp(:,3)), max(LocTemp(:,3)));
        else
            %Error
        end
        app.TransducersInfo.Value = cellArrayText;
        xsizemmSpinnerValueChanged(app);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn_simulation;
            d = uiprogressdlg(f,'Title','Plotting Data ...');
            tempMask = app.initialsMask;
            switch app.Dval
                case '2D'
                    app.ObjectDropDown_3D.Visible = 'Off';
                    app.ObjectDropDown_2D.Visible = 'On';
                    x_size = app.xsizemmSpinner.Value; %[mm]
                    y_size = app.ysizemmSpinner.Value; %[mm]
                    
                    resolutionFactorX = 1/app.GridsizexmmEditField.Value;
                    resolutionFactorY = 1/app.GridsizeymmEditField.Value;
                    
                    Npixel_x = round(x_size*resolutionFactorX);
                    Npixel_y = round(y_size*resolutionFactorY);
                    
                    center_x = app.CenterxmmSpinner.Value; %[mm]
                    center_y = app.CenterymmSpinner.Value; %[mm]
                    
                    x_range = ((1:Npixel_x)-(Npixel_x+1)/2)*x_size/(Npixel_x)+center_x;  % x axis
                    y_range = ((Npixel_y:-1:1)-(Npixel_y+1)/2)*y_size/(Npixel_y)+center_y;    % y axis
                    sensorCordinatesX = round(resolutionFactorX*(app.sensorsPositions(:,1) - x_range(1)));       % [grid]
                    sensorCordinatesY = round(resolutionFactorY*(y_range(1) - app.sensorsPositions(:,2)));     % [grid]
                    
                    switch app.ObjectDropDown_2D.Value
                        case 'Disk'
                            % create initial pressure distribution using makeDisc
                            magnitude = app.magnitude_disk.Value; % [Pa]
                            CX = app.CenterX_disk2D.Value;     % center [mm]
                            CY = app.CenterY_disk2D.Value;     % center [mm]
                            radius = app.radius_disk.Value;       % [mm]
                            CX = round(resolutionFactorX*(CX - x_range(1)));       % [grid]
                            CY = round(resolutionFactorY*(y_range(1)-CY));     % [grid]
                            radius = round(resolutionFactorX*radius);                    % [grid]
                            app.Mask = magnitude * makeDisc(Npixel_y, Npixel_x, CY, CX, radius);
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                        case 'Rectangle'
                            L  = app.Length_rec2D.Value;              % [mm]
                            W  = app.width_rec2D.Value;               % [mm]
                            CX = app.CenterX_rec2D.Value;             % [mm]
                            CY = app.CenterY_rec2D.Value;             % [mm]
                            L  = round(resolutionFactorY*L);                       % [grid]
                            W  = round(resolutionFactorX*W);                       % [grid]
                            CX = round(resolutionFactorX*(CX - x_range(1)));       % [grid]
                            CY = round(resolutionFactorY*(y_range(1)-CY));     % [grid]
                            Angle = app.Angle_rec2D.Value*(pi/180);                % [rad]
                            cornerCoordinates = [CX-W/2 CX-W/2 CX+W/2  CX+W/2;...
                                CY-L/2 CY+L/2 CY+L/2  CY-L/2];
                            rotMat = [cos(Angle) sin(Angle);...
                                -sin(Angle) cos(Angle)];
                            cornerCoordinates = round(rotMat*(cornerCoordinates-repmat([CX;CY],[1 4]))+repmat([CX;CY],[1 4]));
                            cornerCoordinates(:,5)=cornerCoordinates(:,1);
                            app.Mask = app.magnitude_Rect.Value.*poly2mask(cornerCoordinates(1,:),cornerCoordinates(2,:), Npixel_y, Npixel_x);
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                        case 'Modified Shepp-Logan phantom'
                            W = app.Width_phantom_2.Value;        % [mm]
                            CX = app.CenterX_phantom_2.Value;     % [mm]
                            CY = app.CenterY_phantom_2.Value;     % [mm]
                            W = round(min(resolutionFactorX*W,resolutionFactorY*W));                        % [grid]
                            CX = round(resolutionFactorX*(CX - x_range(1)));       % [grid]
                            CY = round(resolutionFactorY*(y_range(1)-CY));     % [grid]
                            p = phantom('Modified Shepp-Logan', W);
                            Zx = Npixel_x-size(p,2); % Zero-padding size
                            if mod(Zx,2)
                                p(:,end) = [];
                                Zx = Npixel_x-size(p,2);
                            end
                            Zy = Npixel_y-size(p,1); % Zero-padding size
                            if mod(Zy,2)
                                p(end,:) = [];
                                Zy = Npixel_y-size(p,1);
                            end
                            Zx = Zx/2;
                            Zy = Zy/2;
                            app.Mask = app.magnitude_phantom_2.Value.*(padarray((padarray(p,[Zy+round(CY-Npixel_y/2) Zx+round(CX-Npixel_x/2)],0,'pre')), [Zy-round(CY-Npixel_y/2) Zx-round(CX-Npixel_x/2)], 0, 'post'));
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                        case 'Load Image'
                            W = app.Width_phantom.Value;        % [mm]
                            CX = app.CenterX_phantom.Value;     % [mm]
                            CY = app.CenterY_phantom.Value;     % [mm]
                            W = round(min(resolutionFactorX*W,resolutionFactorY*W));                        % [grid]
                            CX = round(resolutionFactorX*(CX - x_range(1)));       % [grid]
                            CY = round(resolutionFactorY*(y_range(1)-CY));     % [grid]

                            p = app.loadedImage;
                            if size(p,3)~=1
                                p = p(:,:,1);
                            end
                            p = imresize(p, [NaN W]);
                            Zx = Npixel_x-size(p,2); % Zero-padding size
                            if mod(Zx,2)
                                p(:,end) = [];
                                Zx = Npixel_x-size(p,2);
                            end
                            Zy = Npixel_y-size(p,1); % Zero-padding size
                            if mod(Zy,2)
                                p(end,:) = [];
                                Zy = Npixel_y-size(p,1);
                            end
                            Zx = Zx/2;
                            Zy = Zy/2;
                            app.Mask = app.magnitude_phantom.Value.*(padarray((padarray(p,[Zy+round(CY-Npixel_y/2) Zx+round(CX-Npixel_x/2)],0,'pre')), [Zy-round(CY-Npixel_y/2) Zx-round(CX-Npixel_x/2)], 0, 'post'));
                            
%                             app.Mask = app.magnitude_phantom.Value.*(padarray(p,[round(Zy/2) round(Zx/2)],0,'both'));
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                    end
                    
                    imshow(tempMask, [], 'Parent', app.ImageAxes);
                    hold(app.ImageAxes, 'on')
                    plot(app.ImageAxes,sensorCordinatesX, sensorCordinatesY, 'b.');
                    app.Nx = Npixel_x;
                    app.Ny = Npixel_y;
                    %                     app.ImageAxes.Position = [455,37,320,291];
                case '3D'
                    app.ObjectDropDown_3D.Visible = 'On';
                    app.ObjectDropDown_2D.Visible = 'Off';
                    
                    x_size = app.xsizemmSpinner.Value;
                    y_size = app.ysizemmSpinner.Value;
                    z_size = app.zsizemmSpinner.Value;
                    resolutionFactorX = 1/app.GridsizexmmEditField.Value;
                    resolutionFactorY = 1/app.GridsizeymmEditField.Value;
                    resolutionFactorZ = 1/app.GridsizezmmEditField.Value;
                    Npixel_x = round(x_size*resolutionFactorX);
                    Npixel_y = round(y_size*resolutionFactorY);
                    Npixel_z = round(z_size*resolutionFactorZ);
                    
                    center_x = app.CenterxmmSpinner.Value;
                    center_y = app.CenterymmSpinner.Value;
                    center_z = app.CenterzmmSpinner.Value;
                    x_range = ((1:Npixel_x)-(Npixel_x+1)/2)*x_size/(Npixel_x)+center_x;       % x axis
                    y_range = ((Npixel_y:-1:1)-(Npixel_y+1)/2)*y_size/(Npixel_y)+center_y;    % y axis
                    z_range = ((1:Npixel_z)-(Npixel_z+1)/2)*z_size/(Npixel_z)+center_z;
                    sensorCordinatesX = round(resolutionFactorX*(app.sensorsPositions(:,1) - x_range(1)));       % [grid]
                    sensorCordinatesY = round(resolutionFactorY*(y_range(1) - app.sensorsPositions(:,2)));     % [grid]
                    sensorCordinatesZ = round(resolutionFactorZ*(app.sensorsPositions(:,3) - z_range(1)));       % [grid]
                    
                    switch app.ObjectDropDown_3D.Value
                        case 'Ball'
                            magnitude = app.magnitude_ball3d.Value; % [Pa]
                            CX = app.CenterX_ball3d.Value;          % [mm]
                            CY = app.CenterY_ball3d.Value;          % [mm]
                            CZ = app.CenterZ_ball3d.Value;          % [mm]
                            radius = app.radius_ball3d.Value;       % [mm]
                            CX = round(resolutionFactorX*(CX - x_range(1)));             % [grid]
                            CY = round(resolutionFactorY*(CY - y_range(end)));           % [grid]
                            CZ = round(resolutionFactorZ*(CZ - z_range(1)));             % [grid]
                            radius = round(resolutionFactorX*radius);                    % [grid]
                            app.Mask = magnitude * makeBall(Npixel_y, Npixel_x, Npixel_z, CY, CX,CZ, radius);
%                             cart_data = app.sensorsPositions;
%                             [grid_data, order_index, reorder_index] = cart2grid(kgrid, cart_data');
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                        case 'Cube'
                            magnitude = app.magnitude_Cube3D.Value; % [Pa]
                            CX = app.CenterX_Cube3D.Value;          % [mm]
                            CY = app.CenterY_Cube3D.Value;          % [mm]
                            CZ = app.CenterZ_Cube3D.Value;          % [mm]
                            CX = round(resolutionFactorX*(CX - x_range(1)));             % [grid]
                            CY = round(resolutionFactorY*(CY - y_range(end)));           % [grid]
                            CZ = round(resolutionFactorZ*(CZ - z_range(1)));             % [grid]
                            SX = app.sizeX_Cube3D.Value;            % [mm]
                            SY = app.sizeY_Cube3D.Value;            % [mm]
                            SZ = app.sizeZ_Cube3D.Value;            % [mm]
                            SX = round(resolutionFactorX*SX/2);       % [grid]
                            SY = round(resolutionFactorY*SY/2);       % [grid]
                            SZ = round(resolutionFactorZ*SZ/2);       % [grid]
                            app.Mask = zeros(Npixel_y,Npixel_x,Npixel_z);
                            app.Mask(CY-SY:CY+SY, CX-SX:CX+SX, CZ-SZ:CZ+SZ) = magnitude;
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                        case 'Bar'
                            magnitude = app.magnitude_Bar.Value; % [Pa]
                            CX = app.CenterX_Bar.Value;          % [mm]
                            CY = app.CenterY_Bar.Value;          % [mm]
                            CZ = app.CenterZ_Bar.Value;          % [mm]
                            CX = round(resolutionFactorX*(CX - x_range(1)));    % [grid]
                            CY = round(resolutionFactorY*(CY - y_range(end)));  % [grid]
                            CZ = round(resolutionFactorZ*(CZ - z_range(1)));    % [grid]
                            length = app.length_Bar.Value;                      % [mm]
                            length = round(resolutionFactorZ*length/2);         % [grid]
                            radius = app.radius_Bar.Value;                      %[mm]
                            radius = round(resolutionFactorZ*radius);         % [grid]
                            value = app.BarDirection.SelectedObject;
                            switch value.Text
                                case 'x'
                                    disc = magnitude.*makeDisc(Npixel_z, Npixel_y, CZ, CY, radius);
                                    mask = repmat(disc,[1,1,length]);
                                    if  rem(Npixel_x-length, 2) == 0
                                     maskTopBottom = zeros(Npixel_z, Npixel_y,(Npixel_x-length)/2); 
                                     mask = cat(3,maskTopBottom,mask);
                                     mask = cat(3,mask,maskTopBottom);
                                    else
                                     maskTop = zeros(Npixel_z, Npixel_y,(Npixel_x-length+1)/2);
                                     maskBottom = zeros(Npixel_z, Npixel_y,(Npixel_x-length-1)/2);
                                     mask = cat(3,maskTop,mask);
                                     mask = cat(3,mask,maskBottom);  
                                    end
                                    app.Mask = permute(mask, [2 3 1]);
                                case 'y'
                                    disc = magnitude.*makeDisc(Npixel_x, Npixel_z, CX, CZ, radius);
                                    mask = repmat(disc,[1,1,length]);
                                    if  rem(Npixel_y-length, 2) == 0
                                     maskTopBottom = zeros(Npixel_x, Npixel_z,(Npixel_y-length)/2); 
                                     mask = cat(3,maskTopBottom,mask);
                                     mask = cat(3,mask,maskTopBottom);
                                    else
                                     maskTop = zeros(Npixel_x, Npixel_z,(Npixel_y-length+1)/2);
                                     maskBottom = zeros(Npixel_x, Npixel_z,(Npixel_y-length-1)/2);
                                     mask = cat(3,maskTop,mask);
                                     mask = cat(3,mask,maskBottom);  
                                    end
                                    app.Mask = permute(mask, [3 1 2]);
                                case 'z'
                                    disc = magnitude.*makeDisc(Npixel_y, Npixel_x, CY, CX, radius);
                                    mask = repmat(disc,[1,1,length]);
                                    if  rem(Npixel_z-length, 2) == 0
                                     maskTopBottom = zeros(Npixel_y, Npixel_x,(Npixel_z-length)/2); 
                                     mask = cat(3,maskTopBottom,mask);
                                     mask = cat(3,mask,maskTopBottom);
                                    else
                                     maskTop = zeros(Npixel_y, Npixel_x,(Npixel_z-length+1)/2);
                                     maskBottom = zeros(Npixel_y, Npixel_x,(Npixel_z-length-1)/2);
                                     mask = cat(3,maskTop,mask);
                                     mask = cat(3,mask,maskBottom);  
                                    end
                                    app.Mask = mask;
                            end
                            if isempty(tempMask)
                                tempMask = app.Mask;
                            else
                                tempMask = tempMask + app.Mask;
                            end
                    end
                    if ~isempty(tempMask)
                        cla(app.ImageAxes, 'reset');
                        tempMask(tempMask==0)=nan;
%                         voxelPlot(tempMask,'Color', [1 0 0], 'Transparency', 0.5);
                        h = slice(tempMask, [], [], 1:size(tempMask,3));
                        set(h, 'EdgeColor','none', 'FaceColor','interp')
                        ax = gca;
                        plot3(app.ImageAxes,sensorCordinatesX, sensorCordinatesY, sensorCordinatesZ, 'r.');
                        axis(app.ImageAxes, 'equal')
                        hold(app.ImageAxes, 'on')
                        copyobj(ax.Children, app.ImageAxes)
                        close
                        xlim(app.ImageAxes,[1 Npixel_x]) ;
                        ylim(app.ImageAxes,[1 Npixel_y]) ;
                        zlim(app.ImageAxes,[1 Npixel_z]) ;
                        app.Nx = Npixel_x;
                        app.Ny = Npixel_y;
                        app.Nz = Npixel_z;
                    end
            end
            app.AddButton.BackgroundColor = [0.80,0.80,0.80];
            app.SaveButton.BackgroundColor = [0.80,0.80,0.80];
            close(d)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn_simulation;
            d = uiprogressdlg(f,'Title','Add new absorber ...','Cancelable',"on");
            if isempty(app.initialsMask)
                app.initialsMask = app.Mask;
            else
                app.initialsMask = app.initialsMask + app.Mask;
            end
            app.AddButton.BackgroundColor = [0.39,0.83,0.07];
            close(d)
        end

        % Value changed function: ObjectDropDown_3D
        function ObjectDropDown_3DValueChanged(app, event)
            value = app.ObjectDropDown_3D.Value;
            switch value
                case 'Ball'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'On';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'Off';
                case 'Cube'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'On';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'Off';
                case 'Bar'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'On';
                    app.Panel_ShepLogan.Visible = 'Off';                
            end
        end

        % Value changed function: ObjectDropDown_2D
        function ObjectDropDown_2DValueChanged(app, event)
            value = app.ObjectDropDown_2D.Value;
            switch value
                case 'Disk'
                    app.Panel_Disk2D.Visible = 'On';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'Off';
                case 'Rectangle'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'On';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'Off';
                case 'Load Image'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'On';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'Off';
                case 'Modified Shepp-Logan phantom'
                    app.Panel_Disk2D.Visible = 'Off';
                    app.Panel_rect2D.Visible = 'Off';
                    app.Panel_LoadImage2D.Visible = 'Off';
                    app.Panel_Ball3D.Visible = 'Off';
                    app.Panel_Cube3D.Visible = 'Off';
                    app.Panel_Bar3D.Visible = 'Off';
                    app.Panel_ShepLogan.Visible = 'On';
            end
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn_simulation;
            d = uiprogressdlg(f,'Title','Clear all ...','Cancelable',"on");
            app.initialsMask = [];
%             app.ImageAxes.cla;
            cla(app.ImageAxes,'reset');
            title(app.ImageAxes, '')
                xlabel(app.ImageAxes, 'X')
                ylabel(app.ImageAxes, 'Y')
                app.ImageAxes.PlotBoxAspectRatio = [1.09701492537313 1 1];
                app.ImageAxes.XGrid = 'on';
                app.ImageAxes.XMinorGrid = 'on';
                app.ImageAxes.YGrid = 'on';
                app.ImageAxes.YMinorGrid = 'on';
                app.ImageAxes.ZGrid = 'on';
                app.ImageAxes.ZMinorGrid = 'on';
                app.ImageAxes.Position = [455,37,320,291];
            app.AddButton.BackgroundColor = [0.80,0.80,0.80];
            app.SaveButton.BackgroundColor = [0.80,0.80,0.80];
            close(d)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn_simulation;
            d = uiprogressdlg(f,'Title','Save Data ...','Cancelable',"on");
            Target = app.initialsMask;
            formatOut = 'dd_mmm_yyyy';
            tNow = datestr(now,formatOut);
            title = sprintf('Target_%s.mat', tNow);
            save (title,'Target');
            message = sprintf('The file has been successfully saved to the directory: %s', cd);
            uialert(app.PAT_Rec_AddsOn_simulation,message,'Save', 'Icon','success');
            app.SaveButton.BackgroundColor = [0.39,0.83,0.07];
            close(d)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
%             try
            switch app.Dval
                case '2D'
                    %% Medium Properties
                    kgrid = kWaveGrid(app.Ny, app.GridsizeymmEditField.Value*1e-3, app.Nx, app.GridsizexmmEditField.Value*1e-3);
                    LT = app.ListeningTime.Value*1e-6;   % [sec]
                    dt = 1/(app.SamplingRate.Value*1e6); % [sec]
                    kgrid.t_array = 0:dt:LT;
                    
                    %                     kgrid = kWaveGrid(app.Nx, app.GridsizexmmEditField.Value*1e-3, app.Ny, app.GridsizeymmEditField.Value*1e-3);
                    value = app.SpeedofsoundDropDown.Value;
                    switch value
                        case 'Scalar'
                            medium.sound_speed = app.SoS.Value;	     % [m/s]
                        case 'Load a matrix'
                            if isempty(app.SoSmap)
                                uialert(app.PAT_Rec_AddsOn_simulation, "Please load a matrix for the 'Speed of sound' dropdown or use other options.", 'Error');
                                return;
                            else
                                medium.sound_speed = app.SoSmap;
                            end
                        case 'Design a matrix'
                            if isempty(app.SoSmap)
                                uialert(app.PAT_Rec_AddsOn_simulation, "Please design a matrix for the 'Speed of sound' dropdown or use other options.", 'Error');
                                return;
                            else
                                medium.sound_speed = app.SoSmap;
                            end
                    end
                    value = app.RefSOSDropdown.Value;
                    if strcmp(value, 'Scalar')
                        medium.sound_speed = app.SoS_ref.Value;	     % [m/s]
                    end
                    
                    value = app.DensityDropDown.Value;
                    switch value
                        case 'Scalar'
                            medium.density = app.Density.Value * ones(app.Ny, app.Nx);       % [kg/m^3]
                        case 'Load a matrix'
                            if ~app.DensityMap
                                medium.density = app.DensityMap;
                            else
                                errordlg("Please load a matrix for the 'Density' dropdown or use other options.",'Error');
                            end
                    end                   
                    value = app.NonlinearityDropDown.Value;
                    if strcmp(value, 'Scalar')
                        medium.BonA = app.BonaScalar.Value;
                    end
                    value = app.AlphacoefficientDropDown.Value;
                    switch value
                        case 'Scalar'
                            medium.alpha_coeff = app.AlphaCoeff.Value;  % [dB/(MHz^y cm)]
                        case 'Load a matrix'
                            if ~app.AlphaCoeffMap
                                medium.alpha_coeff = app.AlphaCoeffMap;
                            else
                                errordlg("Please load a matrix for the 'Alpha coefficint' dropdown or use other options.",'Error');
                            end
                    end
                    if strcmp(app.AlphapowerDropDown.Value, 'Scalar')
                        medium.alpha_power = app.AlphaPower.Value;
                    end
                    value = app.AlphamodeDropDown.Value;
                    switch value
                        case 'No absorption'
                            medium.alpha_mode = 'no_absorption';
                        case 'No dispersion'
                            medium.alpha_mode = 'no_dispersion';
                    end
                    
                    value = app.AlphasignDropDown.Value;
                    if strcmp(value,'2 element array')
                        medium.alpha_sign = [app.absorptionSpinner.Value,app.dispersionSpinner.Value];
                    end
                    %% Sensors Properties
                    % Acoustic parameters to record
                    record = {};
                    if app.acousticpressureCheckBox.Value; record{end+1}= 'p'; end
                    if app.maxpressureCheckBox.Value; record{end+1}= 'p_max'; end
                    if app.minpressureCheckBox.Value; record{end+1}= 'p_min'; end
                    if app.RMSpressureCheckBox.Value; record{end+1}= 'p_rms'; end
                    if app.finalpressurefieldCheckBox.Value; record{end+1}= 'p_final'; end
                    if app.maxpressureatallgridpointsCheckBox.Value; record{end+1}= 'p_max_all'; end
                    if app.minpressureatallgridpointsCheckBox.Value; record{end+1}= 'p_min_all'; end
                    if app.particlevelocityCheckBox.Value; record{end+1}= 'u'; end
                    if app.maxparticlevelocityCheckBox.Value; record{end+1}= 'u_max'; end
                    if app.minparticlevelocityCheckBox.Value; record{end+1}= 'u_min'; end
                    if app.RMSparticlevelocityCheckBox.Value; record{end+1}= 'u_rms'; end
                    if app.finalparticlevelocityfieldCheckBox.Value; record{end+1}= 'u_final'; end
                    if app.maxvelocityatallgridpointsCheckBox.Value; record{end+1}= 'u_max_all'; end
                    if app.minvelocityatallgridpointsCheckBox.Value; record{end+1}= 'u_min_all'; end
                    if app.particlevelocityonnonstaggeredgridpointsCheckBox.Value; record{end+1}= 'u_non_staggered'; end
                    if app.timevaryingacousticintensityCheckBox.Value; record{end+1}= 'I'; end
                    if app.averageacousticintensityCheckBox.Value; record{end+1}= 'I_avg'; end
                    if ~isempty(record)
                        sensor.record = record;
                    end
                    
                    sensor.record_start_index = app.RecordStartIndex.Value;
                    if app.FrequencyresponseCheckBox.Value
                        sensor.frequency_response = [app.CenterFrequency.Value*1e6,app.Bandwidth.Value];
                    end
                    
%                     if app.DirectivityangleCheckBox.Value
%                         if ~app.DirectivityAngle
%                             sensor.directivity_angle = app.directivityAngle;
%                         else
%                             errordlg("Please load a matrix for the 'Directivity angle' checkbox option or use other options.",'Error');
%                         end
%                     end
                    
%                     if app.DirectivitysizemmCheckBox.Value
%                         sensor.directivity_size = app.DirectivitySize.Value;
%                     end
               
                    % Sensors Mask
                    sensor.mask = [1e-3.*(app.sensorsPositions(:,2)'-app.CenterymmSpinner.Value); 1e-3.*(app.sensorsPositions(:,1)'-app.CenterxmmSpinner.Value)];
                    if app.DirectivityangleCheckBox.Value
                        binarySensorsMask  = cart2grid(kgrid, sensor.mask);
                        sensor.mask = [];
                        sensor.mask = binarySensorsMask;
                        sensor.directivity_angle = zeros(app.Ny, app.Nx);
                        sensor.directivity_angle(sensor.mask == 1) = app.dirAngles;
                        % define the directivity pattern
                        sensor.directivity_pattern = 'pressure';
                        % define the directivity size
                        sensor.directivity_size = app.DirectivitySize.Value*1e-3;
                        figure;
                        ax1=subplot(121); imshow(flip(sensor.mask),[]); title("Sensors mask")
                        ax2=subplot(122); imshow(flip(sensor.directivity_angle),[]); title("Directivity angles")
                        linkaxes([ax1,ax2], 'xy')
                    end
                    %% Initial Pressure Distribution
                    source.p0 = double(flip(app.initialsMask));
                    %% Input Arguments
                    if ~isempty(app.Args)
                        input_args = app.Args;
                    else
                        input_args = {'PlotLayout', true, 'PlotPML', false, ...
                            'DataCast', 'single'};
                    end
                    %% Run the Simulation
                    output = kspaceFirstOrder2D(kgrid, medium, source, sensor, input_args{:});
                case '3D'
                    %% Medium Properties
                    kgrid = kWaveGrid(app.Ny, app.GridsizeymmEditField.Value*1e-3, app.Nx, app.GridsizexmmEditField.Value*1e-3, app.Nz, app.GridsizezmmEditField.Value*1e-3);
                    medium.sound_speed = app.SoS.Value .* ones(app.Ny, app.Nx, app.Nz);	     % [m/s]
                    medium.density = app.Density.Value * ones(app.Ny, app.Nx, app.Nz);       % [kg/m^3]
                    %% Initial Pressure Distribution
                    source.p0 = app.initialsMask;
                    %% Sensors Mask
                    center_x = app.CenterxmmSpinner.Value;
                    center_y = app.CenterymmSpinner.Value;
                    center_z = app.CenterzmmSpinner.Value;
                    sensor.mask = [1e-3.*(app.sensorsPositions(:,2)-center_y)'; 1e-3.*(app.sensorsPositions(:,1)-center_x)'; 1e-3.*(app.sensorsPositions(:,3)-center_z)'];
                    %% input arguments
                    input_args = {'PlotLayout', true, 'PlotPML', false, ...
                        'DataCast', 'single', 'CartInterp', 'nearest'};
                    % run the simulation
                    output = kspaceFirstOrder3D(kgrid, medium, source, sensor, input_args{:});
            end
            app.sensor_data = output;
            % Call main app's public function
            UpdateLoadedData(app.CallingApp, output.p);
%              catch ME
%                 uialert(app.PAT_Rec_AddsOn_simulation, ME.message, 'Error');
%                 return;
%             end
        end

        % Callback function
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            switch selectedButton.Text
                case 'Non'
                    app.CenterFrequency.Enable = 'Off';
                    app.Bandwidth.Enable = 'Off';
                case 'Gaussian'
                    app.CenterFrequency.Enable = 'On';
                    app.Bandwidth.Enable = 'On';
                case 'Load customized'
                    app.CenterFrequency.Enable = 'Off';
                    app.Bandwidth.Enable = 'Off';
            end
        end

        % Value changed function: SamplingRate, SoS, 
        % xsizemmSpinner, ysizemmSpinner, zsizemmSpinner
        function xsizemmSpinnerValueChanged(app, event)
%             ClearallButtonPushed(app);
            app.GridsizexmmEditField.Value = 1000*app.SoS.Value /(app.SamplingRate.Value*1e6);
            app.GridsizeymmEditField.Value = 1000*app.SoS.Value /(app.SamplingRate.Value*1e6);
            app.GridsizezmmEditField.Value = 1000*app.SoS.Value /(app.SamplingRate.Value*1e6);
            app.NumberofgridsxEditField.Value = round(app.xsizemmSpinner.Value/app.GridsizexmmEditField.Value);
            app.NumberofgridsyEditField.Value = round(app.ysizemmSpinner.Value/app.GridsizeymmEditField.Value);
            app.NumberofpixelszEditField.Value = round(app.zsizemmSpinner.Value/app.GridsizezmmEditField.Value);
        end

        % Value changed function: SpeedofsoundDropDown
        function SpeedofsoundDropDownValueChanged(app, event)
            value = app.SpeedofsoundDropDown.Value;
            switch value 
                case 'Scalar'
                    app.SOSLoadButton.Visible = 'Off';
                    app.SoS.Visible = 'On';
                    app.SOSDesignButton.Visible = 'Off';
                case 'Load a matrix'
                    app.SOSLoadButton.Visible = 'On';
                    app.SoS.Visible = 'Off';
                    app.SOSDesignButton.Visible = 'Off';
                case 'Design a matrix'
                    app.SOSLoadButton.Visible = 'Off';
                    app.SoS.Visible = 'Off';
                    app.SOSDesignButton.Visible = 'On';
            end
        end

        % Value changed function: RefSOSDropdown
        function RefSOSDropdownValueChanged(app, event)
            value = app.RefSOSDropdown.Value;
            switch value 
                case 'Defaults to the maximum sound speed within the medium'
                    app.SoS_ref.Visible = 'Off';
                case 'Scalar'
                    app.SoS_ref.Visible = 'On';
            end
        end

        % Value changed function: DensityDropDown
        function DensityDropDownValueChanged(app, event)
            value = app.DensityDropDown.Value;
            switch value
                case 'Non (homogeneous and lossless media)'
                    app.Density.Visible = 'Off';
%                     app.DensityDesignButton.Visible = 'Off';
                    app.DensityLoadButton.Visible = 'Off';
                case 'Scalar'
                    app.Density.Visible = 'On';
%                     app.DensityDesignButton.Visible = 'Off';
                    app.DensityLoadButton.Visible = 'Off';
                case 'Load a matrix'
                    app.Density.Visible = 'Off';
%                     app.DensityDesignButton.Visible = 'Off';
                    app.DensityLoadButton.Visible = 'On';
                case 'Design a matrix'
                    app.Density.Visible = 'Off';
%                     app.DensityDesignButton.Visible = 'On';
                    app.DensityLoadButton.Visible = 'Off';
            end
        end

        % Value changed function: FrequencyresponseCheckBox
        function FrequencyresponseCheckBoxValueChanged(app, event)
            value = app.FrequencyresponseCheckBox.Value;
            if value
                app.CenterFrequency.Enable = 'On';
                app.CenterfrequencyMHzLabel.Enable = 'On';
                app.Bandwidth.Enable = 'On';
                app.BandwidthLabel.Enable = 'On';
            else
                app.CenterFrequency.Enable = 'Off';
                app.CenterfrequencyMHzLabel.Enable = 'Off';
                app.Bandwidth.Enable = 'Off'; 
                app.BandwidthLabel.Enable = 'Off';
            end
        end

        % Button pushed function: 
        % DefineOptionalInputParametersButton
        function DefineOptionalInputParametersButtonPushed(app, event)
            InputParametersAPP(app);
            
        end

        % Value changed function: DirectivityangleCheckBox
        function DirectivityangleCheckBoxValueChanged(app, event)
            value = app.DirectivityangleCheckBox.Value;
            if value
                app.DirectivityAngle.Enable = 'On';
            else
                app.DirectivityAngle.Enable = 'Off';
            end
        end

        % Value changed function: DirectivitysizemmCheckBox
        function DirectivitysizemmCheckBoxValueChanged(app, event)
            value = app.DirectivitysizemmCheckBox.Value;
            if value
                app.DirectivitySize.Enable = 'On';
            else
                app.DirectivitySize.Enable = 'Off';
            end
        end

        % Value changed function: AlphasignDropDown
        function AlphasignDropDownValueChanged(app, event)
            value = app.AlphasignDropDown.Value;
            if strcmp(value, '2 element array')
                app.absorptionSpinner.Enable = 'On';
                app.dispersionSpinner.Enable = 'On';
            else
                app.absorptionSpinner.Enable = 'Off';
                app.dispersionSpinner.Enable = 'Off';
            end
        end

        % Value changed function: AlphafilterDropDown
        function AlphafilterDropDownValueChanged(app, event)
            value = app.AlphafilterDropDown.Value;
            if strcmp(value, 'Design filter')
                app.DensityAlphaFilterButton.Visible = 'On';
            else
                app.DensityAlphaFilterButton.Visible = 'Off';
            end
        end

        % Value changed function: NonlinearityDropDown
        function NonlinearityDropDownValueChanged(app, event)
            value = app.NonlinearityDropDown.Value;
            switch value
                case 'Non (linear and lossless)'
                    app.BonaScalar.Visible = 'Off';
                case 'Scalar'
                    app.BonaScalar.Visible = 'On';
            end
        end

        % Value changed function: AlphacoefficientDropDown
        function AlphacoefficientDropDownValueChanged(app, event)
            value = app.AlphacoefficientDropDown.Value;
            switch value
                case 'Non (linear and lossless)'
                    app.AlphaCoeff.Visible = 'Off';
                    app.AlphsCoeffButton.Visible = 'Off';
                case 'Scalar'
                    app.AlphaCoeff.Visible = 'On';
                    app.AlphsCoeffButton.Visible = 'Off';
                case 'Load a matrix'
                    app.AlphaCoeff.Visible = 'Off';
                    app.AlphsCoeffButton.Visible = 'On';
                case 'Design a matrix'
                    app.AlphaCoeff.Visible = 'Off';
                    app.AlphsCoeffButton.Visible = 'Off';
            end
        end

        % Value changed function: AlphapowerDropDown
        function AlphapowerDropDownValueChanged(app, event)
            value = app.AlphapowerDropDown.Value;
            switch value
                case 'Non (linear and lossless)'
                    app.AlphaPower.Visible = 'Off';
                case 'Scalar'
                    app.AlphaPower.Visible = 'On';
            end
        end

        % Button pushed function: SOSDesignButton
        function SOSDesignButtonPushed(app, event)
            if size(app.sensorsPositions,2) ==2
                dimensionInfo = [app.NumberofgridsyEditField.Value, app.NumberofgridsxEditField.Value];
            else
                dimensionInfo = [app.NumberofgridsyEditField.Value, app.NumberofgridsxEditField.Value, app.NumberofpixelszEditField.Value];
            end
            DesignSoSmap(app, dimensionInfo);
        end

        % Button pushed function: SOSLoadButton
        function SOSLoadButtonPushed(app, event)
            try
                f = app.PAT_Rec_AddsOn_simulation;
                d = uiprogressdlg(f,'Title','Loading SOS map file','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_Rec_AddsOn_simulation.Visible = 'Off';
                app.PAT_Rec_AddsOn_simulation.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    temp = value{1};
                elseif strcmp(ext, '.txt')
                    temp =  load(fullfile(PathName,filename));
                end
                app.SoSmap = temp;
                close(d);
            catch ME
                uialert(app.PAT_Rec_AddsOn_simulation, ME.message, 'Error');
                return;
            end
        end

        % Button pushed function: DensityLoadButton
        function DensityLoadButtonPushed(app, event)
            try
                f = app.PAT_Rec_AddsOn_simulation;
                d = uiprogressdlg(f,'Title','Loading Density map file','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_Rec_AddsOn_simulation.Visible = 'Off';
                app.PAT_Rec_AddsOn_simulation.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    temp = value{1};
                elseif strcmp(ext, '.txt')
                    temp =  load(fullfile(PathName,filename));
                end
                app.DensityMap = temp;
                close(d);
            catch ME
                uialert(app.PAT_Rec_AddsOn_simulation, ME.message, 'Error');
                return;
            end
        end

        % Button pushed function: AlphsCoeffButton
        function AlphsCoeffButtonPushed(app, event)
            try
                f = app.PAT_Rec_AddsOn_simulation;
                d = uiprogressdlg(f,'Title','Loading Alpha Coefficient matrix ','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_Rec_AddsOn_simulation.Visible = 'Off';
                app.PAT_Rec_AddsOn_simulation.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    temp = value{1};
                elseif strcmp(ext, '.txt')
                    temp =  load(fullfile(PathName,filename));
                end
                app.AlphaCoeffMap = temp;
                close(d);
            catch ME
                uialert(app.PAT_Rec_AddsOn_simulation, ME.message, 'Error');
                return;
            end
        end

        % Button pushed function: DirectivityAngle
        function DirectivityAngleButtonPushed(app, event)
            if size(app.sensorsPositions, 2)==2
                %                 ny = app.NumberofgridsyEditField.Value;
                %                 nx = app.NumberofgridsxEditField.Value;
                %
%                 gridDimInfo = [app.NumberofgridsyEditField.Value, app.NumberofgridsxEditField.Value];
%                 gridSizeInfo = [app.GridsizeymmEditField.Value*1e-3, app.GridsizexmmEditField.Value*1e-3];
                %                 LoadDirectivityApp(app, dimensionInfo);
                
                % Sensors Mask
                sensorMask = [1e-3.*(app.sensorsPositions(:,2)'-app.CenterymmSpinner.Value); 1e-3.*(app.sensorsPositions(:,1)'-app.CenterxmmSpinner.Value)];
                
                guiHandle = LoadDirectivityApp(app, sensorMask);
                guiFigureHandle = guiHandle.DirectivityLoad;
                uiwait(guiFigureHandle)
                app.DirectivityAngle.BackgroundColor = [0.39,0.83,0.07];
            else
                uialert(app.PAT_Rec_AddsOn_simulation, 'This function is only designed for 2D simulation!', 'Error');
            end
            
            %             try
%                 f = app.PAT_Rec_AddsOn_simulation;
%                 d = uiprogressdlg(f,'Title','Loading Directivity angle map','Indeterminate','on');
%                 [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
%                 app.PAT_Rec_AddsOn_simulation.Visible = 'Off';
%                 app.PAT_Rec_AddsOn_simulation.Visible = 'On';
%                 [~,~,ext] = fileparts(filename);
%                 if strcmp(ext, '.mat')
%                     x =  load(fullfile(PathName,filename));
%                     value = struct2cell(x);
%                     temp = value{1};
%                 elseif strcmp(ext, '.txt')
%                     temp =  load(fullfile(PathName,filename));
%                 end
%                 app.DirectivityAngle = temp;
%                 
%                
%                 close(d);
%             catch ME
%                 uialert(app.PAT_Rec_AddsOn_simulation, ME.message, 'Error');
%                 return;
%             end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            s1 = app.sensor_data;
            formatOut = 'dd_mmm_yyyy';
            tNow = datestr(now,formatOut);
            save(fullfile(cd, append('GeneratedData', tNow,'.mat')),'-struct','s1');
            message = sprintf('The file has been successfully saved to the directory: %s', cd);
            uialert(app.PAT_Rec_AddsOn_simulation,message,'Save', 'Icon','success');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [FileName,PathName] = uigetfile({['*.mat;*.fig;*.jpg; *.JPG; *.jpeg; *.JPEG;...' ...
                '*.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF;*.PNG; *.png; *.bmp; *.BMP']}, 'Select an image');
            complete_name = fullfile(PathName, FileName);
            if isequal(FileName,0)
                disp('User selected Cancel');
                app.LoadImageButton.BackgroundColor = [0.80,0.80,0.80];
            else
                app.PAT_Rec_AddsOn_simulation.Visible = 'Off';
                app.PAT_Rec_AddsOn_simulation.Visible = 'On';
                app.loadedImage = imread(complete_name);
                app.LoadImageButton.BackgroundColor = [0.39,0.83,0.07];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PAT_Rec_AddsOn_simulation = uifigure('Visible', 'off');
            app.PAT_Rec_AddsOn_simulation.Name = 'UI Figure';
            app.PAT_Rec_AddsOn_simulation.Icon = 'PatLab_Logo_1.png';
            app.Panel = uipanel(app.PAT_Rec_AddsOn_simulation);
            app.ImageAxes = uiaxes(app.Panel);
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.ImageAxes.Visible = 'off';
            app.DefineInitialPressureDistributionPanel = uipanel(app.Panel);
            app.DefineInitialPressureDistributionPanel.Title = 'Define Initial Pressure Distribution:';
            app.ObjectLabel_2 = uilabel(app.DefineInitialPressureDistributionPanel);
            app.ObjectLabel_2.Text = 'Object:';
            app.ObjectDropDown_2D = uidropdown(app.DefineInitialPressureDistributionPanel);
            app.ObjectDropDown_2D.Items = {'Disk', 'Rectangle', 'Modified Shepp-Logan phantom', 'Load Image'};
            app.ObjectDropDown_2D.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDown_2DValueChanged, true);
            app.ObjectDropDown_2D.Value = 'Disk';
            app.ObjectLabel = uilabel(app.DefineInitialPressureDistributionPanel);
            app.ObjectLabel.Text = 'Object:';
            app.ObjectDropDown_3D = uidropdown(app.DefineInitialPressureDistributionPanel);
            app.ObjectDropDown_3D.Items = {'Ball', 'Cube', 'Bar'};
            app.ObjectDropDown_3D.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDown_3DValueChanged, true);
            app.ObjectDropDown_3D.Value = 'Ball';
            app.AddButton = uibutton(app.DefineInitialPressureDistributionPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ProcessButton = uibutton(app.DefineInitialPressureDistributionPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ClearallButton = uibutton(app.DefineInitialPressureDistributionPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.SaveButton = uibutton(app.DefineInitialPressureDistributionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel_Disk2D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.RadiusmmLabel_3 = uilabel(app.Panel_Disk2D);
            app.RadiusmmLabel_3.Text = 'Radius [mm]:';
            app.radius_disk = uispinner(app.Panel_Disk2D);
            app.radius_disk.Limits = [0 Inf];
            app.radius_disk.Value = 1;
            app.ySpinner_disk2D = uilabel(app.Panel_Disk2D);
            app.ySpinner_disk2D.Text = 'y';
            app.CenterY_disk2D = uispinner(app.Panel_Disk2D);
            app.CentercoordinatemmxLabel = uilabel(app.Panel_Disk2D);
            app.CentercoordinatemmxLabel.Text = 'Center coordinate [mm]:                                  x';
            app.CenterX_disk2D = uispinner(app.Panel_Disk2D);
            app.MagnitudePaLabel_7 = uilabel(app.Panel_Disk2D);
            app.MagnitudePaLabel_7.Text = 'Magnitude [Pa]:';
            app.magnitude_disk = uispinner(app.Panel_Disk2D);
            app.magnitude_disk.Limits = [0 Inf];
            app.magnitude_disk.Value = 5;
            app.Panel_rect2D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_rect2D.Visible = 'off';
            app.LengthmmLabel = uilabel(app.Panel_rect2D);
            app.LengthmmLabel.Text = 'Length [mm]:';
            app.Length_rec2D = uispinner(app.Panel_rect2D);
            app.Length_rec2D.Limits = [0 Inf];
            app.Length_rec2D.Value = 5;
            app.WidthmmLabel_3 = uilabel(app.Panel_rect2D);
            app.WidthmmLabel_3.Text = 'Width [mm]:';
            app.width_rec2D = uispinner(app.Panel_rect2D);
            app.width_rec2D.Limits = [0 Inf];
            app.width_rec2D.Value = 1;
            app.ySpinner_rec2D = uilabel(app.Panel_rect2D);
            app.ySpinner_rec2D.Text = 'y';
            app.CenterY_rec2D = uispinner(app.Panel_rect2D);
            app.CentercoordinatemmxLabel_2 = uilabel(app.Panel_rect2D);
            app.CentercoordinatemmxLabel_2.Text = 'Center coordinate [mm]:                                      x';
            app.CenterX_rec2D = uispinner(app.Panel_rect2D);
            app.AngledegLabel = uilabel(app.Panel_rect2D);
            app.AngledegLabel.Text = 'Angle [deg]:';
            app.Angle_rec2D = uispinner(app.Panel_rect2D);
            app.Angle_rec2D.Limits = [0 Inf];
            app.MagnitudePaLabel_8 = uilabel(app.Panel_rect2D);
            app.MagnitudePaLabel_8.Text = 'Magnitude [Pa]:';
            app.magnitude_Rect = uispinner(app.Panel_rect2D);
            app.magnitude_Rect.Limits = [0 Inf];
            app.magnitude_Rect.Value = 5;
            app.Panel_Ball3D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_Ball3D.Visible = 'off';
            app.RadiusmmLabel_4 = uilabel(app.Panel_Ball3D);
            app.RadiusmmLabel_4.Text = 'Radius [mm]:';
            app.radius_ball3d = uispinner(app.Panel_Ball3D);
            app.radius_ball3d.Limits = [0 Inf];
            app.radius_ball3d.Value = 1;
            app.ySpinner_ball3d = uilabel(app.Panel_Ball3D);
            app.ySpinner_ball3d.Text = 'y';
            app.CenterY_ball3d = uispinner(app.Panel_Ball3D);
            app.CentermmxLabel = uilabel(app.Panel_Ball3D);
            app.CentermmxLabel.Text = 'Center [mm]:                 x';
            app.CenterX_ball3d = uispinner(app.Panel_Ball3D);
            app.MagnitudePaLabel_10 = uilabel(app.Panel_Ball3D);
            app.MagnitudePaLabel_10.Text = 'Magnitude [Pa]:';
            app.magnitude_ball3d = uispinner(app.Panel_Ball3D);
            app.magnitude_ball3d.Limits = [0 Inf];
            app.magnitude_ball3d.Value = 5;
            app.zSpinner_ball3d = uilabel(app.Panel_Ball3D);
            app.zSpinner_ball3d.Text = 'z';
            app.CenterZ_ball3d = uispinner(app.Panel_Ball3D);
            app.Panel_Cube3D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_Cube3D.Visible = 'off';
            app.DimensionsmmxLabel = uilabel(app.Panel_Cube3D);
            app.DimensionsmmxLabel.Text = 'Dimensions [mm]:          x';
            app.sizeX_Cube3D = uispinner(app.Panel_Cube3D);
            app.sizeX_Cube3D.Limits = [0 Inf];
            app.sizeX_Cube3D.Value = 2;
            app.CentermmxLabel_2 = uilabel(app.Panel_Cube3D);
            app.CentermmxLabel_2.Text = 'Center [mm]:                 x';
            app.CenterX_Cube3D = uispinner(app.Panel_Cube3D);
            app.MagnitudePaLabel_11 = uilabel(app.Panel_Cube3D);
            app.MagnitudePaLabel_11.Text = 'Magnitude [Pa]:';
            app.magnitude_Cube3D = uispinner(app.Panel_Cube3D);
            app.magnitude_Cube3D.Limits = [0 Inf];
            app.magnitude_Cube3D.Value = 5;
            app.yLabel = uilabel(app.Panel_Cube3D);
            app.yLabel.Text = 'y';
            app.sizeY_Cube3D = uispinner(app.Panel_Cube3D);
            app.sizeY_Cube3D.Limits = [0 Inf];
            app.sizeY_Cube3D.Value = 2;
            app.yLabel_2 = uilabel(app.Panel_Cube3D);
            app.yLabel_2.Text = 'y';
            app.CenterY_Cube3D = uispinner(app.Panel_Cube3D);
            app.CenterY_Cube3D.Value = 1;
            app.zLabel = uilabel(app.Panel_Cube3D);
            app.zLabel.Text = 'z';
            app.CenterZ_Cube3D = uispinner(app.Panel_Cube3D);
            app.CenterZ_Cube3D.Value = 1;
            app.yLabel_3 = uilabel(app.Panel_Cube3D);
            app.yLabel_3.Text = 'y';
            app.sizeZ_Cube3D = uispinner(app.Panel_Cube3D);
            app.sizeZ_Cube3D.Limits = [0 Inf];
            app.sizeZ_Cube3D.Value = 2;
            app.Panel_Bar3D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_Bar3D.Visible = 'off';
            app.LengthmmLabel_2 = uilabel(app.Panel_Bar3D);
            app.LengthmmLabel_2.Text = 'Length [mm]:';
            app.length_Bar = uispinner(app.Panel_Bar3D);
            app.length_Bar.Limits = [0 Inf];
            app.length_Bar.Value = 10;
            app.CentermmxLabel_3 = uilabel(app.Panel_Bar3D);
            app.CentermmxLabel_3.Text = 'Center [mm]:                 x';
            app.CenterX_Bar = uispinner(app.Panel_Bar3D);
            app.MagnitudePaLabel_12 = uilabel(app.Panel_Bar3D);
            app.MagnitudePaLabel_12.Text = 'Magnitude [Pa]:';
            app.magnitude_Bar = uispinner(app.Panel_Bar3D);
            app.magnitude_Bar.Limits = [0 Inf];
            app.magnitude_Bar.Value = 5;
            app.zLabel_2 = uilabel(app.Panel_Bar3D);
            app.zLabel_2.Text = 'z';
            app.CenterZ_Bar = uispinner(app.Panel_Bar3D);
            app.DirectionLabel = uilabel(app.Panel_Bar3D);
            app.DirectionLabel.Text = 'Direction:';
            app.BarDirection = uibuttongroup(app.Panel_Bar3D);
            app.xButton = uiradiobutton(app.BarDirection);
            app.xButton.Text = 'x';
            app.yButton = uiradiobutton(app.BarDirection);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.BarDirection);
            app.zButton.Text = 'z';
            app.zButton.Value = true;
            app.RadiusmmLabel_5 = uilabel(app.Panel_Bar3D);
            app.RadiusmmLabel_5.Text = 'Radius [mm]:';
            app.radius_Bar = uispinner(app.Panel_Bar3D);
            app.radius_Bar.Limits = [0 Inf];
            app.radius_Bar.Value = 1;
            app.yLabel_4 = uilabel(app.Panel_Bar3D);
            app.yLabel_4.Text = 'y';
            app.CenterY_Bar = uispinner(app.Panel_Bar3D);
            app.Panel_LoadImage2D = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_LoadImage2D.Visible = 'off';
            app.WidthmmLabel_4 = uilabel(app.Panel_LoadImage2D);
            app.WidthmmLabel_4.Text = 'Width [mm]:';
            app.Width_phantom = uispinner(app.Panel_LoadImage2D);
            app.Width_phantom.Limits = [0 Inf];
            app.Width_phantom.Value = 5;
            app.ySpinner_phantom = uilabel(app.Panel_LoadImage2D);
            app.ySpinner_phantom.Text = 'y';
            app.CenterY_phantom = uispinner(app.Panel_LoadImage2D);
            app.CenterX_phantom = uispinner(app.Panel_LoadImage2D);
            app.MagnitudePaLabel_13 = uilabel(app.Panel_LoadImage2D);
            app.MagnitudePaLabel_13.Text = 'Magnitude [Pa]:';
            app.magnitude_phantom = uispinner(app.Panel_LoadImage2D);
            app.magnitude_phantom.Limits = [0 Inf];
            app.magnitude_phantom.Value = 5;
            app.CentercoordinatemmxSpinnerLabel = uilabel(app.Panel_LoadImage2D);
            app.CentercoordinatemmxSpinnerLabel.Text = 'Center coordinate [mm]:                                 x';
            app.LoadImageButton = uibutton(app.Panel_LoadImage2D, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.Panel_ShepLogan = uipanel(app.DefineInitialPressureDistributionPanel);
            app.Panel_ShepLogan.Visible = 'off';
            app.WidthmmLabel_5 = uilabel(app.Panel_ShepLogan);
            app.WidthmmLabel_5.Text = 'Width [mm]:';
            app.Width_phantom_2 = uispinner(app.Panel_ShepLogan);
            app.Width_phantom_2.Limits = [0 Inf];
            app.Width_phantom_2.Value = 5;
            app.ySpinner_phantom_2 = uilabel(app.Panel_ShepLogan);
            app.ySpinner_phantom_2.Text = 'y';
            app.CenterY_phantom_2 = uispinner(app.Panel_ShepLogan);
            app.CenterX_phantom_2 = uispinner(app.Panel_ShepLogan);
            app.MagnitudePaLabel_14 = uilabel(app.Panel_ShepLogan);
            app.MagnitudePaLabel_14.Text = 'Magnitude [Pa]:';
            app.magnitude_phantom_2 = uispinner(app.Panel_ShepLogan);
            app.magnitude_phantom_2.Limits = [0 Inf];
            app.magnitude_phantom_2.Value = 5;
            app.CentercoordinatemmxSpinnerLabel_2 = uilabel(app.Panel_ShepLogan);
            app.CentercoordinatemmxSpinnerLabel_2.Text = 'Center coordinate [mm]:                                 x';
            app.DefineAcousticMediumPanel = uipanel(app.Panel);
            app.DefineAcousticMediumPanel.Title = 'Define Acoustic Medium:';
            app.SoS = uispinner(app.DefineAcousticMediumPanel);
            app.SoS.Limits = [0 Inf];
            app.SoS.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.SoS.Tooltip = {'[m/s]'};
            app.SoS.Value = 1500;
            app.AlphaCoeff = uispinner(app.DefineAcousticMediumPanel);
            app.AlphaCoeff.Visible = 'off';
            app.AlphaCoeff.Tooltip = {''};
            app.AlphaCoeff.Value = 0.75;
            app.AlphaPower = uispinner(app.DefineAcousticMediumPanel);
            app.AlphaPower.Limits = [0 Inf];
            app.AlphaPower.Visible = 'off';
            app.AlphaPower.Tooltip = {'must'; 'be between 0 and 3 and not equal to 1'};
            app.AlphaPower.Value = 1.5;
            app.Density = uispinner(app.DefineAcousticMediumPanel);
            app.Density.Limits = [0 Inf];
            app.Density.Visible = 'off';
            app.Density.Tooltip = {'[kg/m^3]'};
            app.Density.Value = 1000;
            app.SpeedofsoundDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.SpeedofsoundDropDownLabel.Text = 'Speed of sound:';
            app.SpeedofsoundDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.SpeedofsoundDropDown.Items = {'Scalar', 'Load a matrix', 'Design a matrix'};
            app.SpeedofsoundDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedofsoundDropDownValueChanged, true);
            app.SpeedofsoundDropDown.Tooltip = {'sound speed distribution within the medium'};
            app.SpeedofsoundDropDown.Value = 'Scalar';
            app.DensityDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.DensityDropDownLabel.Text = 'Density:             ';
            app.DensityDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.DensityDropDown.Items = {'Non (homogeneous and lossless media)', 'Scalar', 'Load a matrix'};
            app.DensityDropDown.ValueChangedFcn = createCallbackFcn(app, @DensityDropDownValueChanged, true);
            app.DensityDropDown.Tooltip = {'ambient density distribution within the medium'};
            app.DensityDropDown.Value = 'Non (homogeneous and lossless media)';
            app.NonlinearityDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.NonlinearityDropDownLabel.Text = 'Nonlinearity:';
            app.NonlinearityDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.NonlinearityDropDown.Items = {'Non (linear and lossless)', 'Scalar'};
            app.NonlinearityDropDown.ValueChangedFcn = createCallbackFcn(app, @NonlinearityDropDownValueChanged, true);
            app.NonlinearityDropDown.Tooltip = {'BonA'};
            app.NonlinearityDropDown.Value = 'Non (linear and lossless)';
            app.AlphapowerDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.AlphapowerDropDownLabel.Text = 'Alpha power:';
            app.AlphapowerDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.AlphapowerDropDown.Items = {'Non (linear and lossless)', 'Scalar'};
            app.AlphapowerDropDown.ValueChangedFcn = createCallbackFcn(app, @AlphapowerDropDownValueChanged, true);
            app.AlphapowerDropDown.Tooltip = {'power law absorption exponent'};
            app.AlphapowerDropDown.Value = 'Non (linear and lossless)';
            app.ReferencesoundspeedusedinthekspaceoperatorDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.ReferencesoundspeedusedinthekspaceoperatorDropDownLabel.Tooltip = {'Reference sound speed used in the k-space operator:'};
            app.ReferencesoundspeedusedinthekspaceoperatorDropDownLabel.Text = 'Reference sound speed used in the k-space operator:';
            app.RefSOSDropdown = uidropdown(app.DefineAcousticMediumPanel);
            app.RefSOSDropdown.Items = {'Defaults to the maximum sound speed within the medium', 'Scalar'};
            app.RefSOSDropdown.ValueChangedFcn = createCallbackFcn(app, @RefSOSDropdownValueChanged, true);
            app.RefSOSDropdown.Value = 'Defaults to the maximum sound speed within the medium';
            app.AlphamodeDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.AlphamodeDropDownLabel.Text = 'Alpha mode:';
            app.AlphamodeDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.AlphamodeDropDown.Items = {'Non', 'No absorption', 'No dispersion'};
            app.AlphamodeDropDown.Tooltip = {'optional input to force either the absorption or dispersion'; 'terms in the equation of state to be excluded'};
            app.AlphamodeDropDown.Value = 'Non';
            app.AlphasignDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.AlphasignDropDownLabel.Text = 'Alpha sign:';
            app.AlphasignDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.AlphasignDropDown.Items = {'Non', '2 element array'};
            app.AlphasignDropDown.ValueChangedFcn = createCallbackFcn(app, @AlphasignDropDownValueChanged, true);
            app.AlphasignDropDown.Tooltip = {'two element array used to control the sign of absorption'; 'and dispersion terms in the pressure-density relation'};
            app.AlphasignDropDown.Value = 'Non';
            app.AlphafilterDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.AlphafilterDropDownLabel.Text = 'Alpha filter:';
            app.AlphafilterDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.AlphafilterDropDown.Items = {'Non', 'Design filter'};
            app.AlphafilterDropDown.ValueChangedFcn = createCallbackFcn(app, @AlphafilterDropDownValueChanged, true);
            app.AlphafilterDropDown.Tooltip = {'frequency domain filter applied to the absorption and dispersion terms in the pressure density relation'};
            app.AlphafilterDropDown.Value = 'Non';
            app.SoS_ref = uispinner(app.DefineAcousticMediumPanel);
            app.SoS_ref.Limits = [0 Inf];
            app.SoS_ref.Visible = 'off';
            app.SoS_ref.Tooltip = {'[m/s]'};
            app.SoS_ref.Value = 1500;
            app.SOSLoadButton = uibutton(app.DefineAcousticMediumPanel, 'push');
            app.SOSLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SOSLoadButtonPushed, true);
            app.SOSLoadButton.Visible = 'off';
            app.SOSLoadButton.Text = 'Load';
            app.SOSDesignButton = uibutton(app.DefineAcousticMediumPanel, 'push');
            app.SOSDesignButton.ButtonPushedFcn = createCallbackFcn(app, @SOSDesignButtonPushed, true);
            app.SOSDesignButton.Visible = 'off';
            app.SOSDesignButton.Text = 'Design';
            app.DensityLoadButton = uibutton(app.DefineAcousticMediumPanel, 'push');
            app.DensityLoadButton.ButtonPushedFcn = createCallbackFcn(app, @DensityLoadButtonPushed, true);
            app.DensityLoadButton.Visible = 'off';
            app.DensityLoadButton.Text = 'Load';
            app.AlphacoefficientDropDownLabel = uilabel(app.DefineAcousticMediumPanel);
            app.AlphacoefficientDropDownLabel.Text = 'Alpha coefficient:';
            app.AlphacoefficientDropDown = uidropdown(app.DefineAcousticMediumPanel);
            app.AlphacoefficientDropDown.Items = {'Non (linear and lossless)', 'Scalar', 'Load a matrix'};
            app.AlphacoefficientDropDown.ValueChangedFcn = createCallbackFcn(app, @AlphacoefficientDropDownValueChanged, true);
            app.AlphacoefficientDropDown.Tooltip = {'power law absorption prefactor [dB/(MHz^y cm)]'};
            app.AlphacoefficientDropDown.Value = 'Non (linear and lossless)';
            app.absorptionLabel = uilabel(app.DefineAcousticMediumPanel);
            app.absorptionLabel.Enable = 'off';
            app.absorptionLabel.Text = 'absorption';
            app.absorptionSpinner = uispinner(app.DefineAcousticMediumPanel);
            app.absorptionSpinner.Step = 2;
            app.absorptionSpinner.Limits = [-1 1];
            app.absorptionSpinner.Enable = 'off';
            app.absorptionSpinner.Value = 1;
            app.dispersionSpinnerLabel = uilabel(app.DefineAcousticMediumPanel);
            app.dispersionSpinnerLabel.Enable = 'off';
            app.dispersionSpinnerLabel.Text = 'dispersion';
            app.dispersionSpinner = uispinner(app.DefineAcousticMediumPanel);
            app.dispersionSpinner.Step = 2;
            app.dispersionSpinner.Limits = [-1 1];
            app.dispersionSpinner.Enable = 'off';
            app.dispersionSpinner.Value = 1;
            app.DensityAlphaFilterButton = uibutton(app.DefineAcousticMediumPanel, 'push');
            app.DensityAlphaFilterButton.Visible = 'off';
            app.DensityAlphaFilterButton.Text = 'Load';
            app.BonaScalar = uispinner(app.DefineAcousticMediumPanel);
            app.BonaScalar.Visible = 'off';
            app.BonaScalar.Tooltip = {''};
            app.BonaScalar.Value = 1;
            app.AlphsCoeffButton = uibutton(app.DefineAcousticMediumPanel, 'push');
            app.AlphsCoeffButton.ButtonPushedFcn = createCallbackFcn(app, @AlphsCoeffButtonPushed, true);
            app.AlphsCoeffButton.Visible = 'off';
            app.AlphsCoeffButton.Text = 'Load';
            app.DefineComputationalGridPanel = uipanel(app.Panel);
            app.DefineComputationalGridPanel.Title = 'Define Computational Grid:';
            app.CenterzmmLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterzmmLabel.Text = 'Center z [mm]:';
            app.CenterzmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.zsizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.zsizemmSpinnerLabel.Text = 'z size [mm]:';
            app.zsizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.zsizemmSpinner.Limits = [0 Inf];
            app.zsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.zsizemmSpinner.Value = 30;
            app.NumberofpixelszEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofpixelszEditFieldLabel.Text = 'Number of grids z:';
            app.NumberofpixelszEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofpixelszEditField.Editable = 'off';
            app.NumberofpixelszEditField.Tooltip = {'It is suggested that the total number of grid points in each direction (including the PML) to be a power of 2, or to have small prime factors.'};
            app.xsizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.xsizemmSpinnerLabel.Text = 'x size [mm]:';
            app.xsizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.xsizemmSpinner.Limits = [0 Inf];
            app.xsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.xsizemmSpinner.Value = 30;
            app.ysizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.ysizemmSpinnerLabel.Text = 'y size [mm]:';
            app.ysizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.ysizemmSpinner.Limits = [0 Inf];
            app.ysizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.ysizemmSpinner.Value = 30;
            app.CenterxmmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterxmmSpinnerLabel.Text = 'Center x [mm]:';
            app.CenterxmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.CenterymmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterymmSpinnerLabel.Text = 'Center y [mm]:';
            app.CenterymmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.NumberofgridsxEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofgridsxEditFieldLabel.Text = 'Number of grids x:';
            app.NumberofgridsxEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofgridsxEditField.Editable = 'off';
            app.NumberofgridsxEditField.Tooltip = {'It is suggested that the total number of grid points in each direction (including the PML) to be a power of 2, or to have small prime factors.'};
            app.NumberofgridsyLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofgridsyLabel.Text = 'Number of grids y:';
            app.NumberofgridsyEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofgridsyEditField.Editable = 'off';
            app.NumberofgridsyEditField.Tooltip = {'It is suggested that the total number of grid points in each direction (including the PML) to be a power of 2, or to have small prime factors.'};
            app.GridsizexmmEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.GridsizexmmEditFieldLabel.Text = 'Grid size x [mm]:';
            app.GridsizexmmEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.GridsizexmmEditField.Editable = 'off';
            app.GridsizeymmEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.GridsizeymmEditFieldLabel.Text = 'Grid size y [mm]:';
            app.GridsizeymmEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.GridsizeymmEditField.Editable = 'off';
            app.GridsizezmmEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.GridsizezmmEditFieldLabel.Text = 'Grid size z [mm]:';
            app.GridsizezmmEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.GridsizezmmEditField.Editable = 'off';
            app.DetectorPositionsInformationPanel = uipanel(app.DefineComputationalGridPanel);
            app.DetectorPositionsInformationPanel.Title = 'Detector Positions Information:';
            app.TransducersInfo = uitextarea(app.DetectorPositionsInformationPanel);
            app.TransducersInfo.Editable = 'off';
            app.SamplingrateMHzLabel_2 = uilabel(app.DefineComputationalGridPanel);
            app.SamplingrateMHzLabel_2.Text = 'Sampling rate [MHz]:';
            app.SamplingRate = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.SamplingRate.Value = 50;
            app.ListeningtimesLabel = uilabel(app.DefineComputationalGridPanel);
            app.ListeningtimesLabel.Text = 'Listening time [μs]:';
            app.ListeningTime = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.ListeningTime.Value = 30;
            app.StartSimulationButton = uibutton(app.Panel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.DefineDetectorPropertiesPanel = uipanel(app.Panel);
            app.DefineDetectorPropertiesPanel.Title = 'Define Detector Properties:';
            app.DirectivitySize = uispinner(app.DefineDetectorPropertiesPanel);
            app.DirectivitySize.Limits = [0 Inf];
            app.DirectivitySize.Enable = 'off';
            app.DirectivitySize.Value = 10;
            app.AcousticparameterstorecordLabel = uilabel(app.DefineDetectorPropertiesPanel);
            app.AcousticparameterstorecordLabel.Text = 'Acoustic parameters to record:';
            app.acousticpressureCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.acousticpressureCheckBox.Tooltip = {'acoustic pressure'};
            app.acousticpressureCheckBox.Text = 'acoustic pressure';
            app.acousticpressureCheckBox.Value = true;
            app.maxpressureCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.maxpressureCheckBox.Tooltip = {'max pressure'};
            app.maxpressureCheckBox.Text = 'max pressure';
            app.minpressureCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.minpressureCheckBox.Tooltip = {'min pressure'};
            app.minpressureCheckBox.Text = 'min pressure';
            app.RMSpressureCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.RMSpressureCheckBox.Tooltip = {'RMS pressure'};
            app.RMSpressureCheckBox.Text = 'RMS pressure';
            app.finalpressurefieldCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.finalpressurefieldCheckBox.Tooltip = {'final pressure field'};
            app.finalpressurefieldCheckBox.Text = 'final pressure field';
            app.maxpressureatallgridpointsCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.maxpressureatallgridpointsCheckBox.Tooltip = {'max pressure at all grid points'};
            app.maxpressureatallgridpointsCheckBox.Text = 'max pressure at all grid points';
            app.minpressureatallgridpointsCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.minpressureatallgridpointsCheckBox.Tooltip = {'min pressure at all grid points'};
            app.minpressureatallgridpointsCheckBox.Text = 'min pressure at all grid points';
            app.particlevelocityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.particlevelocityCheckBox.Tooltip = {'particle velocity'};
            app.particlevelocityCheckBox.Text = 'particle velocity';
            app.maxparticlevelocityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.maxparticlevelocityCheckBox.Tooltip = {'max particle velocity'};
            app.maxparticlevelocityCheckBox.Text = 'max particle velocity';
            app.minparticlevelocityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.minparticlevelocityCheckBox.Tooltip = {'min particle velocity'};
            app.minparticlevelocityCheckBox.Text = 'min particle velocity';
            app.RMSparticlevelocityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.RMSparticlevelocityCheckBox.Tooltip = {'RMS particle velocity'};
            app.RMSparticlevelocityCheckBox.Text = 'RMS particle velocity';
            app.finalparticlevelocityfieldCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.finalparticlevelocityfieldCheckBox.Tooltip = {'final particle velocity field'};
            app.finalparticlevelocityfieldCheckBox.Text = 'final particle velocity field';
            app.maxvelocityatallgridpointsCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.maxvelocityatallgridpointsCheckBox.Tooltip = {'max velocity at all grid points'};
            app.maxvelocityatallgridpointsCheckBox.Text = 'max velocity at all grid points';
            app.minvelocityatallgridpointsCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.minvelocityatallgridpointsCheckBox.Tooltip = {'min velocity at all grid points'};
            app.minvelocityatallgridpointsCheckBox.Text = 'min velocity at all grid points';
            app.particlevelocityonnonstaggeredgridpointsCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.particlevelocityonnonstaggeredgridpointsCheckBox.Tooltip = {'particle velocity on non-staggered grid points'};
            app.particlevelocityonnonstaggeredgridpointsCheckBox.Text = 'particle velocity on non-staggered grid points';
            app.timevaryingacousticintensityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.timevaryingacousticintensityCheckBox.Tooltip = {'time varying acoustic intensity'};
            app.timevaryingacousticintensityCheckBox.Text = 'time varying acoustic intensity';
            app.averageacousticintensityCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.averageacousticintensityCheckBox.Tooltip = {'average acoustic intensity'};
            app.averageacousticintensityCheckBox.Text = 'average acoustic intensity';
            app.FrequencyresponseCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.FrequencyresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @FrequencyresponseCheckBoxValueChanged, true);
            app.FrequencyresponseCheckBox.Text = 'Frequency response';
            app.RecordstartindexLabel = uilabel(app.DefineDetectorPropertiesPanel);
            app.RecordstartindexLabel.Tooltip = {'time index at which the sensor should start recording'; '(default = 1)'};
            app.RecordstartindexLabel.Text = 'Record start index:';
            app.RecordStartIndex = uispinner(app.DefineDetectorPropertiesPanel);
            app.RecordStartIndex.Limits = [1 Inf];
            app.RecordStartIndex.Value = 1;
            app.DirectivityangleCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.DirectivityangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectivityangleCheckBoxValueChanged, true);
            app.DirectivityangleCheckBox.Tooltip = {'Only supported in 2D'};
            app.DirectivityangleCheckBox.Text = 'Directivity angle';
            app.DirectivitysizemmCheckBox = uicheckbox(app.DefineDetectorPropertiesPanel);
            app.DirectivitysizemmCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectivitysizemmCheckBoxValueChanged, true);
            app.DirectivitysizemmCheckBox.Tooltip = {'Only supported in 2D'};
            app.DirectivitysizemmCheckBox.Text = 'Directivity size [mm]:';
            app.DirectivityAngle = uibutton(app.DefineDetectorPropertiesPanel, 'push');
            app.DirectivityAngle.ButtonPushedFcn = createCallbackFcn(app, @DirectivityAngleButtonPushed, true);
            app.DirectivityAngle.Enable = 'off';
            app.DirectivityAngle.Text = 'process';
            app.CenterFrequency = uispinner(app.DefineDetectorPropertiesPanel);
            app.CenterFrequency.Enable = 'off';
            app.Bandwidth = uispinner(app.DefineDetectorPropertiesPanel);
            app.Bandwidth.Enable = 'off';
            app.Bandwidth.Value = 80;
            app.BandwidthLabel = uilabel(app.DefineDetectorPropertiesPanel);
            app.BandwidthLabel.Enable = 'off';
            app.BandwidthLabel.Text = 'Bandwidth [%]:';
            app.CenterfrequencyMHzLabel = uilabel(app.DefineDetectorPropertiesPanel);
            app.CenterfrequencyMHzLabel.Enable = 'off';
            app.CenterfrequencyMHzLabel.Text = 'Center frequency [MHz]:';
            app.DefineOptionalInputParametersButton = uibutton(app.Panel, 'push');
            app.DefineOptionalInputParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefineOptionalInputParametersButtonPushed, true);
            app.DefineOptionalInputParametersButton.Text = 'Define Optional Input Parameters:';
            app.Panel_2 = uipanel(app.Panel);
            app.optionalInputArgs = uitextarea(app.Panel_2);
            app.optionalInputArgs.Editable = 'off';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.PAT_Rec_AddsOn_simulation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulatePAsignal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PAT_Rec_AddsOn_simulation)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PAT_Rec_AddsOn_simulation)
        end
    end
end