classdef DesignTransducerConfiguration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PAT_Rec_AddsOn                 matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        ClearallButton                 matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        DefineDetectorArrayPanel       matlab.ui.container.Panel
        Panel_C2D                      matlab.ui.container.Panel
        CentermmxSpinner_3             matlab.ui.control.Spinner
        CentermmxLabel                 matlab.ui.control.Label
        ySpinner_15                    matlab.ui.control.Spinner
        ySpinner_3Label_2              matlab.ui.control.Label
        ArcangledegSpinner             matlab.ui.control.Spinner
        ArcangledegSpinnerLabel        matlab.ui.control.Label
        Radius_C2d                     matlab.ui.control.Spinner
        RadiusmmLabel_2                matlab.ui.control.Label
        Numberoftransducers_L_2D_2     matlab.ui.control.Spinner
        NumberofdetectorsLabel_4       matlab.ui.control.Label
        Panel_L3D                      matlab.ui.container.Panel
        ySpinner_21                    matlab.ui.control.Spinner
        ySpinner_21Label               matlab.ui.control.Label
        EndmmxSpinner                  matlab.ui.control.Spinner
        EndmmxLabel                    matlab.ui.control.Label
        zSpinner_20                    matlab.ui.control.Spinner
        zSpinner_4Label_9              matlab.ui.control.Label
        ySpinner_19                    matlab.ui.control.Spinner
        ySpinner_19Label               matlab.ui.control.Label
        StartmmxSpinner                matlab.ui.control.Spinner
        StartmmxLabel                  matlab.ui.control.Label
        zSpinner_1                     matlab.ui.control.Spinner
        zSpinner_Label                 matlab.ui.control.Label
        Numberoftransducers_L_2D_3     matlab.ui.control.Spinner
        NumberofdetectorsLabel_3       matlab.ui.control.Label
        Panel_S3D                      matlab.ui.container.Panel
        ySpinner_17                    matlab.ui.control.Spinner
        ySpinner_17Label               matlab.ui.control.Label
        CentermmxSpinner_2             matlab.ui.control.Spinner
        CentermmxLabel_3               matlab.ui.control.Label
        zSpinner_16                    matlab.ui.control.Spinner
        ySpinner_4Label_7              matlab.ui.control.Label
        Radius_S3D                     matlab.ui.control.Spinner
        RadiusLabel                    matlab.ui.control.Label
        Numberoftransducers_L_2D_4     matlab.ui.control.Spinner
        NumberofdetectorsLabel_2       matlab.ui.control.Label
        Panel_L2D                      matlab.ui.container.Panel
        StartmmxLabel_2                matlab.ui.control.Label
        ySpinner_2                     matlab.ui.control.Spinner
        ySpinner_2Label                matlab.ui.control.Label
        EndmmxSpinner_2                matlab.ui.control.Spinner
        EndmmxLabel_2                  matlab.ui.control.Label
        ySpinner                       matlab.ui.control.Spinner
        ySpinnerLabel                  matlab.ui.control.Label
        StartmmxSpinner_2              matlab.ui.control.Spinner
        Numberoftransducers_L_2D       matlab.ui.control.Spinner
        NumberofdetectorsLabel         matlab.ui.control.Label
        Panel_Cy3D                     matlab.ui.container.Panel
        TotalnumberofdetectorsEditField  matlab.ui.control.NumericEditField
        TotalnumberofdetectorsEditFieldLabel  matlab.ui.control.Label
        CenterofthebottomringmmLabel   matlab.ui.control.Label
        Space_Cy                       matlab.ui.control.Spinner
        SpacesbetweentheringsinzmmLabel  matlab.ui.control.Label
        numberOfRings_Cy               matlab.ui.control.Spinner
        NumberofringsLabel             matlab.ui.control.Label
        ySpinner_Cy                    matlab.ui.control.Spinner
        ySpinner_14Label               matlab.ui.control.Label
        xSpinner_Cy                    matlab.ui.control.Spinner
        xLabel                         matlab.ui.control.Label
        zSpinner_Cy                    matlab.ui.control.Spinner
        ySpinner_4Label_6              matlab.ui.control.Label
        Radius_Cy3D                    matlab.ui.control.Spinner
        RadiusmmLabel                  matlab.ui.control.Label
        Numberoftransducers_Cy_singleRing  matlab.ui.control.Spinner
        NumberofdetectorsonasingleringxyLabel  matlab.ui.control.Label
        Panel_Planar3D                 matlab.ui.container.Panel
        SpacingBetweenTransducersInArray_PL  matlab.ui.control.Spinner
        SpacebetweendetectorsinthelineararrayLabel  matlab.ui.control.Label
        ButtonGroup_PL                 matlab.ui.container.ButtonGroup
        yButton                        matlab.ui.control.RadioButton
        xButton                        matlab.ui.control.RadioButton
        DirectionofthearrayLabel       matlab.ui.control.Label
        TotalnumberoftransducersEditField_PL  matlab.ui.control.NumericEditField
        TotalnumberofdetectorsLabel    matlab.ui.control.Label
        FirstdetectorelementofthefirstarraymmLabel  matlab.ui.control.Label
        Space_PL                       matlab.ui.control.Spinner
        SpacesbetweenthearraysmmLabel  matlab.ui.control.Label
        numberOfRings_PL               matlab.ui.control.Spinner
        NumberofrepetitionsinotherdirectionxoryLabel  matlab.ui.control.Label
        ySpinner_PL                    matlab.ui.control.Spinner
        ySpinner_14Label_2             matlab.ui.control.Label
        xSpinner_PL                    matlab.ui.control.Spinner
        xLabel_2                       matlab.ui.control.Label
        zSpinner_PL                    matlab.ui.control.Spinner
        ySpinner_4Label_8              matlab.ui.control.Label
        Numberoftransducers_PL         matlab.ui.control.Spinner
        NumberofdetectorsinasinglelineararrayLabel  matlab.ui.control.Label
        ConfigurationDropDown_3D       matlab.ui.control.DropDown
        ConfigurationLabel             matlab.ui.control.Label
        ConfigurationDropDown_2D       matlab.ui.control.DropDown
        ConfigurationDropDownLabel     matlab.ui.control.Label
        DefineComputationalGridPanel   matlab.ui.container.Panel
        NumberofgridsyEditField        matlab.ui.control.NumericEditField
        NumberofgridsyEditFieldLabel   matlab.ui.control.Label
        NumberofgridsxEditField        matlab.ui.control.NumericEditField
        NumberofgridsxEditFieldLabel   matlab.ui.control.Label
        CenterymmSpinner               matlab.ui.control.Spinner
        CenterymmSpinnerLabel          matlab.ui.control.Label
        CenterxmmSpinner               matlab.ui.control.Spinner
        CenterxmmSpinnerLabel          matlab.ui.control.Label
        GridsizeymmSpinner             matlab.ui.control.Spinner
        GridsizeymmSpinnerLabel        matlab.ui.control.Label
        GridsizexmmSpinner             matlab.ui.control.Spinner
        GridsizexmmSpinnerLabel        matlab.ui.control.Label
        ysizemmSpinner                 matlab.ui.control.Spinner
        ysizemmSpinnerLabel            matlab.ui.control.Label
        xsizemmSpinner                 matlab.ui.control.Spinner
        xsizemmSpinnerLabel            matlab.ui.control.Label
        NumberofpixelszEditField       matlab.ui.control.NumericEditField
        NumberofpixelszEditFieldLabel  matlab.ui.control.Label
        pixelsizezmmSpinner            matlab.ui.control.Spinner
        pixelsizezmmSpinnerLabel       matlab.ui.control.Label
        zsizemmSpinner                 matlab.ui.control.Spinner
        zsizemmSpinnerLabel            matlab.ui.control.Label
        CenterzmmSpinner               matlab.ui.control.Spinner
        CenterzmmLabel                 matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp  %The main app
        Cords = []; % Coordinates
        tempMask % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.PAT_Rec_AddsOn.Name = 'Design Detector Locations';
            SwitchValueChanged(app)
            xsizemmSpinnerValueChanged(app)
            app.CallingApp = MainApp;
        end

        % Value changed function: ConfigurationDropDown_2D
        function ConfigurationDropDown_2DValueChanged(app, event)
            value = app.ConfigurationDropDown_2D.Value;
            switch value
                case 'Linear Array'
                    app.Panel_L2D.Visible = 'On';
                    app.Panel_C2D.Visible = 'Off';
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'Off';
                case 'Circular'
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'On';
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'Off';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            ClearallButtonPushed(app)
            
            value = app.Switch.Value;
            switch value
                case '2D'
                    app.ConfigurationDropDown_2D.Visible = 'On';
                    app.ConfigurationDropDown_3D.Visible = 'Off';
                    if strcmp(app.ConfigurationDropDown_2D.Value,'Linear Array')
                        app.Panel_L2D.Visible = 'On';
                        app.Panel_C2D.Visible = 'Off';
                    elseif strcmp(app.ConfigurationDropDown_2D.Value,'Circular')
                        app.Panel_L2D.Visible = 'Off';
                        app.Panel_C2D.Visible = 'On';
                    end
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'Off';
                    app.CenterzmmLabel.Visible = 'Off';app.CenterzmmSpinner.Visible = 'Off';
                    app.zsizemmSpinnerLabel.Visible = 'Off';app.zsizemmSpinner.Visible = 'Off';
                    app.pixelsizezmmSpinnerLabel.Visible = 'Off';app.pixelsizezmmSpinner.Visible = 'Off';
                    app.NumberofpixelszEditFieldLabel.Visible = 'Off';app.NumberofpixelszEditField.Visible = 'Off';
                   
                case '3D'
                    app.ConfigurationDropDown_2D.Visible = 'Off';
                    app.ConfigurationDropDown_3D.Visible = 'On';
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'Off';
                    if strcmp(app.ConfigurationDropDown_3D.Value,'Linear Array')
                        app.Panel_L3D.Visible = 'On';
                        app.Panel_Planar3D.Visible = 'Off';
                        app.Panel_S3D.Visible = 'Off';
                        app.Panel_Cy3D.Visible = 'Off';
                    elseif strcmp(app.ConfigurationDropDown_3D.Value,'Planar')
                        app.Panel_L3D.Visible = 'Off';
                        app.Panel_Planar3D.Visible = 'Off';
                        app.Panel_S3D.Visible = 'On';
                        app.Panel_Cy3D.Visible = 'Off';
                    elseif strcmp(app.ConfigurationDropDown_3D.Value,'Spherical')
                        app.Panel_L3D.Visible = 'Off';
                        app.Panel_Planar3D.Visible = 'Off';
                        app.Panel_S3D.Visible = 'On';
                        app.Panel_Cy3D.Visible = 'Off';
                    elseif strcmp(app.ConfigurationDropDown_3D.Value,'Cylindrical')
                        app.Panel_L3D.Visible = 'Off';
                        app.Panel_Planar3D.Visible = 'Off';
                        app.Panel_S3D.Visible = 'Off';
                        app.Panel_Cy3D.Visible = 'On';
                    end
                    app.CenterzmmLabel.Visible = 'On';app.CenterzmmSpinner.Visible = 'On';
                    app.zsizemmSpinnerLabel.Visible = 'On';app.zsizemmSpinner.Visible = 'On';
                    app.pixelsizezmmSpinnerLabel.Visible = 'On';app.pixelsizezmmSpinner.Visible = 'On';
                    app.NumberofpixelszEditFieldLabel.Visible = 'On';app.NumberofpixelszEditField.Visible = 'On';
%                     plot3(app.ImageAxes,[],[],[]);
%                     xlim(app.ImageAxes,[-10 10]) ;
%                     ylim(app.ImageAxes,[-10 10]) ;
%                     zlim(app.ImageAxes,[-10 10]) ;

            end
        end

        % Value changed function: ConfigurationDropDown_3D
        function ConfigurationDropDown_3DValueChanged(app, event)
            value = app.ConfigurationDropDown_3D.Value;
            switch value
                case 'Linear Array'
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'Off';
                    app.Panel_L3D.Visible = 'On';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'Off';
                case 'Planar'
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'Off';
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'On';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'Off';
                case 'Spherical'
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'Off';
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'On';
                    app.Panel_Cy3D.Visible = 'Off';
                case 'Cylindrical'
                    app.Panel_L2D.Visible = 'Off';
                    app.Panel_C2D.Visible = 'Off';
                    app.Panel_L3D.Visible = 'Off';
                    app.Panel_Planar3D.Visible = 'Off';
                    app.Panel_S3D.Visible = 'Off';
                    app.Panel_Cy3D.Visible = 'On';
            end
        end

        % Callback function: EndmmxSpinner_2, 
        % Numberoftransducers_L_2D, ProcessButton, 
        % StartmmxSpinner_2, ySpinner, ySpinner_2
        function ProcessButtonPushed(app, event)
            tempCords = app.Cords;
            val = app.Switch.Value;
            switch val
                case '2D'
                    x_size = app.xsizemmSpinner.Value;
                    y_size = app.ysizemmSpinner.Value;
                    
                    resolutionFactorX = 1/app.GridsizexmmSpinner.Value;
                    resolutionFactorY = 1/app.GridsizeymmSpinner.Value;
                    
                    Npixel_x = round(x_size*resolutionFactorX);
                    Npixel_y = round(y_size*resolutionFactorY);
                    
                    center_x = app.CenterxmmSpinner.Value;
                    center_y = app.CenterymmSpinner.Value;
                   
                    x_range = ((1:Npixel_x)-(Npixel_x+1)/2)*x_size/(Npixel_x)+center_x;  % x axis
                    y_range = ((Npixel_y:-1:1)-(Npixel_y+1)/2)*y_size/(Npixel_y)+center_y;    % y axis
                    val2 = app.ConfigurationDropDown_2D.Value;
                    switch val2
                        case 'Linear Array'
                            x1 = app.StartmmxSpinner_2.Value;           % [mm]
                            y1 = app.ySpinner.Value;                    % [mm]
                            x2 = app.EndmmxSpinner_2.Value;             % [mm]
                            y2 = app.ySpinner_2.Value;                  % [mm]
                            numSensors = app.Numberoftransducers_L_2D.Value;
                            x = linspace(x1, x2, numSensors);
                            y = linspace(y1, y2, numSensors);
                            app.tempMask = [x; y];
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords =  cat(2,tempCords, app.tempMask);
                            end
                        case 'Circular'
                            numSensors       = app.Numberoftransducers_L_2D_2.Value;
                            sensor_Center_x  = app.CentermmxSpinner_3.Value*1e-3;        % [m]
                            sensor_Center_y  = app.ySpinner_15.Value*1e-3;               % [m]
                            sensor_radius    = app.Radius_C2d.Value*1e-3;                % [m]
                            sensor_arc_angle = app.ArcangledegSpinner.Value*pi/180;      % [rad]
                            xy               = 1000.*makeCartCircle(sensor_radius,numSensors, [sensor_Center_x sensor_Center_y], sensor_arc_angle);
                            app.tempMask = xy;
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords =  cat(2,tempCords, app.tempMask);
                            end
                    end
                    plot(app.ImageAxes,tempCords(1,:), tempCords(2,:), 'b.');
                    xlim(app.ImageAxes,[x_range(1) x_range(end)]) ;
                    ylim(app.ImageAxes,[y_range(end) y_range(1)]) ;
                    grid(app.ImageAxes, "on")
                    box(app.ImageAxes, "on")
                    zlabel(app.ImageAxes, 'Z')
                case '3D'
                    x_size = app.xsizemmSpinner.Value;
                    y_size = app.ysizemmSpinner.Value;
                    z_size = app.zsizemmSpinner.Value;
                    resolutionFactorX = 1/app.GridsizexmmSpinner.Value;
                    resolutionFactorY = 1/app.GridsizeymmSpinner.Value;
                    resolutionFactorZ = 1/app.pixelsizezmmSpinner.Value;
                    Npixel_x = round(x_size*resolutionFactorX);
                    Npixel_y = round(y_size*resolutionFactorY);
                    Npixel_z = round(z_size*resolutionFactorZ);

                    center_x = app.CenterxmmSpinner.Value;
                    center_y = app.CenterymmSpinner.Value;
                    center_z = app.CenterzmmSpinner.Value;
                    x_range = ((1:Npixel_x)-(Npixel_x+1)/2)*x_size/(Npixel_x)+center_x;  % x axis
                    y_range = ((Npixel_y:-1:1)-(Npixel_y+1)/2)*y_size/(Npixel_y)+center_y;    % y axis
                    z_range = ((1:Npixel_z)-(Npixel_z+1)/2)*z_size/(Npixel_z)+center_z;
                    
                    val2 = app.ConfigurationDropDown_3D.Value;
                    switch val2
                        case 'Linear Array'
                            x1 = app.StartmmxSpinner.Value;           % [mm]
                            y1 = app.ySpinner_19.Value;                    % [mm]
                            z1 = app.zSpinner_1.Value;
                            x2 = app.EndmmxSpinner.Value;             % [mm]
                            y2 = app.ySpinner_21.Value;                  % [mm]
                            z2 = app.zSpinner_20.Value;
                            numSensors = app.Numberoftransducers_L_2D_3.Value;
                            x = linspace(x1, x2, numSensors);
                            y = linspace(y1, y2, numSensors);
                            z = linspace(z1, z2, numSensors);
                            app.tempMask = [x; y; z;];
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords =  cat(2,tempCords, app.tempMask);
                            end
                        case 'Planar'
                            NT_array = app.Numberoftransducers_PL.Value;
                            SpaceTransducers = app.SpacingBetweenTransducersInArray_PL.Value;
                            N_arrayRepetition = app.numberOfRings_PL.Value;
                            SpaceArrays = app.Space_PL.Value;
                            xFirst = app.xSpinner_PL.Value;
                            yFirst = app.ySpinner_PL.Value;
                            zFirst = app.zSpinner_PL.Value;
                            direction = app.ButtonGroup_PL.SelectedObject;
                            switch direction.Text
                                case 'y'
                                    tempArray = [xFirst.*ones(1,NT_array);
                                        yFirst:SpaceTransducers:yFirst+(NT_array-1)*SpaceTransducers];
                                    temp = tempArray;
                                    for i=2:N_arrayRepetition
                                        temp = cat(2,temp,tempArray+[(i-1)*SpaceArrays.*ones(1,NT_array);zeros(1,NT_array)]);
                                    end
                                case 'x'
                                    tempArray = [xFirst:SpaceTransducers:xFirst+(NT_array-1)*SpaceTransducers;
                                        yFirst.*ones(1,NT_array)];
                                     temp = tempArray;
                                    for i=2:N_arrayRepetition
                                        temp = cat(2,temp,tempArray+[zeros(1,NT_array);(i-1)*SpaceArrays.*ones(1,NT_array)]);
                                    end
                            end
                            app.tempMask = cat(1,temp,zFirst.*(ones(1,size(temp,2))));
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords =  cat(2,tempCords, app.tempMask);
                            end
                        case 'Spherical'
                            numSensors       = app.Numberoftransducers_L_2D_4.Value;
                            sensor_Center_x  = app.CentermmxSpinner_2.Value*1e-3;        % [m]
                            sensor_Center_y  = app.ySpinner_17.Value*1e-3;               % [m]
                            sensor_Center_z  = app.zSpinner_16.Value*1e-3;               % [m]
                            sensor_radius    = app.Radius_S3D.Value*1e-3;                % [m]
%                             sensor_arc_angle = app.ArcangledegSpinner.Value*pi/180;      % [rad]
                            xy               = 1000.*makeCartSphere(sensor_radius,numSensors, [sensor_Center_x sensor_Center_y sensor_Center_z]);
                            app.tempMask = xy;
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords = cat(2, tempCords, app.tempMask);
                            end
                        case 'Cylindrical'
                            NR = app.numberOfRings_Cy.Value;
                            SpaceR = app.Space_Cy.Value;
                            zFirst = app.zSpinner_Cy.Value;
                            numSensors       = app.Numberoftransducers_Cy_singleRing.Value;
                            sensor_Center_x  = app.xSpinner_Cy.Value*1e-3;        % [m]
                            sensor_Center_y  = app.ySpinner_Cy.Value*1e-3;               % [m]
                            sensor_radius    = app.Radius_Cy3D.Value*1e-3;
                            xy           = 1000.*makeCartCircle(sensor_radius,numSensors, [sensor_Center_x sensor_Center_y]);
                            Mask = [];
                            for i=1:NR
                                xyMask = [xy; (zFirst+(i-1)*SpaceR).*ones(1,numSensors)];
                                Mask   = cat(2,Mask,xyMask);
                            end
                            app.tempMask = Mask;
                            if isempty(tempCords)
                                tempCords = app.tempMask;
                            else
                                tempCords =  cat(2,tempCords, app.tempMask);
                            end
                    end
                    plot3(app.ImageAxes,tempCords(1,:), tempCords(2,:), tempCords(3,:), 'b.');
                    xlim(app.ImageAxes,[x_range(1) x_range(end)]) ;
                    ylim(app.ImageAxes,[y_range(end) y_range(1)]) ;
                    zlim(app.ImageAxes,[z_range(1) z_range(end)]) ;
                    app.ImageAxes.Box = 'On';
                    app.ImageAxes.XGrid = 'On';
                    app.ImageAxes.YGrid = 'On';
                    app.ImageAxes.ZGrid = 'On';
            end
            app.AddButton.BackgroundColor = [0.80,0.80,0.80];
            app.SaveButton.BackgroundColor = [0.80,0.80,0.80];
        end

        % Value changed function: GridsizexmmSpinner, 
        % GridsizeymmSpinner, pixelsizezmmSpinner, xsizemmSpinner, 
        % ysizemmSpinner, zsizemmSpinner
        function xsizemmSpinnerValueChanged(app, event)
            app.Cords = [];
            x_size = app.xsizemmSpinner.Value;
            y_size = app.ysizemmSpinner.Value;
            z_size = app.zsizemmSpinner.Value;
            resolutionFactorX = 1/app.GridsizexmmSpinner.Value;
            resolutionFactorY = 1/app.GridsizeymmSpinner.Value;
            resolutionFactorZ = 1/app.pixelsizezmmSpinner.Value;
            Npixel_x = round(x_size*resolutionFactorX);
            Npixel_y = round(y_size*resolutionFactorY);
            Npixel_z = round(z_size*resolutionFactorZ);
            app.NumberofgridsxEditField.Value = Npixel_x;
            app.NumberofgridsyEditField.Value = Npixel_y;
            app.NumberofpixelszEditField.Value = Npixel_z;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Call main app's public function
            UpdateCoordinates(app.CallingApp, app.Cords');
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn;
            d = uiprogressdlg(f,'Title','Add new locations ...','Cancelable',"on");
            if isempty(app.Cords)
                app.Cords = app.tempMask;
            else
                app.Cords =  cat(2,app.Cords, app.tempMask);
            end
            app.AddButton.BackgroundColor = [0.39,0.83,0.07];
            close(d)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn;
            d = uiprogressdlg(f,'Title','Clear all ...','Cancelable',"on");
            app.Cords = [];
%             app.ImageAxes.cla;
            cla(app.ImageAxes,'reset');
            title(app.ImageAxes, '')
                xlabel(app.ImageAxes, 'X')
                ylabel(app.ImageAxes, 'Y')
                app.ImageAxes.PlotBoxAspectRatio = [1.09701492537313 1 1];
                app.ImageAxes.XGrid = 'on';
                app.ImageAxes.YGrid = 'on';
                app.ImageAxes.ZGrid = 'on';
                app.ImageAxes.Position = [310,37,319,285];
            app.AddButton.BackgroundColor = [0.80,0.80,0.80];
            app.SaveButton.BackgroundColor = [0.80,0.80,0.80];
            close(d)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            f = app.PAT_Rec_AddsOn;
            d = uiprogressdlg(f,'Title','Save Data ...','Cancelable',"on");
            SensorCords = app.Cords;
            formatOut = 'dd_mmm_yyyy';
            tNow = datestr(now,formatOut);
            title = sprintf('TransducersPositions_%s.mat', tNow);
%             title= title(find(~isspace(title)));
            save (title,'SensorCords');
            message = sprintf('The file has been successfully saved to the directory: %s', cd);
            uialert(app.PAT_Rec_AddsOn,message,'Save', 'Icon','success');
            app.SaveButton.BackgroundColor = [0.39,0.83,0.07];
            close(d)
        end

        % Value changed function: 
        % Numberoftransducers_Cy_singleRing, numberOfRings_Cy
        function Numberoftransducers_Cy_singleRingValueChanged(app, event)
            app.TotalnumberofdetectorsEditField.Value = app.Numberoftransducers_Cy_singleRing.Value*app.numberOfRings_Cy.Value;
        end

        % Value changed function: Numberoftransducers_PL, 
        % numberOfRings_PL
        function Numberoftransducers_PLValueChanged(app, event)
            value = app.Numberoftransducers_PL.Value;
            app.TotalnumberoftransducersEditField_PL.Value  = value*app.numberOfRings_PL.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PAT_Rec_AddsOn = uifigure('Visible', 'off');
            app.PAT_Rec_AddsOn.Name = 'UI Figure';
            app.PAT_Rec_AddsOn.Icon = 'PatLab_Logo_1.png';
            app.Panel = uipanel(app.PAT_Rec_AddsOn);
            app.ImageAxes = uiaxes(app.Panel);
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.ImageAxes.Visible = 'off';
            app.Switch = uiswitch(app.Panel, 'toggle');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2D';
            app.DefineComputationalGridPanel = uipanel(app.Panel);
            app.DefineComputationalGridPanel.Title = 'Define Computational Grid:';
            app.CenterzmmLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterzmmLabel.Text = 'Center z [mm]:';
            app.CenterzmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.zsizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.zsizemmSpinnerLabel.Text = 'z size [mm]:';
            app.zsizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.zsizemmSpinner.Limits = [0 Inf];
            app.zsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.zsizemmSpinner.Value = 42;
            app.pixelsizezmmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.pixelsizezmmSpinnerLabel.Text = 'Grid size z [mm]:';
            app.pixelsizezmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.pixelsizezmmSpinner.Step = 0.05;
            app.pixelsizezmmSpinner.Limits = [0 Inf];
            app.pixelsizezmmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.pixelsizezmmSpinner.Value = 0.1;
            app.NumberofpixelszEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofpixelszEditFieldLabel.Text = 'Number of grids z:';
            app.NumberofpixelszEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofpixelszEditField.Editable = 'off';
            app.xsizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.xsizemmSpinnerLabel.Text = 'x size [mm]:';
            app.xsizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.xsizemmSpinner.Limits = [0 Inf];
            app.xsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.xsizemmSpinner.Value = 42;
            app.ysizemmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.ysizemmSpinnerLabel.Text = 'y size [mm]:';
            app.ysizemmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.ysizemmSpinner.Limits = [0 Inf];
            app.ysizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.ysizemmSpinner.Value = 42;
            app.GridsizexmmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.GridsizexmmSpinnerLabel.Text = 'Grid size x [mm]:';
            app.GridsizexmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.GridsizexmmSpinner.Step = 0.05;
            app.GridsizexmmSpinner.Limits = [0 Inf];
            app.GridsizexmmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.GridsizexmmSpinner.Value = 0.1;
            app.GridsizeymmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.GridsizeymmSpinnerLabel.Text = 'Grid size y [mm]:';
            app.GridsizeymmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.GridsizeymmSpinner.Step = 0.05;
            app.GridsizeymmSpinner.Limits = [0 Inf];
            app.GridsizeymmSpinner.ValueChangedFcn = createCallbackFcn(app, @xsizemmSpinnerValueChanged, true);
            app.GridsizeymmSpinner.Value = 0.1;
            app.CenterxmmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterxmmSpinnerLabel.Text = 'Center x [mm]:';
            app.CenterxmmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.CenterymmSpinnerLabel = uilabel(app.DefineComputationalGridPanel);
            app.CenterymmSpinnerLabel.Text = 'Center y [mm]:';
            app.CenterymmSpinner = uispinner(app.DefineComputationalGridPanel);
            app.NumberofgridsxEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofgridsxEditFieldLabel.Text = 'Number of grids x:';
            app.NumberofgridsxEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofgridsxEditField.Editable = 'off';
            app.NumberofgridsyEditFieldLabel = uilabel(app.DefineComputationalGridPanel);
            app.NumberofgridsyEditFieldLabel.Text = 'Number of grids y:';
            app.NumberofgridsyEditField = uieditfield(app.DefineComputationalGridPanel, 'numeric');
            app.NumberofgridsyEditField.Editable = 'off';
            app.DefineDetectorArrayPanel = uipanel(app.Panel);
            app.DefineDetectorArrayPanel.Title = 'Define Detector Array:';
            app.ConfigurationDropDownLabel = uilabel(app.DefineDetectorArrayPanel);
            app.ConfigurationDropDownLabel.Text = 'Configuration:';
            app.ConfigurationDropDown_2D = uidropdown(app.DefineDetectorArrayPanel);
            app.ConfigurationDropDown_2D.Items = {'Linear Array', 'Circular'};
            app.ConfigurationDropDown_2D.ValueChangedFcn = createCallbackFcn(app, @ConfigurationDropDown_2DValueChanged, true);
            app.ConfigurationDropDown_2D.Value = 'Linear Array';
            app.ConfigurationLabel = uilabel(app.DefineDetectorArrayPanel);
            app.ConfigurationLabel.Text = 'Configuration:';
            app.ConfigurationDropDown_3D = uidropdown(app.DefineDetectorArrayPanel);
            app.ConfigurationDropDown_3D.Items = {'Linear Array', 'Planar', 'Spherical', 'Cylindrical'};
            app.ConfigurationDropDown_3D.ValueChangedFcn = createCallbackFcn(app, @ConfigurationDropDown_3DValueChanged, true);
            app.ConfigurationDropDown_3D.Visible = 'off';
            app.ConfigurationDropDown_3D.Value = 'Linear Array';
            app.Panel_Planar3D = uipanel(app.DefineDetectorArrayPanel);
            app.Panel_Planar3D.Visible = 'off';
            app.NumberofdetectorsinasinglelineararrayLabel = uilabel(app.Panel_Planar3D);
            app.NumberofdetectorsinasinglelineararrayLabel.Text = 'Number of detectors in a single linear array:';
            app.Numberoftransducers_PL = uispinner(app.Panel_Planar3D);
            app.Numberoftransducers_PL.Limits = [1 Inf];
            app.Numberoftransducers_PL.ValueChangedFcn = createCallbackFcn(app, @Numberoftransducers_PLValueChanged, true);
            app.Numberoftransducers_PL.Value = 10;
            app.ySpinner_4Label_8 = uilabel(app.Panel_Planar3D);
            app.ySpinner_4Label_8.Text = 'z';
            app.zSpinner_PL = uispinner(app.Panel_Planar3D);
            app.xLabel_2 = uilabel(app.Panel_Planar3D);
            app.xLabel_2.Text = 'x';
            app.xSpinner_PL = uispinner(app.Panel_Planar3D);
            app.xSpinner_PL.Value = -10;
            app.ySpinner_14Label_2 = uilabel(app.Panel_Planar3D);
            app.ySpinner_14Label_2.Text = 'y';
            app.ySpinner_PL = uispinner(app.Panel_Planar3D);
            app.ySpinner_PL.Value = -10;
            app.NumberofrepetitionsinotherdirectionxoryLabel = uilabel(app.Panel_Planar3D);
            app.NumberofrepetitionsinotherdirectionxoryLabel.Text = 'Number of repetitions in other direction (x or y):';
            app.numberOfRings_PL = uispinner(app.Panel_Planar3D);
            app.numberOfRings_PL.Limits = [1 Inf];
            app.numberOfRings_PL.ValueChangedFcn = createCallbackFcn(app, @Numberoftransducers_PLValueChanged, true);
            app.numberOfRings_PL.Value = 5;
            app.SpacesbetweenthearraysmmLabel = uilabel(app.Panel_Planar3D);
            app.SpacesbetweenthearraysmmLabel.Text = 'Spaces between the arrays [mm]:';
            app.Space_PL = uispinner(app.Panel_Planar3D);
            app.Space_PL.Limits = [0 Inf];
            app.Space_PL.Value = 2;
            app.FirstdetectorelementofthefirstarraymmLabel = uilabel(app.Panel_Planar3D);
            app.FirstdetectorelementofthefirstarraymmLabel.Text = 'First detector element of the first array [mm]: ';
            app.TotalnumberofdetectorsLabel = uilabel(app.Panel_Planar3D);
            app.TotalnumberofdetectorsLabel.Text = 'Total number of detectors:';
            app.TotalnumberoftransducersEditField_PL = uieditfield(app.Panel_Planar3D, 'numeric');
            app.TotalnumberoftransducersEditField_PL.Editable = 'off';
            app.TotalnumberoftransducersEditField_PL.Value = 50;
            app.DirectionofthearrayLabel = uilabel(app.Panel_Planar3D);
            app.DirectionofthearrayLabel.Text = 'Direction of the array:';
            app.ButtonGroup_PL = uibuttongroup(app.Panel_Planar3D);
            app.xButton = uiradiobutton(app.ButtonGroup_PL);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ButtonGroup_PL);
            app.yButton.Text = 'y';
            app.SpacebetweendetectorsinthelineararrayLabel = uilabel(app.Panel_Planar3D);
            app.SpacebetweendetectorsinthelineararrayLabel.Text = 'Space between detectors in the linear array: ';
            app.SpacingBetweenTransducersInArray_PL = uispinner(app.Panel_Planar3D);
            app.SpacingBetweenTransducersInArray_PL.Limits = [1 Inf];
            app.SpacingBetweenTransducersInArray_PL.Tooltip = {'[mm]'};
            app.SpacingBetweenTransducersInArray_PL.Value = 1;
            app.Panel_Cy3D = uipanel(app.DefineDetectorArrayPanel);
            app.Panel_Cy3D.Visible = 'off';
            app.NumberofdetectorsonasingleringxyLabel = uilabel(app.Panel_Cy3D);
            app.NumberofdetectorsonasingleringxyLabel.Text = 'Number of detectors on a single ring (xy):';
            app.Numberoftransducers_Cy_singleRing = uispinner(app.Panel_Cy3D);
            app.Numberoftransducers_Cy_singleRing.Limits = [1 Inf];
            app.Numberoftransducers_Cy_singleRing.ValueChangedFcn = createCallbackFcn(app, @Numberoftransducers_Cy_singleRingValueChanged, true);
            app.Numberoftransducers_Cy_singleRing.Value = 64;
            app.RadiusmmLabel = uilabel(app.Panel_Cy3D);
            app.RadiusmmLabel.Text = 'Radius [mm]:';
            app.Radius_Cy3D = uispinner(app.Panel_Cy3D);
            app.Radius_Cy3D.Limits = [0 Inf];
            app.Radius_Cy3D.Value = 20;
            app.ySpinner_4Label_6 = uilabel(app.Panel_Cy3D);
            app.ySpinner_4Label_6.Text = 'z';
            app.zSpinner_Cy = uispinner(app.Panel_Cy3D);
            app.zSpinner_Cy.Value = -9;
            app.xLabel = uilabel(app.Panel_Cy3D);
            app.xLabel.Text = 'x';
            app.xSpinner_Cy = uispinner(app.Panel_Cy3D);
            app.ySpinner_14Label = uilabel(app.Panel_Cy3D);
            app.ySpinner_14Label.Text = 'y';
            app.ySpinner_Cy = uispinner(app.Panel_Cy3D);
            app.NumberofringsLabel = uilabel(app.Panel_Cy3D);
            app.NumberofringsLabel.Text = 'Number of rings:';
            app.numberOfRings_Cy = uispinner(app.Panel_Cy3D);
            app.numberOfRings_Cy.Limits = [1 Inf];
            app.numberOfRings_Cy.ValueChangedFcn = createCallbackFcn(app, @Numberoftransducers_Cy_singleRingValueChanged, true);
            app.numberOfRings_Cy.Value = 7;
            app.SpacesbetweentheringsinzmmLabel = uilabel(app.Panel_Cy3D);
            app.SpacesbetweentheringsinzmmLabel.Text = 'Spaces between the rings in z [mm]:';
            app.Space_Cy = uispinner(app.Panel_Cy3D);
            app.Space_Cy.Limits = [0 Inf];
            app.Space_Cy.Value = 3;
            app.CenterofthebottomringmmLabel = uilabel(app.Panel_Cy3D);
            app.CenterofthebottomringmmLabel.Text = 'Center of the bottom ring [mm]: ';
            app.TotalnumberofdetectorsEditFieldLabel = uilabel(app.Panel_Cy3D);
            app.TotalnumberofdetectorsEditFieldLabel.Text = 'Total number of detectors:';
            app.TotalnumberofdetectorsEditField = uieditfield(app.Panel_Cy3D, 'numeric');
            app.TotalnumberofdetectorsEditField.Editable = 'off';
            app.TotalnumberofdetectorsEditField.Value = 640;
            app.Panel_L2D = uipanel(app.DefineDetectorArrayPanel);
            app.NumberofdetectorsLabel = uilabel(app.Panel_L2D);
            app.NumberofdetectorsLabel.Text = 'Number of detectors:';
            app.Numberoftransducers_L_2D = uispinner(app.Panel_L2D);
            app.Numberoftransducers_L_2D.Limits = [1 Inf];
            app.Numberoftransducers_L_2D.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.Numberoftransducers_L_2D.Value = 128;
            app.StartmmxSpinner_2 = uispinner(app.Panel_L2D);
            app.StartmmxSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.StartmmxSpinner_2.Value = -10;
            app.ySpinnerLabel = uilabel(app.Panel_L2D);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.Panel_L2D);
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ySpinner.Value = -10;
            app.EndmmxLabel_2 = uilabel(app.Panel_L2D);
            app.EndmmxLabel_2.Text = 'End [mm]:                         x';
            app.EndmmxSpinner_2 = uispinner(app.Panel_L2D);
            app.EndmmxSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.EndmmxSpinner_2.Value = 10;
            app.ySpinner_2Label = uilabel(app.Panel_L2D);
            app.ySpinner_2Label.Text = 'y';
            app.ySpinner_2 = uispinner(app.Panel_L2D);
            app.ySpinner_2.ValueChangedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ySpinner_2.Value = 10;
            app.StartmmxLabel_2 = uilabel(app.Panel_L2D);
            app.StartmmxLabel_2.Text = 'Start [mm]:                        x';
            app.Panel_S3D = uipanel(app.DefineDetectorArrayPanel);
            app.Panel_S3D.Visible = 'off';
            app.NumberofdetectorsLabel_2 = uilabel(app.Panel_S3D);
            app.NumberofdetectorsLabel_2.Text = 'Number of detectors:';
            app.Numberoftransducers_L_2D_4 = uispinner(app.Panel_S3D);
            app.Numberoftransducers_L_2D_4.Limits = [1 Inf];
            app.Numberoftransducers_L_2D_4.Value = 128;
            app.RadiusLabel = uilabel(app.Panel_S3D);
            app.RadiusLabel.Text = 'Radius:';
            app.Radius_S3D = uispinner(app.Panel_S3D);
            app.Radius_S3D.Limits = [0 Inf];
            app.Radius_S3D.Value = 10;
            app.ySpinner_4Label_7 = uilabel(app.Panel_S3D);
            app.ySpinner_4Label_7.Text = 'z';
            app.zSpinner_16 = uispinner(app.Panel_S3D);
            app.CentermmxLabel_3 = uilabel(app.Panel_S3D);
            app.CentermmxLabel_3.Text = 'Center [mm]:              x';
            app.CentermmxSpinner_2 = uispinner(app.Panel_S3D);
            app.ySpinner_17Label = uilabel(app.Panel_S3D);
            app.ySpinner_17Label.Text = 'y';
            app.ySpinner_17 = uispinner(app.Panel_S3D);
            app.Panel_L3D = uipanel(app.DefineDetectorArrayPanel);
            app.Panel_L3D.Visible = 'off';
            app.NumberofdetectorsLabel_3 = uilabel(app.Panel_L3D);
            app.NumberofdetectorsLabel_3.Text = 'Number of detectors:';
            app.Numberoftransducers_L_2D_3 = uispinner(app.Panel_L3D);
            app.Numberoftransducers_L_2D_3.Limits = [1 Inf];
            app.Numberoftransducers_L_2D_3.Value = 128;
            app.zSpinner_Label = uilabel(app.Panel_L3D);
            app.zSpinner_Label.Text = 'z';
            app.zSpinner_1 = uispinner(app.Panel_L3D);
            app.zSpinner_1.Value = -10;
            app.StartmmxLabel = uilabel(app.Panel_L3D);
            app.StartmmxLabel.Text = 'Start [mm]:                 x';
            app.StartmmxSpinner = uispinner(app.Panel_L3D);
            app.StartmmxSpinner.Value = -10;
            app.ySpinner_19Label = uilabel(app.Panel_L3D);
            app.ySpinner_19Label.Text = 'y';
            app.ySpinner_19 = uispinner(app.Panel_L3D);
            app.ySpinner_19.Value = -10;
            app.zSpinner_4Label_9 = uilabel(app.Panel_L3D);
            app.zSpinner_4Label_9.Text = 'z';
            app.zSpinner_20 = uispinner(app.Panel_L3D);
            app.zSpinner_20.Value = 10;
            app.EndmmxLabel = uilabel(app.Panel_L3D);
            app.EndmmxLabel.Text = 'End [mm]:                  x';
            app.EndmmxSpinner = uispinner(app.Panel_L3D);
            app.EndmmxSpinner.Value = 10;
            app.ySpinner_21Label = uilabel(app.Panel_L3D);
            app.ySpinner_21Label.Text = 'y';
            app.ySpinner_21 = uispinner(app.Panel_L3D);
            app.ySpinner_21.Value = 10;
            app.Panel_C2D = uipanel(app.DefineDetectorArrayPanel);
            app.Panel_C2D.Visible = 'off';
            app.NumberofdetectorsLabel_4 = uilabel(app.Panel_C2D);
            app.NumberofdetectorsLabel_4.Text = 'Number of detectors:';
            app.Numberoftransducers_L_2D_2 = uispinner(app.Panel_C2D);
            app.Numberoftransducers_L_2D_2.Limits = [1 Inf];
            app.Numberoftransducers_L_2D_2.Value = 128;
            app.RadiusmmLabel_2 = uilabel(app.Panel_C2D);
            app.RadiusmmLabel_2.Text = 'Radius [mm]:';
            app.Radius_C2d = uispinner(app.Panel_C2D);
            app.Radius_C2d.Limits = [0 Inf];
            app.Radius_C2d.Value = 10;
            app.ArcangledegSpinnerLabel = uilabel(app.Panel_C2D);
            app.ArcangledegSpinnerLabel.Text = 'Arc angle [deg]:';
            app.ArcangledegSpinner = uispinner(app.Panel_C2D);
            app.ArcangledegSpinner.Value = 360;
            app.ySpinner_3Label_2 = uilabel(app.Panel_C2D);
            app.ySpinner_3Label_2.Text = 'y';
            app.ySpinner_15 = uispinner(app.Panel_C2D);
            app.CentermmxLabel = uilabel(app.Panel_C2D);
            app.CentermmxLabel.Text = 'Center [mm]:                     x';
            app.CentermmxSpinner_3 = uispinner(app.Panel_C2D);
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ProcessButton = uibutton(app.Panel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ClearallButton = uibutton(app.Panel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.PAT_Rec_AddsOn.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignTransducerConfiguration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PAT_Rec_AddsOn)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PAT_Rec_AddsOn)
        end
    end
end