classdef LoadDirectivityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DirectivityLoad              matlab.ui.Figure
        ApplyButton                  matlab.ui.control.Button
        MethodButtonGroup            matlab.ui.container.ButtonGroup
        GlobalfocalpointButton       matlab.ui.control.RadioButton
        GlobalunitvectorButton       matlab.ui.control.RadioButton
        LoadfocalpointsButton        matlab.ui.control.RadioButton
        BrowseDirectivityDataButton  matlab.ui.control.Button
        xEditField                   matlab.ui.control.NumericEditField
        yEditField                   matlab.ui.control.NumericEditField
        xEditField_2                 matlab.ui.control.NumericEditField
        yEditField_2                 matlab.ui.control.NumericEditField
        LoadunitvectorsButton        matlab.ui.control.RadioButton
        xLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        xLabel_2                     matlab.ui.control.Label
        yLabel_2                     matlab.ui.control.Label
        LoaddirectionanglesButton    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp % calling main app
        dirMat % loaded matrix
        sensorMaskCart 
    end
    
    methods (Access = public)
        
        function UpdateAssignDirectivity2(app, importedDirMat)
            % concat the loaded matrix to remove "scan" dimension 
            LocTemp = [];
            for i = 1:size(importedDirMat,3)
                LocTemp = cat(1,LocTemp, importedDirMat(:,:,i));
            end
            app.dirMat =  LocTemp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, sensorMask)
            app.DirectivityLoad.Name = 'Loade Directivity';
            app.CallingApp = MainApp;
            app.sensorMaskCart = sensorMask;
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            switch selectedButton.Text
                case {'Load focal points', 'Load unit vectors', 'Load direction angles'}
                    app.BrowseDirectivityDataButton.Enable = 'On';
                    app.xEditField.Enable = 'Off';
                    app.yEditField.Enable = 'Off';
                    app.xEditField_2.Enable = 'Off';
                    app.yEditField_2.Enable = 'Off';
                    app.xLabel.Enable = 'Off';
                    app.yLabel.Enable = 'Off';
                    app.xLabel_2.Enable = 'Off';
                    app.yLabel_2.Enable = 'Off';
                case 'Global focal point'
                    app.BrowseDirectivityDataButton.Enable = 'Off';
                    app.xEditField.Enable = 'On';
                    app.yEditField.Enable = 'On';
                    app.xEditField_2.Enable = 'Off';
                    app.yEditField_2.Enable = 'Off';
                    app.xLabel.Enable = 'On';
                    app.yLabel.Enable = 'On';
                    app.xLabel_2.Enable = 'Off';
                    app.yLabel_2.Enable = 'Off';
                case 'Global unit vector'
                    app.BrowseDirectivityDataButton.Enable = 'Off';
                    app.xEditField.Enable = 'Off';
                    app.yEditField.Enable = 'Off';
                    app.xEditField_2.Enable = 'On';
                    app.yEditField_2.Enable = 'On';
                    app.xLabel.Enable = 'Off';
                    app.yLabel.Enable = 'Off';
                    app.xLabel_2.Enable = 'On';
                    app.yLabel_2.Enable = 'On';
%                 case 'Center of the sensors locations for every scan'
%                     app.BrowseDirectivityDataButton.Enable = 'Off';
%                     app.xEditField.Enable = 'Off';
%                     app.yEditField.Enable = 'Off';
%                     app.xEditField_2.Enable = 'Off';
%                     app.yEditField_2.Enable = 'Off';
%                     app.xLabel.Enable = 'Off';
%                     app.yLabel.Enable = 'Off';
%                     app.xLabel_2.Enable = 'Off';
%                     app.yLabel_2.Enable = 'Off';
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            val = app.MethodButtonGroup.SelectedObject;
            val = val.Text;
            SensorsCart = app.sensorMaskCart;
            NS = size(SensorsCart,2);
            dirAngles = [];
            switch val
                case 'Global focal point'
                    xc = app.xEditField.Value*1e-3;
                    yc = app.yEditField.Value*1e-3;
                    x = SensorsCart(2,:);
                    y = SensorsCart(1,:);
                    dirAngles = atan2(xc-x, y-yc);
                case 'Global unit vector'
                    xv = app.xEditField_2.Value;
                    yv = -1*(app.yEditField_2.Value);
                    dirAngles = atan2(xv, yv).*ones(1,NS);
                case 'Load focal points'
                    if isempty(app.dirMat)
                        uialert(app.DirectivityLoad, 'The directivity must be loaded or a different option must be selected.', 'Error');
                        return;
                    end
                    for i = 1: NS
                        xc = app.dirMat(i,1)*1e-3;
                        yc = app.dirMat(i,2)*1e-3;
                        x = SensorsCart(2,:);
                        y = SensorsCart(1,:);
                        dirAngles(i) = atan2(xc-x, yc-y);
                    end
                case 'Load unit vectors'
                    if isempty(app.dirMat)
                        uialert(app.DirectivityLoad, 'The directivity must be loaded or a different option must be selected.', 'Error');
                        return;
                    end
                    for i = 1: NS
                        xv = app.dirMat(i,1);
                        yv = -1*app.dirMat(i,2);
                        dirAngles(i) = atan2(xv, yv);
                    end
                case 'Load direction angles'
                    if isempty(app.dirMat)
                        uialert(app.DirectivityLoad, 'The directivity must be loaded or a different option must be selected.', 'Error');
                        return;
                    end
                    dirAngles = app.dirMat;
            end
            
            UpdateLoadDirectivity(app.CallingApp, dirAngles);
            delete(app.DirectivityLoad);
        end

        % Button pushed function: BrowseDirectivityDataButton
        function BrowseDirectivityDataButtonPushed(app, event)
            try
                f = app.DirectivityLoad;
                d = uiprogressdlg(f,'Title','Loading CSV file','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.DirectivityLoad.Visible = 'Off';
                app.DirectivityLoad.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    temp = value{1};
                elseif strcmp(ext, '.txt')
                    temp =  load(fullfile(PathName,filename));
                end
                guiHandle = AssignDirectivity2App(app, temp);
                guiFigureHandle = guiHandle.DirectivityAssign2;
                uiwait(guiFigureHandle)
                app.BrowseDirectivityDataButton.BackgroundColor = [0.39,0.83,0.07];
                close(d);
            catch ME
                uialert(app.DirectivityLoad, ME.message, 'Image Error');
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DirectivityLoad = uifigure('Visible', 'off');
            app.DirectivityLoad.Name = 'MATLAB App';
            app.DirectivityLoad.Icon = 'PatLab_Logo_1.png';
            app.ApplyButton = uibutton(app.DirectivityLoad, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MethodButtonGroup = uibuttongroup(app.DirectivityLoad);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method:';
            app.GlobalfocalpointButton = uiradiobutton(app.MethodButtonGroup);
            app.GlobalfocalpointButton.Text = 'Global focal point';
            app.GlobalfocalpointButton.Value = true;
            app.GlobalunitvectorButton = uiradiobutton(app.MethodButtonGroup);
            app.GlobalunitvectorButton.Text = 'Global unit vector';
            app.LoadfocalpointsButton = uiradiobutton(app.MethodButtonGroup);
            app.LoadfocalpointsButton.Text = 'Load focal points';
            app.BrowseDirectivityDataButton = uibutton(app.MethodButtonGroup, 'push');
            app.BrowseDirectivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDirectivityDataButtonPushed, true);
            app.BrowseDirectivityDataButton.Enable = 'off';
            app.BrowseDirectivityDataButton.Text = 'Browse Directivity Data';
            app.xEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.yEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.xEditField_2 = uieditfield(app.MethodButtonGroup, 'numeric');
            app.xEditField_2.Enable = 'off';
            app.yEditField_2 = uieditfield(app.MethodButtonGroup, 'numeric');
            app.yEditField_2.Enable = 'off';
            app.LoadunitvectorsButton = uiradiobutton(app.MethodButtonGroup);
            app.LoadunitvectorsButton.Text = 'Load unit vectors';
            app.xLabel = uilabel(app.MethodButtonGroup);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.MethodButtonGroup);
            app.yLabel.Text = 'y:';
            app.xLabel_2 = uilabel(app.MethodButtonGroup);
            app.xLabel_2.Enable = 'off';
            app.xLabel_2.Text = 'x:';
            app.yLabel_2 = uilabel(app.MethodButtonGroup);
            app.yLabel_2.Enable = 'off';
            app.yLabel_2.Text = 'y:';
            app.LoaddirectionanglesButton = uiradiobutton(app.MethodButtonGroup);
            app.LoaddirectionanglesButton.Text = 'Load direction angles';
            % Show the figure after all components are created
            app.DirectivityLoad.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadDirectivityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DirectivityLoad)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DirectivityLoad)
        end
    end
end