classdef PlotGUI_16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotApp                         matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ColorControlPanel               matlab.ui.container.Panel
        ColormapSelection               matlab.ui.control.DropDown
        ColormapDropDown_2Label         matlab.ui.control.Label
        FramenumberSpinner_2Label       matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        ContrastBrightnessControlPanel  matlab.ui.container.Panel
        WindowLevelButton               matlab.ui.control.Button
        FramenumberSpinnerLabel         matlab.ui.control.Label
        FramenumberSpinner_2            matlab.ui.control.Spinner
        MagnificationSpinnerLabel       matlab.ui.control.Label
        MagnificationSpinner            matlab.ui.control.Spinner
        VolumeViewPanel                 matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        SliceViewPanel                  matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        NormalizedMultisliceviewPanel   matlab.ui.container.Panel
        PlotButton_3                    matlab.ui.control.Button
        HistogramPanel                  matlab.ui.container.Panel
        FramenumberSpinner_3Label       matlab.ui.control.Label
        FramenumberSpinner_3            matlab.ui.control.Spinner
        HistogramUi                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Axes % Description
        CallingApp
        inputImage
    end
    methods (Access = private)
        function updateFigures(app, inputImage)
            %             Max = max(inputImage(:));
            %             Min = min(inputImage(:));
            ySlice = size(inputImage,1); %number of slices
            xSlice = size(inputImage,2); %number of slices
            zSlice = size(inputImage,3); %number of slices
            
            %             iter = 0;
            %             color = get(app.PlotApp,'Color');
            color = get(app.PlotApp,'Color');
            if zSlice ==1
%                 t = tiledlayout(app.Panel_fig, 1, 1);
                % Access first subplot-tile for plotting
                ax = uiaxes(app.PlotApp, 'Position',[222,1,550,550]);
                set(ax,'XColor',color,'YColor',color,'TickDir','out')
                I = imshow(inputImage,'Parent',ax,'DisplayRange',[],'XData', [1 ax.Position(3)], 'YData', [1 ax.Position(4)]);
                ax.XLim = [0 I.XData(2)];   ax.YLim = [0 I.YData(2)];
            else
                app.Spinner.Value = zSlice;
                [mip3, indx3] = max(inputImage, [], 3);
                [mip2, indx2] = max(inputImage, [], 2);
                [mip1, indx1] = max(inputImage, [], 1);
                figSize = 280;
                figSize = figSize/2;
               
                ax1 = uiaxes(app.PlotApp, 'Position',[222,281,figSize+round(figSize*xSlice/max([zSlice,ySlice,xSlice])),figSize+round(figSize*ySlice/max([zSlice,ySlice,xSlice]))]);
                set(ax1,'XColor',color,'YColor',color,'TickDir','out')
                I = imshow(mip3,'Parent', ax1, 'DisplayRange',[],'XData', [1 ax1.Position(3)], 'YData', [1 ax1.Position(4)]);
                ax3.XLim = [0 I.XData(2)];   ax3.YLim = [0 I.YData(2)];
                ax2 = uiaxes(app.PlotApp, 'Position',[560,281,figSize+round(figSize*zSlice/max([zSlice,ySlice,xSlice])),figSize+round(figSize*ySlice/max([zSlice,ySlice,xSlice]))]);
                set(ax2,'XColor',color,'YColor',color,'TickDir','out')
                I = imshow(squeeze(mip2),'Parent', ax2, 'DisplayRange',[],'XData', [1 ax2.Position(3)], 'YData', [1 ax2.Position(4)]);
                ax2.XLim = [0 I.XData(2)];   ax2.YLim = [0 I.YData(2)];
                ax3 = uiaxes(app.PlotApp, 'Position',[222,1,figSize+round(figSize*xSlice/max([zSlice,ySlice,xSlice])),figSize+round(figSize*zSlice/max([zSlice,ySlice,xSlice]))]);
                set(ax3,'XColor',color,'YColor',color,'TickDir','out')
                I = imshow(squeeze(mip1)','Parent', ax3, 'DisplayRange',[],'XData', [1 ax3.Position(3)], 'YData', [1 ax3.Position(4)]);
                ax3.XLim = [0 I.XData(2)];   ax3.YLim = [0 I.YData(2)];
                ax = [ax1, ax2, ax3];
            end

            app.Axes=ax;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, recImage, title)
            app.PlotApp.Name = append('Display tools: ', string(title));
            app.CallingApp = MainApp;
            try
                app.inputImage = recImage;
                updateFigures(app, recImage)
                FramenumberSpinner_3ValueChanged(app)
            catch ME
                % If problem reading image, display error message
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: ColormapSelection
        function ColormapSelectionValueChanged(app, event)
            Colmap = app.ColormapSelection.Value;
            for i =1:size(app.Axes,2)
                colormap(app.Axes(i), Colmap)
            end
        end

        % Button pushed function: WindowLevelButton
        function WindowLevelButtonPushed(app, event)
            try
                iSlice = app.FramenumberSpinner_2.Value;
                im=app.inputImage(:,:,iSlice);
                im= uint8(255 * mat2gray(im)); 
                figure(112),clf(112)
                imshow(im, 'DisplayRange',[],'InitialMagnification', app.MagnificationSpinner.Value);
                ax = gca;
                %Create a colorbar
                c_b=cell(1,2);c_b{1,1}='vert';
                c_b{1,2}=colorbar(c_b{1,1},'peer',ax);
                enabled=true;
                default_caxis_values=[50 250];
                speed_multiplier=1;
                legacy_colorbar=c_b;
                enableTagTrigger=true;
                WindowLevel(ax,enabled,default_caxis_values,speed_multiplier,legacy_colorbar,enableTagTrigger)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            try
                im=app.inputImage;
                volumeViewer(im)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            try
                im=app.inputImage;
                im= uint8(255 * mat2gray(im));
                figure; 
                sliceViewer(im)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            try
                imdisp(app.inputImage)
            catch ME
                uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: FramenumberSpinner_3
        function FramenumberSpinner_3ValueChanged(app, event)
            try
                iSlice = app.FramenumberSpinner_3.Value;
                im=app.inputImage(:,:,iSlice);
                 histogram(app.HistogramUi, im, 'FaceColor','g','EdgeColor', 'none');
           catch ME
               uialert(app.PlotApp, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            
            value = app.Spinner.Value;
            xSlice = size(app.inputImage,2);
            ySlice = size(app.inputImage,1);
            zSlice = size(app.inputImage,3);
            if value>zSlice
                uialert(app.PlotApp, 'Outside the range!', 'Error');
                return;
            end
            figSize = 280;
            figSize = figSize/2;
            ax1 = uiaxes(app.PlotApp, 'Position',[222,281,figSize+round(figSize*xSlice/max([zSlice,ySlice,xSlice])),figSize+round(figSize*ySlice/max([zSlice,ySlice,xSlice]))]);
            set(ax1,'TickDir','out')
            I = imshow(app.inputImage(:,:,value),'Parent', ax1, 'DisplayRange',[],'XData', [1 ax1.Position(3)], 'YData', [1 ax1.Position(4)]);
            ax1.XLim = [0 I.XData(2)];   ax1.YLim = [0 I.YData(2)];
            Colmap = app.ColormapSelection.Value;
            colormap(app.Axes(1), Colmap)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotApp = uifigure('Visible', 'off');
            app.PlotApp.Name = 'MATLAB App';
            app.Panel = uipanel(app.PlotApp);
            app.ColorControlPanel = uipanel(app.Panel);
            app.ColorControlPanel.Title = 'Color Control:';
            app.ColormapSelection = uidropdown(app.ColorControlPanel);
            app.ColormapSelection.Items = {'gray', 'jet', 'parula', 'autumn', 'bone', 'colorcube', 'cool', 'copper', 'flag', 'hot', 'hsv', 'lines', 'pink', 'prism', 'spring', 'summer', 'white', 'winter'};
            app.ColormapSelection.ValueChangedFcn = createCallbackFcn(app, @ColormapSelectionValueChanged, true);
            app.ColormapSelection.Tooltip = {'Valid for 3D surface dispaly'};
            app.ColormapSelection.Value = 'gray';
            app.ColormapDropDown_2Label = uilabel(app.ColorControlPanel);
            app.ColormapDropDown_2Label.Text = 'Colormap:';
            app.FramenumberSpinner_2Label = uilabel(app.ColorControlPanel);
            app.FramenumberSpinner_2Label.Text = 'Frame number:';
            app.Spinner = uispinner(app.ColorControlPanel);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Tooltip = {'z slice'};
            app.Spinner.Value = 1;
            app.ContrastBrightnessControlPanel = uipanel(app.Panel);
            app.ContrastBrightnessControlPanel.Title = 'Contrast & Brightness Control:';
            app.WindowLevelButton = uibutton(app.ContrastBrightnessControlPanel, 'push');
            app.WindowLevelButton.ButtonPushedFcn = createCallbackFcn(app, @WindowLevelButtonPushed, true);
            app.WindowLevelButton.Text = 'Window Level';
            app.FramenumberSpinnerLabel = uilabel(app.ContrastBrightnessControlPanel);
            app.FramenumberSpinnerLabel.Text = 'Frame number:';
            app.FramenumberSpinner_2 = uispinner(app.ContrastBrightnessControlPanel);
            app.FramenumberSpinner_2.Limits = [1 Inf];
            app.FramenumberSpinner_2.Tooltip = {'z slice'};
            app.FramenumberSpinner_2.Value = 1;
            app.MagnificationSpinnerLabel = uilabel(app.ContrastBrightnessControlPanel);
            app.MagnificationSpinnerLabel.Text = 'Magnification:';
            app.MagnificationSpinner = uispinner(app.ContrastBrightnessControlPanel);
            app.MagnificationSpinner.Value = 500;
            app.VolumeViewPanel = uipanel(app.Panel);
            app.VolumeViewPanel.Title = 'Volume View:';
            app.PlotButton = uibutton(app.VolumeViewPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {'sliceomatic'};
            app.PlotButton.Text = 'Plot';
            app.SliceViewPanel = uipanel(app.Panel);
            app.SliceViewPanel.Title = 'Slice View:';
            app.PlotButton_2 = uibutton(app.SliceViewPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.NormalizedMultisliceviewPanel = uipanel(app.Panel);
            app.NormalizedMultisliceviewPanel.Title = 'Normalized Multislice view:';
            app.PlotButton_3 = uibutton(app.NormalizedMultisliceviewPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Tooltip = {'imdisp'};
            app.PlotButton_3.Text = 'Plot';
            app.HistogramPanel = uipanel(app.Panel);
            app.HistogramPanel.Title = 'Histogram:';
            app.FramenumberSpinner_3Label = uilabel(app.HistogramPanel);
            app.FramenumberSpinner_3Label.Text = 'Frame number:';
            app.FramenumberSpinner_3 = uispinner(app.HistogramPanel);
            app.FramenumberSpinner_3.Limits = [1 Inf];
            app.FramenumberSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FramenumberSpinner_3ValueChanged, true);
            app.FramenumberSpinner_3.Tooltip = {'z slice'};
            app.FramenumberSpinner_3.Value = 1;
            app.HistogramUi = uiaxes(app.HistogramPanel);
            title(app.HistogramUi, 'Histogram')
            xlabel(app.HistogramUi, 'Intensity Value')
            ylabel(app.HistogramUi, 'Number of Pixels')
            % Show the figure after all components are created
            app.PlotApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotGUI_16(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotApp)
        end
    end
end