classdef AssignDirectivity2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DirectivityAssign2  matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        DropDown_3          matlab.ui.control.DropDown
        DropDown_1          matlab.ui.control.DropDown
        DropDown_2          matlab.ui.control.DropDown
        size3               matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        size2               matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        size1               matlab.ui.control.NumericEditField
        Label1              matlab.ui.control.Label
        AssignButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % main app 
        loadedMat % loaded data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, dirMat)
            app.DirectivityAssign2.Name = 'Assign Loaded Directivity';
            app.CallingApp = MainApp;
            app.loadedMat = dirMat;
            app.size1.Value = size(dirMat,1);
            app.size2.Value = size(dirMat,2);
            app.size3.Value = size(dirMat,3);
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
             % Change the outputMat_input coorfinates to
            % [Detectors, coordinates(2 or 3), Frames]
            val1 = app.DropDown_1.Value;
            val2 = app.DropDown_2.Value;
            val3 = app.DropDown_3.Value;
            Loc = app.loadedMat;
            if isempty(Loc)
                warndlg('Load data first', 'Warning')
                app.AssignButton.BackgroundColor = 'r';
            elseif strcmp(val1, val2)||strcmp(val1, val3)||strcmp(val2, val3)
                warndlg('The three dimensions DropDowns should be different', 'Warning')
                app.AssignButton.BackgroundColor = 'r';
            else
                if strcmp(val1, 'Detectors')&&strcmp(val2, 'Coordinates')&&strcmp(val3, 'Frames')
                    outputMat = Loc;
                elseif strcmp(val1, 'Detectors')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Coordinates')
                    outputMat = permute(Loc, [1, 3, 2]);
                elseif strcmp(val1, 'Coordinates')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Frames')
                    outputMat = permute(Loc, [2, 1, 3]);
                elseif strcmp(val1, 'Coordinates')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Detectors')
                    outputMat = permute(Loc, [2, 3, 1]);
                elseif strcmp(val1, 'Frames')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Coordinates')
                    outputMat = permute(Loc, [3, 1, 2]);
                elseif strcmp(val1, 'Frames')&&strcmp(val2, 'Coordinates')&&strcmp(val3, 'Detectors')
                    outputMat = permute(Loc, [3, 2, 1]);
                end
                app.AssignButton.BackgroundColor = [0.39,0.83,0.07];
            end
            
            UpdateAssignDirectivity2(app.CallingApp, outputMat);
            delete(app.DirectivityAssign2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DirectivityAssign2 = uifigure('Visible', 'off');
            app.DirectivityAssign2.Name = 'MATLAB App';
            app.DirectivityAssign2.Icon = 'PatLab_Logo_1.png';
            app.Panel = uipanel(app.DirectivityAssign2);
            app.AssignButton = uibutton(app.Panel, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            app.Label1 = uilabel(app.Panel);
            app.Label1.Text = 'Size:      1:';
            app.size1 = uieditfield(app.Panel, 'numeric');
            app.size1.Editable = 'off';
            app.size1.Value = 1;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '2:';
            app.size2 = uieditfield(app.Panel, 'numeric');
            app.size2.Editable = 'off';
            app.size2.Value = 1;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '3:';
            app.size3 = uieditfield(app.Panel, 'numeric');
            app.size3.Editable = 'off';
            app.size3.Value = 1;
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_2.Value = 'Coordinates';
            app.DropDown_1 = uidropdown(app.Panel);
            app.DropDown_1.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_1.Value = 'Coordinates';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_3.Value = 'Coordinates';
            % Show the figure after all components are created
            app.DirectivityAssign2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AssignDirectivity2App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DirectivityAssign2)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DirectivityAssign2)
        end
    end
end