classdef DesignSoSmap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesignSpeedofSoundSoSMatrixPanel  matlab.ui.container.Panel
        ProcessButton                   matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        AssignButton                    matlab.ui.control.Button
        MethodofInterpolationDropDown   matlab.ui.control.DropDown
        MethodofInterpolationDropDownLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Step3Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        NumberofRegionsinySpinner       matlab.ui.control.Spinner
        NumberofRegionsinySpinnerLabel  matlab.ui.control.Label
        NumberofRegionsinxSpinner       matlab.ui.control.Spinner
        NumberofRegionsinxSpinnerLabel  matlab.ui.control.Label
        NumberofRegionsinzLabel         matlab.ui.control.Label
        NumberofRegionsinzSpinner       matlab.ui.control.Spinner
        zsliceLabel                     matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DefaultSoSmsSpinner             matlab.ui.control.Spinner
        DefaultSoSmsSpinnerLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        uit % Description
        Icone % Description
        CallingApp % Description
        computationalGrid % Description
        data3D
        outPut = [];% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, dim)
            app.UIFigure.Name = 'Design SoS map for k-wave PA signal simulation';
            app.CallingApp = MainApp;
            app.UITable.Data = num2cell(1500.*ones(4));
            app.UITable.Position = [283,33,339,311];
            app.UITable.ColumnEditable = true;
            app.UITable.ColumnName = {};
            app.UITable.RowName = {};
            app.UITable.ColumnWidth = 'auto';
            app.computationalGrid = dim;
            if size(dim,2) ==2
                app.Icone = uiimage(app.DesignSpeedofSoundSoSMatrixPanel);
                app.Icone.Position = [51,105,135,135];
                app.Icone.ImageSource = 'Design2D.png';
            else
                app.Icone = uiimage(app.DesignSpeedofSoundSoSMatrixPanel);
                app.Icone.Position = [50,100,142,142];
                app.Icone.ImageSource = 'Design3D.png';
                app.NumberofRegionsinzSpinner.Visible = 'On';
                app.NumberofRegionsinzLabel.Visible = 'On';
                app.zsliceLabel.Visible = 'On';
                app.SliceSpinner.Visible = 'On';
                app.AssignButton.Visible = 'On';
            end
            DefaultSoSmsSpinnerValueChanged(app);

        end

        % Value changed function: DefaultSoSmsSpinner, 
        % NumberofRegionsinxSpinner, NumberofRegionsinySpinner, 
        % NumberofRegionsinzSpinner
        function DefaultSoSmsSpinnerValueChanged(app, event)
            SoS = app.DefaultSoSmsSpinner.Value;
            M = app.NumberofRegionsinxSpinner.Value;
            N = app.NumberofRegionsinySpinner.Value;
            
            if size(app.computationalGrid,2) ==2
                data = SoS.* ones(N,M);
                app.UITable.Data = num2cell(data);
            else
                L = app.NumberofRegionsinzSpinner.Value;
                data = SoS.* ones(N,M,L);
                app.SliceSpinner.UpperLimitInclusive = L; 
                app.UITable.Data = num2cell(data(:,:,1));
                app.data3D = data;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.outPut)
                if size(app.computationalGrid,2) ==2
                    data = app.UITable.Data;
                    data = cell2mat(data);
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = interp2(X,Y,data,Xq,Yq,app.MethodofInterpolationDropDown.Value);
                else
                    data = app.data3D;
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = zeros(app.computationalGrid(1),app.computationalGrid(2),size(data,3));
                    data(:,:,app.SliceSpinner.Value) = cell2mat(app.UITable.Data);
                    for i = 1:size(data,3)
                        tempData(:,:,i) = interp2(X,Y,data(:,:,i),Xq,Yq,app.MethodofInterpolationDropDown.Value);
                    end
                end
                app.outPut = tempData;
            end
            % Call main app's public function
            UpdateSoSmap(app.CallingApp, app.outPut);
            delete(app.UIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             if isempty(app.outPut)
                if size(app.computationalGrid,2) ==2
                    data = app.UITable.Data;
                    data = cell2mat(data);
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = interp2(X,Y,data,Xq,Yq,app.MethodofInterpolationDropDown.Value);
                else
                    data = app.data3D;
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = zeros(app.computationalGrid(1),app.computationalGrid(2),size(data,3));
                    data(:,:,app.SliceSpinner.Value) = cell2mat(app.UITable.Data);
                    for i = 1:size(data,3)
                        tempData(:,:,i) = interp2(X,Y,data(:,:,i),Xq,Yq,app.MethodofInterpolationDropDown.Value);
                    end
                end
                app.outPut = tempData;
             end
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','Save Data ...','Cancelable',"on");
            SOS_designed = app.outPut;
            formatOut = 'dd_mmm_yyyy';
            tNow = datestr(now,formatOut);
            title = sprintf('SOS_designed_%s.mat', tNow);
            save (title,'SOS_designed');
            message = sprintf('The file has been successfully saved to the directory: %s', cd);
            uialert(app.UIFigure,message,'Save', 'Icon','success');
            app.SaveButton.BackgroundColor = [0.39,0.83,0.07];
            close(d)
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            value = app.SliceSpinner.Value;
            app.data3D(:,:,value) = cell2mat(app.UITable.Data);
            app.AssignButton.BackgroundColor = [0.39,0.83,0.07];
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            app.AssignButton.BackgroundColor = [0.80,0.80,0.80];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             if isempty(app.outPut)
                if size(app.computationalGrid,2) ==2
                    data = app.UITable.Data;
                    data = cell2mat(data);
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = interp2(X,Y,data,Xq,Yq,app.MethodofInterpolationDropDown.Value);
                else
                    data = app.data3D;
                    [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                    x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                    y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                    [Xq,Yq] = meshgrid(x,y);
                    tempData = zeros(app.computationalGrid(1),app.computationalGrid(2),size(data,3));
                    data(:,:,app.SliceSpinner.Value) = cell2mat(app.UITable.Data);
                    for i = 1:size(data,3)
                        tempData(:,:,i) = interp2(X,Y,data(:,:,i),Xq,Yq,app.MethodofInterpolationDropDown.Value);
                    end
                end
                app.outPut = tempData;
            end
            if size(app.computationalGrid,2) ==2
                figure; imshow(app.outPut, [])
            else
                data = app.outPut;
                figure;
                h = slice(data, [], [], 1:size(data,3));
                set(h, 'EdgeColor','none', 'FaceColor','interp')
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
             if size(app.computationalGrid,2) ==2
                data = app.UITable.Data;
                data = cell2mat(data);
                [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                [Xq,Yq] = meshgrid(x,y);
                tempData = interp2(X,Y,data,Xq,Yq,app.MethodofInterpolationDropDown.Value);
            else
                data = app.data3D;
                [X,Y] = meshgrid(1:size(data,2),1:size(data,1));
                x = 1:(size(data,2)-1)/(app.computationalGrid(2)-1):size(data,2);
                y = 1:(size(data,1)-1)/(app.computationalGrid(1)-1):size(data,1);
                [Xq,Yq] = meshgrid(x,y);
                tempData = zeros(app.computationalGrid(1),app.computationalGrid(2),size(data,3));
                data(:,:,app.SliceSpinner.Value) = cell2mat(app.UITable.Data);
                for i = 1:size(data,3)
                    tempData(:,:,i) = interp2(X,Y,data(:,:,i),Xq,Yq,app.MethodofInterpolationDropDown.Value);
                end
            end
            % Call main app's public function
            UpdateSoSmap(app.CallingApp, tempData);
            app.outPut = tempData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'PatLab_Logo_1.png';
            app.DesignSpeedofSoundSoSMatrixPanel = uipanel(app.UIFigure);
            app.DesignSpeedofSoundSoSMatrixPanel.Title = 'Design Speed of Sound (SoS) Matrix:';
            app.DefaultSoSmsSpinnerLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.DefaultSoSmsSpinnerLabel.Text = 'Default SoS (m/s):';
            app.DefaultSoSmsSpinner = uispinner(app.DesignSpeedofSoundSoSMatrixPanel);
            app.DefaultSoSmsSpinner.Limits = [1 Inf];
            app.DefaultSoSmsSpinner.ValueChangedFcn = createCallbackFcn(app, @DefaultSoSmsSpinnerValueChanged, true);
            app.DefaultSoSmsSpinner.Value = 1500;
            app.UITable = uitable(app.DesignSpeedofSoundSoSMatrixPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ExportButton = uibutton(app.DesignSpeedofSoundSoSMatrixPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.xLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.yLabel.Text = 'y';
            app.SliceSpinner = uispinner(app.DesignSpeedofSoundSoSMatrixPanel);
            app.SliceSpinner.Limits = [1 Inf];
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Visible = 'off';
            app.SliceSpinner.Value = 1;
            app.zsliceLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.zsliceLabel.Visible = 'off';
            app.zsliceLabel.Text = 'z slice #:';
            app.NumberofRegionsinzSpinner = uispinner(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinzSpinner.Limits = [1 Inf];
            app.NumberofRegionsinzSpinner.ValueChangedFcn = createCallbackFcn(app, @DefaultSoSmsSpinnerValueChanged, true);
            app.NumberofRegionsinzSpinner.Visible = 'off';
            app.NumberofRegionsinzSpinner.Value = 2;
            app.NumberofRegionsinzLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinzLabel.Visible = 'off';
            app.NumberofRegionsinzLabel.Text = 'Number of Regions in z:';
            app.NumberofRegionsinxSpinnerLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinxSpinnerLabel.Text = 'Number of Regions in x:';
            app.NumberofRegionsinxSpinner = uispinner(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinxSpinner.Limits = [1 Inf];
            app.NumberofRegionsinxSpinner.ValueChangedFcn = createCallbackFcn(app, @DefaultSoSmsSpinnerValueChanged, true);
            app.NumberofRegionsinxSpinner.Value = 4;
            app.NumberofRegionsinySpinnerLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinySpinnerLabel.Text = 'Number of Regions in y:';
            app.NumberofRegionsinySpinner = uispinner(app.DesignSpeedofSoundSoSMatrixPanel);
            app.NumberofRegionsinySpinner.Limits = [1 Inf];
            app.NumberofRegionsinySpinner.ValueChangedFcn = createCallbackFcn(app, @DefaultSoSmsSpinnerValueChanged, true);
            app.NumberofRegionsinySpinner.Value = 4;
            app.Step1Label = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.Step1Label.Text = 'Step 1:';
            app.Step2Label = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.Step2Label.Text = 'Step 2:';
            app.Step3Label = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.Step3Label.Text = 'Step 3:';
            app.SaveButton = uibutton(app.DesignSpeedofSoundSoSMatrixPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MethodofInterpolationDropDownLabel = uilabel(app.DesignSpeedofSoundSoSMatrixPanel);
            app.MethodofInterpolationDropDownLabel.Text = 'Method of Interpolation:';
            app.MethodofInterpolationDropDown = uidropdown(app.DesignSpeedofSoundSoSMatrixPanel);
            app.MethodofInterpolationDropDown.Items = {'linear', 'nearest', 'cubic', 'makima', 'spline'};
            app.MethodofInterpolationDropDown.Tooltip = {'to resize the matrix into the computational grid size'};
            app.MethodofInterpolationDropDown.Value = 'linear';
            app.AssignButton = uibutton(app.DesignSpeedofSoundSoSMatrixPanel, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Visible = 'off';
            app.AssignButton.Text = 'Assign';
            app.PlotButton = uibutton(app.DesignSpeedofSoundSoSMatrixPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ProcessButton = uibutton(app.DesignSpeedofSoundSoSMatrixPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignSoSmap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end