classdef PATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PAT_ReconstructionInterface     matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Switch                          matlab.ui.control.ToggleSwitch
        SaveButton                      matlab.ui.control.Button
        OptionsDropDownType             matlab.ui.control.DropDown
        OptionsDropDownData             matlab.ui.control.DropDown
        OptionsDropDown                 matlab.ui.control.DropDown
        SelectDataSamplesButtonGroup    matlab.ui.container.ButtonGroup
        ArrayLabel                      matlab.ui.control.Label
        intervalEditField_5             matlab.ui.control.NumericEditField
        intervalEditField_5Label_2      matlab.ui.control.Label
        SamplesToEditField              matlab.ui.control.NumericEditField
        lastLabel                       matlab.ui.control.Label
        firstEditField_5                matlab.ui.control.NumericEditField
        firstLabel                      matlab.ui.control.Label
        SelectFramesButtonGroup         matlab.ui.container.ButtonGroup
        lastEditField_4                 matlab.ui.control.NumericEditField
        lastEditField_4Label            matlab.ui.control.Label
        intervalEditField_4             matlab.ui.control.NumericEditField
        intervalEditField_4Label        matlab.ui.control.Label
        firstEditField_4                matlab.ui.control.NumericEditField
        firstEditField_4Label           matlab.ui.control.Label
        ArrayButton                     matlab.ui.control.RadioButton
        selectedFramesEditField_2       matlab.ui.control.EditField
        SelectedButton                  matlab.ui.control.RadioButton
        SelectDetectorsButtonGroup      matlab.ui.container.ButtonGroup
        lastEditField_3                 matlab.ui.control.NumericEditField
        lastEditField_3Label            matlab.ui.control.Label
        intervalEditField_3             matlab.ui.control.NumericEditField
        intervalEditField_3Label        matlab.ui.control.Label
        firstEditField_3                matlab.ui.control.NumericEditField
        firstEditField_3Label           matlab.ui.control.Label
        ArrayButton_2                   matlab.ui.control.RadioButton
        selectedTransducersEditField_2  matlab.ui.control.EditField
        SelectedButton_2                matlab.ui.control.RadioButton
        PlotButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        LoadingGeneratingRawDataTab     matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        DesignDetectorLocationsButton   matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        size3EditField_2                matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        size2EditField_2                matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        size1EditField_2                matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        SizeLabel_2                     matlab.ui.control.Label
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        AssignButton_2                  matlab.ui.control.Button
        LoadDetectorLocationsButton     matlab.ui.control.Button
        PropertiesTextArea_2            matlab.ui.control.TextArea
        Panel_7                         matlab.ui.container.Panel
        SimulateTimeSeriesDataButton    matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        size3EditField                  matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        size2EditField                  matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        size1EditField                  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        SizeLabel                       matlab.ui.control.Label
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        AssignButton                    matlab.ui.control.Button
        PropertiesTextArea              matlab.ui.control.TextArea
        LoadTimeSeriesDataButton        matlab.ui.control.Button
        PreProcessingTab                matlab.ui.container.Tab
        Panel_12                        matlab.ui.container.Panel
        DownsamplingPanel               matlab.ui.container.Panel
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        FactorEditField                 matlab.ui.control.NumericEditField
        FactorEditFieldLabel            matlab.ui.control.Label
        InputDataDropDown_Downsampling  matlab.ui.control.DropDown
        InputdataLabel_4                matlab.ui.control.Label
        MethodDropDown_Downsampling     matlab.ui.control.DropDown
        MethodDropDown_2Label           matlab.ui.control.Label
        DownsamplingButton              matlab.ui.control.Button
        Panel_11                        matlab.ui.container.Panel
        NormalizationPanel              matlab.ui.container.Panel
        MethodType_4                    matlab.ui.control.DropDown
        MethodType_3                    matlab.ui.control.DropDown
        MethodType_2                    matlab.ui.control.DropDown
        MethodType_1                    matlab.ui.control.DropDown
        labelN_1                        matlab.ui.control.Label
        valueN_1                        matlab.ui.control.NumericEditField
        labelN_2                        matlab.ui.control.Label
        valueN_2                        matlab.ui.control.NumericEditField
        MethodtypeoptionsLabel          matlab.ui.control.Label
        InputDataDropDown_Normalization  matlab.ui.control.DropDown
        InputdataLabel_2                matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        NormalizationButton             matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        FilteringPanel                  matlab.ui.container.Panel
        IRR_Option2_2                   matlab.ui.control.NumericEditField
        IRR_Option1_2                   matlab.ui.control.NumericEditField
        optionsLabel2                   matlab.ui.control.Label
        IRR_Option2                     matlab.ui.control.NumericEditField
        IRR_Option1                     matlab.ui.control.NumericEditField
        lowerLabel                      matlab.ui.control.Label
        upperLabel                      matlab.ui.control.Label
        band3Lo                         matlab.ui.control.NumericEditField
        band3Up                         matlab.ui.control.NumericEditField
        band2Lo                         matlab.ui.control.NumericEditField
        band2Up                         matlab.ui.control.NumericEditField
        band1Lo                         matlab.ui.control.NumericEditField
        band1Up                         matlab.ui.control.NumericEditField
        Bnad3Label                      matlab.ui.control.Label
        Bnad2Label                      matlab.ui.control.Label
        Bnad1Label                      matlab.ui.control.Label
        densOption_Filter               matlab.ui.control.NumericEditField
        lowXFreq_Filter                 matlab.ui.control.NumericEditField
        lowXLabel                       matlab.ui.control.Label
        highXLabel                      matlab.ui.control.Label
        highXFreq_Filter                matlab.ui.control.NumericEditField
        cosineOption_Filter             matlab.ui.control.NumericEditField
        WindowLabel                     matlab.ui.control.Label
        optionsLabel                    matlab.ui.control.Label
        shapeOption_Filter              matlab.ui.control.NumericEditField
        alphaOption_Filter              matlab.ui.control.NumericEditField
        attOption_Filter                matlab.ui.control.NumericEditField
        Window_Filter                   matlab.ui.control.DropDown
        TestthefilterButton             matlab.ui.control.Button
        Method_FIR                      matlab.ui.control.DropDown
        Method_IIR                      matlab.ui.control.DropDown
        Method_Filter                   matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        FilterTypr_Filter               matlab.ui.control.DropDown
        FiltertypeLabel                 matlab.ui.control.Label
        InputData_Filter                matlab.ui.control.DropDown
        InputdataLabel                  matlab.ui.control.Label
        ExamplePanel                    matlab.ui.container.Panel
        SelectDetectorEditField         matlab.ui.control.NumericEditField
        SelectDetectorEditFieldLabel    matlab.ui.control.Label
        SelectFrameEditField            matlab.ui.control.NumericEditField
        SelectFrameEditFieldLabel       matlab.ui.control.Label
        lowLabel                        matlab.ui.control.Label
        SignalSamplingRateMHzEditField  matlab.ui.control.NumericEditField
        SignalSamplingRateMHzEditFieldLabel  matlab.ui.control.Label
        CutofffreqMHzLabel              matlab.ui.control.Label
        highLabel                       matlab.ui.control.Label
        filterOrder                     matlab.ui.control.NumericEditField
        OrderLabel                      matlab.ui.control.Label
        lowFreq_Filter                  matlab.ui.control.NumericEditField
        highFreq_Filter                 matlab.ui.control.NumericEditField
        FilteringButton                 matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        MatchedFilteringPanel           matlab.ui.container.Panel
        LevelEditField                  matlab.ui.control.NumericEditField
        LevelEditFieldLabel             matlab.ui.control.Label
        LoadButton_MF                   matlab.ui.control.Button
        Label2_MF                       matlab.ui.control.Label
        numberSamplesAfterMax           matlab.ui.control.NumericEditField
        Label_MF                        matlab.ui.control.Label
        numberSamplesBeforeMax          matlab.ui.control.NumericEditField
        ButtonGroup_MF                  matlab.ui.container.ButtonGroup
        UseonetemplateforalltimeseriesButton  matlab.ui.control.RadioButton
        UseauniquetemplateforeachtimeseriesButton  matlab.ui.control.RadioButton
        Method_MF                       matlab.ui.control.DropDown
        MethodLabel_2                   matlab.ui.control.Label
        TesttheFilterButton_MF          matlab.ui.control.Button
        ExamplePanel_MF                 matlab.ui.container.Panel
        SelectDetector_MFExample        matlab.ui.control.NumericEditField
        SelectDetectorLabel             matlab.ui.control.Label
        SelectFrame_MFExample           matlab.ui.control.NumericEditField
        SelectFrameLabel                matlab.ui.control.Label
        InputDataDropDown_MF            matlab.ui.control.DropDown
        InputdataLabel_5                matlab.ui.control.Label
        MatchedFilteringButton          matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        DataCleaningButton              matlab.ui.control.Button
        DataCleaningPanel               matlab.ui.container.Panel
        InputDataDropDown_Cleaning      matlab.ui.control.DropDown
        InputdataLabel_3                matlab.ui.control.Label
        ofdatasamplesattheendtocutoutEditField  matlab.ui.control.NumericEditField
        ofdatasamplesattheendtocutoutLabel  matlab.ui.control.Label
        ofdatasamplesatthebeginningtocutoutEditField  matlab.ui.control.NumericEditField
        ofdatasamplesatthebeginningtocutoutLabel  matlab.ui.control.Label
        ofdatasamplesattheendtozeroEditField  matlab.ui.control.NumericEditField
        ofdatasamplesattheendtozeroEditFieldLabel  matlab.ui.control.Label
        FramestoremoveEditField         matlab.ui.control.EditField
        FramestoremoveEditFieldLabel    matlab.ui.control.Label
        ofdatasamplesatthebeginningtozeroEditField  matlab.ui.control.NumericEditField
        ofdatasamplesatthebeginningtozeroEditFieldLabel  matlab.ui.control.Label
        ThresholdlevelforentiredataamplitudeEditField  matlab.ui.control.NumericEditField
        ThresholdlevelforentiredataamplitudeEditFieldLabel  matlab.ui.control.Label
        DetectorstoremoveEditField      matlab.ui.control.EditField
        DetectorstoremoveEditFieldLabel  matlab.ui.control.Label
        ImageReconstructionTab          matlab.ui.container.Tab
        ComputationalGridPanel          matlab.ui.container.Panel
        numberofpixelszEditField        matlab.ui.control.NumericEditField
        numberofpixelszEditFieldLabel   matlab.ui.control.Label
        numberofpixelsyEditField        matlab.ui.control.NumericEditField
        numberofpixelsyEditFieldLabel   matlab.ui.control.Label
        numberofpixelsxEditField        matlab.ui.control.NumericEditField
        numberofpixelsxEditFieldLabel   matlab.ui.control.Label
        pixelsizezmmSpinner             matlab.ui.control.Spinner
        pixelsizezmmSpinnerLabel        matlab.ui.control.Label
        pixelsizeymmSpinner             matlab.ui.control.Spinner
        pixelsizeymmSpinnerLabel        matlab.ui.control.Label
        pixelsizexmmSpinner             matlab.ui.control.Spinner
        pixelsizexmmSpinnerLabel        matlab.ui.control.Label
        zsizemmSpinner                  matlab.ui.control.Spinner
        zsizemmSpinnerLabel             matlab.ui.control.Label
        ysizemmSpinner                  matlab.ui.control.Spinner
        ysizemmSpinnerLabel             matlab.ui.control.Label
        xsizemmSpinner                  matlab.ui.control.Spinner
        xsizemmSpinnerLabel             matlab.ui.control.Label
        centerxmmSpinner                matlab.ui.control.Spinner
        centerxmmSpinnerLabel           matlab.ui.control.Label
        centerymmSpinner                matlab.ui.control.Spinner
        centerymmSpinnerLabel           matlab.ui.control.Label
        centerzmmSpinner                matlab.ui.control.Spinner
        centerzmmSpinnerLabel           matlab.ui.control.Label
        SelectSamplesButtonGroup_Rec    matlab.ui.container.ButtonGroup
        ArrayLabel_2                    matlab.ui.control.Label
        intervalEditField_6             matlab.ui.control.NumericEditField
        intervalEditField_6Label_2      matlab.ui.control.Label
        SamplesToEditField_2            matlab.ui.control.NumericEditField
        lastLabel_2                     matlab.ui.control.Label
        firstEditField_6                matlab.ui.control.NumericEditField
        firstEditField_6Label_2         matlab.ui.control.Label
        ReconstructionMethodDropDown    matlab.ui.control.DropDown
        ReconstructionMethodDropDownLabel  matlab.ui.control.Label
        Panel_Rec                       matlab.ui.container.Panel
        Panel_BP_options                matlab.ui.container.Panel
        DerivativesgainLabel            matlab.ui.control.Label
        DerivativesEditField            matlab.ui.control.NumericEditField
        SelectFrameLabel_2              matlab.ui.control.Label
        SelectDetectorLabel_2           matlab.ui.control.Label
        PowerLabel                      matlab.ui.control.Label
        AngleofacceptancedegLabel       matlab.ui.control.Label
        SelectDetectorDirectivityExample  matlab.ui.control.NumericEditField
        SelectFrameDirectivityExample   matlab.ui.control.NumericEditField
        ExampleLabel                    matlab.ui.control.Label
        FullangleofacceptancedegreeEditField  matlab.ui.control.NumericEditField
        PowerEditField                  matlab.ui.control.NumericEditField
        DirectivityCheckBox             matlab.ui.control.CheckBox
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        LoadunitvectorsButton           matlab.ui.control.RadioButton
        zEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        BrowseDirectivityDataButton     matlab.ui.control.Button
        LoadfocalpointsButton           matlab.ui.control.RadioButton
        GlobalunitvectorButton          matlab.ui.control.RadioButton
        GlobalfocalpointButton          matlab.ui.control.RadioButton
        CenterofthedetectorlocationsforeveryframeButton  matlab.ui.control.RadioButton
        WeightingLabel                  matlab.ui.control.Label
        CoherentfactorCheckBox          matlab.ui.control.CheckBox
        Panel_FT_options                matlab.ui.container.Panel
        interpMethod                    matlab.ui.control.DropDown
        label6_FT                       matlab.ui.control.Label
        label5_FT                       matlab.ui.control.Label
        field6_FT                       matlab.ui.control.NumericEditField
        field5_FT                       matlab.ui.control.NumericEditField
        field3_FT                       matlab.ui.control.NumericEditField
        field4_FT                       matlab.ui.control.NumericEditField
        label4_FT                       matlab.ui.control.Label
        label3_FT                       matlab.ui.control.Label
        label2_FT                       matlab.ui.control.Label
        label1_FT                       matlab.ui.control.Label
        ConfigurationButtonGroup        matlab.ui.container.ButtonGroup
        PlanararrayButton               matlab.ui.control.ToggleButton
        LineararrayButton               matlab.ui.control.ToggleButton
        NumberofavailabledetectorsEditField  matlab.ui.control.NumericEditField
        NumberofavailabledetectorsEditFieldLabel  matlab.ui.control.Label
        field1_FT                       matlab.ui.control.NumericEditField
        field2_FT                       matlab.ui.control.NumericEditField
        InterpolationCheckBox           matlab.ui.control.CheckBox
        SamplingpitchmmEditField        matlab.ui.control.NumericEditField
        SamplingpitchmmEditFieldLabel   matlab.ui.control.Label
        SpeedofsoundLabel               matlab.ui.control.Label
        Panel_TR_options                matlab.ui.container.Panel
        DelaysEditField                 matlab.ui.control.Spinner
        DelaysLabel                     matlab.ui.control.Label
        SamplingrateMHzEditField        matlab.ui.control.Spinner
        SamplingrateMHzLabel            matlab.ui.control.Label
        SpeedofsoundDropDown            matlab.ui.control.DropDown
        SOSDesignButton                 matlab.ui.control.Button
        SOSLoadButton                   matlab.ui.control.Button
        SoS                             matlab.ui.control.Spinner
        SlicingButtonGroup              matlab.ui.container.ButtonGroup
        zxyButton                       matlab.ui.control.ToggleButton
        yzxButton                       matlab.ui.control.ToggleButton
        xyzButton                       matlab.ui.control.ToggleButton
        DatatypeDropDown                matlab.ui.control.DropDown
        DatatypeDropDownLabel           matlab.ui.control.Label
        InputdataDropDown               matlab.ui.control.DropDown
        InputdataDropDownLabel          matlab.ui.control.Label
        SelectFramesButtonGroup_Rec     matlab.ui.container.ButtonGroup
        lastEditField_2                 matlab.ui.control.NumericEditField
        lastEditField_2Label            matlab.ui.control.Label
        intervalEditField_2             matlab.ui.control.NumericEditField
        intervalEditField_2Label        matlab.ui.control.Label
        firstEditField_2                matlab.ui.control.NumericEditField
        firstEditField_2Label           matlab.ui.control.Label
        ArrayButton_4                   matlab.ui.control.RadioButton
        selectedFramesEditField         matlab.ui.control.EditField
        SelectedButton_4                matlab.ui.control.RadioButton
        SelectSensorsButtonGroup_Rec    matlab.ui.container.ButtonGroup
        lastEditField                   matlab.ui.control.NumericEditField
        lastEditFieldLabel              matlab.ui.control.Label
        intervalEditField               matlab.ui.control.NumericEditField
        intervalEditFieldLabel          matlab.ui.control.Label
        firstEditField                  matlab.ui.control.NumericEditField
        firstEditFieldLabel             matlab.ui.control.Label
        ArrayButton_3                   matlab.ui.control.RadioButton
        selectedTransducersEditField    matlab.ui.control.EditField
        SelectedButton_3                matlab.ui.control.RadioButton
        ReconstructionButton            matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        UserManualButton                matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
    end

    properties (Access = private)
        selpathData % Description
        ImData
        sensorsPositions = []; %Sensors coordinates
        FramePositions
        NameCSV
        CleanedData % Description
        NormalizedData % Description
        PA_Image % Description
        ImData_input % Input raw data
        sensorsPositions_input % Input sensors' location
        MatchedFilter % Description
        SimulationDataApp % Description
        DialogApp % Description
        bpf % delete later
        FilteredData % Description
        dirMat % directivity info
        window % FIR-Window truncation window
        freqWin_Filter % Frequency window for filtering
        filterType % Description
        freqWin_Filter2 %FIR-Eq & LS
        amp_Filter2  %FIR-Eq & LS
        weight_Filter2 % FIR-Eq & LS
        filterDesigned % Description
        lowerBandLimit_Filter % FIR-CLS
        upperBandLimit_Filter % FIR-CLS
        freqWin_Filter3 % FIR-CLS
        amp_Filter3 % FIR-CLS
        loadedTemplateMF % Loaded/unique template for matched filter
        title % Description
        DownsampledData % Downsampled
    end
    
    methods (Access = public)
        
        function UpdateCoordinates(app, Loc)
            app.sensorsPositions_input = Loc;
            app.AssignButton_2.BackgroundColor = [0.80,0.80,0.80];
            app.LoadDetectorLocationsButton.BackgroundColor = [0.80,0.80,0.80];
            app.size1EditField_2.Value = size(Loc,1);
            app.size2EditField_2.Value = size(Loc,2);
            app.size3EditField_2.Value = size(Loc,3);
            format longG
            t = now;
            dateVal = datetime(t,'ConvertFrom','datenum');
            cellArrayText{1} = 'File generated with Design Detector Configuration';
            cellArrayText{2} = sprintf('%s','Date and Time: ', dateVal);
            app.PropertiesTextArea_2.Value = cellArrayText;
            app.LoadDetectorLocationsButton.BackgroundColor = [0.80,0.80,0.80];
            app.DesignDetectorLocationsButton.BackgroundColor = [0.39,0.83,0.07];
        end
        
        function UpdateLoadedData(app, SimData)
            app.ImData_input =  SimData;
            app.AssignButton.BackgroundColor = [0.80,0.80,0.80];
            app.size1EditField.Value = size(SimData,1);
            app.size2EditField.Value = size(SimData,2);
            app.size3EditField.Value = size(SimData,3);
            format longG
            t = now;
            dateVal = datetime(t,'ConvertFrom','datenum');
            cellArrayText{1} = 'Data generated with Simulate Raw Data';
            cellArrayText{2} = sprintf('%s','Date and Time: ', dateVal);
            app.PropertiesTextArea.Value = cellArrayText;
            app.SimulateTimeSeriesDataButton.BackgroundColor = [0.39,0.83,0.07];
        end
        
        function UpdateAssignDirectivity(app, importedDirMat)
           app.dirMat =  importedDirMat; 
        end
    end
    
    methods (Access = private)
        
        function WeightingOptions(app)
            value = app.Method_FIR.Value;
            switch value
                case 'Equiripple'
                    app.upperLabel.Visible = 'Off';
                    app.lowerLabel.Visible = 'Off';
                    app.band1Lo.Visible = 'Off';
                    app.band2Lo.Visible = 'Off';
                    app.band3Lo.Visible = 'Off';
                    app.Bnad1Label.Visible = 'On';
                    app.Bnad2Label.Visible = 'On';
                    app.band1Up.Visible = 'On';
                    app.band2Up.Visible = 'On';
                    if strcmp(app.FilterTypr_Filter.Value,'Lowpass')||strcmp(app.FilterTypr_Filter.Value,'Highpass')
                        app.Bnad3Label.Visible = 'Off';
                        app.band3Up.Visible = 'Off';
                    else
                        app.Bnad3Label.Visible = 'On';
                        app.band3Up.Visible = 'On';
                    end
                case 'Least-squares'
                    app.upperLabel.Visible = 'Off';
                    app.lowerLabel.Visible = 'Off';
                    app.band1Lo.Visible = 'Off';
                    app.band2Lo.Visible = 'Off';
                    app.band3Lo.Visible = 'Off';
                    app.Bnad1Label.Visible = 'On';
                    app.Bnad2Label.Visible = 'On';
                    app.band1Up.Visible = 'On';
                    app.band2Up.Visible = 'On';
                    if strcmp(app.FilterTypr_Filter.Value,'Lowpass')||strcmp(app.FilterTypr_Filter.Value,'Highpass')
                        app.Bnad3Label.Visible = 'Off';
                        app.band3Up.Visible = 'Off';
                    else
                        app.Bnad3Label.Visible = 'On';
                        app.band3Up.Visible = 'On';
                    end
                case 'Window'
                    app.upperLabel.Visible = 'Off';
                    app.lowerLabel.Visible = 'Off';
                    app.Bnad1Label.Visible = 'Off';
                    app.Bnad2Label.Visible = 'Off';
                    app.Bnad3Label.Visible = 'Off';
                    app.band1Up.Visible = 'Off';
                    app.band1Lo.Visible = 'Off';
                    app.band2Up.Visible = 'Off';
                    app.band2Lo.Visible = 'Off';
                    app.band3Up.Visible = 'Off';
                    app.band3Lo.Visible = 'Off';
                case 'Constrained-least-squares'
                    app.upperLabel.Visible = 'On';
                    app.lowerLabel.Visible = 'On';
                    app.band1Lo.Visible = 'On';
                    app.band2Lo.Visible = 'On';
                    app.Bnad1Label.Visible = 'On';
                    app.Bnad2Label.Visible = 'On';
                    app.band1Up.Visible = 'On';
                    app.band2Up.Visible = 'On';
                    if strcmp(app.FilterTypr_Filter.Value,'Lowpass')||strcmp(app.FilterTypr_Filter.Value,'Highpass')
                        app.Bnad3Label.Visible = 'Off';
                        app.band3Up.Visible = 'Off';
                        app.band3Lo.Visible = 'Off';
                    else
                        app.Bnad3Label.Visible = 'On';
                        app.band3Up.Visible = 'On';
                        app.band3Lo.Visible = 'On';
                    end
            end
        end
        
        function IIROptions(app)
            app.highXFreq_Filter.Visible = 'Off';
            app.lowXFreq_Filter.Visible = 'Off';
            app.highXLabel.Visible = 'Off';
            app.lowXLabel.Visible = 'Off';
            
            app.WindowLabel.Visible = 'Off';
            app.Window_Filter.Visible = 'Off';
            app.alphaOption_Filter.Visible = 'Off';
            app.shapeOption_Filter.Visible = 'Off';
            app.cosineOption_Filter.Visible = 'Off';
            app.densOption_Filter.Visible = 'Off';
            
            app.upperLabel.Visible = 'Off';
            app.lowerLabel.Visible = 'Off';
            app.Bnad1Label.Visible = 'Off';
            app.Bnad2Label.Visible = 'Off';
            app.Bnad3Label.Visible = 'Off';
            app.band1Up.Visible = 'Off';
            app.band1Lo.Visible = 'Off';
            app.band2Up.Visible = 'Off';
            app.band2Lo.Visible = 'Off';
            app.band3Up.Visible = 'Off';
            app.band3Lo.Visible = 'Off';
            
            val1 = app.FilterTypr_Filter.Value;
            switch val1
                case 'Lowpass'
                    app.lowFreq_Filter.Visible = 'Off';
                    app.lowLabel.Visible = 'Off';
                    app.highFreq_Filter.Visible = 'On';
                    app.highLabel.Visible = 'On';
                case 'Highpass'
                    app.lowFreq_Filter.Visible = 'On';
                    app.lowLabel.Visible = 'On';
                    app.highFreq_Filter.Visible = 'Off';
                    app.highLabel.Visible = 'Off';
                case 'Bandpass'
                    app.lowFreq_Filter.Visible = 'On';
                    app.lowLabel.Visible = 'On';
                    app.highFreq_Filter.Visible = 'On';
                    app.highLabel.Visible = 'On';
                case 'Bandstop'
                    app.lowFreq_Filter.Visible = 'On';
                    app.lowLabel.Visible = 'On';
                    app.highFreq_Filter.Visible = 'On';
                    app.highLabel.Visible = 'On';
            end
            val2 = app.Method_IIR.Value;
            switch val2
                case 'Butterworth'
                    app.optionsLabel.Text = '';
                    app.optionsLabel2.Text = '';
                    app.IRR_Option1.Visible = 'Off';
                    app.IRR_Option2.Visible = 'Off';
                    app.IRR_Option1_2.Visible = 'Off';
                    app.IRR_Option2_2.Visible = 'Off';
                case 'Chebyshev Type I'
                    app.optionsLabel.Text = 'Apass (dB):';
                    app.optionsLabel2.Text = '';
                    app.IRR_Option1.Visible = 'On';
                    app.IRR_Option1_2.Visible = 'Off';
                    app.IRR_Option2.Visible = 'Off';
                    app.IRR_Option2_2.Visible = 'Off';
                case 'Chebyshev Type II'
                    app.optionsLabel.Text = 'Astop (dB):';
                    app.optionsLabel2.Text = '';
                    app.IRR_Option1.Visible = 'Off';
                    app.IRR_Option1_2.Visible = 'On';
                    app.IRR_Option2.Visible = 'Off';
                    app.IRR_Option2_2.Visible = 'Off';
                case 'Elliptic'
                    switch val1
                        case 'Lowpass'
                            app.optionsLabel.Text = 'Apass (dB):';
                            app.optionsLabel2.Text = 'Astop (dB):';
                            app.IRR_Option1.Visible = 'On';
                            app.IRR_Option1_2.Visible = 'Off';
                            app.IRR_Option2.Visible = 'Off';
                            app.IRR_Option2_2.Visible = 'On';
                        case 'Highpass'
                            app.optionsLabel.Text = 'Astop (dB):';
                            app.optionsLabel2.Text = 'Apass (dB):';
                            app.IRR_Option1.Visible = 'Off';
                            app.IRR_Option1_2.Visible = 'On';
                            app.IRR_Option2.Visible = 'On';
                            app.IRR_Option2_2.Visible = 'Off';
                        case 'Bandpass'
                            app.optionsLabel.Text = 'Astop (dB):';
                            app.optionsLabel2.Text = 'Apass (dB):';
                            app.IRR_Option1.Visible = 'Off';
                            app.IRR_Option1_2.Visible = 'On';
                            app.IRR_Option2.Visible = 'On';
                            app.IRR_Option2_2.Visible = 'Off';
                        case 'Bandstop'
                            app.optionsLabel.Text = 'Apass (dB):';
                            app.optionsLabel2.Text = 'Astop (dB):';
                            app.IRR_Option1.Visible = 'On';
                            app.IRR_Option1_2.Visible = 'Off';
                            app.IRR_Option2.Visible = 'Off';
                            app.IRR_Option2_2.Visible = 'On';
                    end
            end
        end
        
        function CalculateNumberSensors(app)
            val = app.SelectFramesButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    Str = app.selectedFramesEditField.Value;
                    Str(strfind(Str, ',')) = ' ';
                    Frames = sscanf(Str,'%u')';
                    if isempty(Frames)
                        disp('Pleas select some Frames')
                    end
                case 'Array'
                    Frames  = app.firstEditField_2.Value:app.intervalEditField_2.Value: app.lastEditField_2.Value;
            end
            if isempty(Frames)
                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                return;
            end
            % select sensors
            val = app.SelectSensorsButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    Str = app.selectedTransducersEditField.Value;
                    Str(strfind(Str, ',')) = ' ';
                    Sensors = sscanf(Str,'%u')';
                    if isempty(Sensors)
                        disp('Pleas select some detectors')
                    end
                case 'Array'
                    Sensors  = app.firstEditField.Value:app.intervalEditField.Value: app.lastEditField.Value;
            end
            if isempty(Sensors)
                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                return;
            end
            app.NumberofavailabledetectorsEditField.Value = length(Sensors)*length(Frames);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PAT_ReconstructionInterface.Name = 'PATLab';
            addpath(genpath('../dependencies'));
            app.numberofpixelsxEditField.Value = round(app.xsizemmSpinner.Value/app.pixelsizexmmSpinner.Value);
            app.numberofpixelsyEditField.Value = round(app.ysizemmSpinner.Value/app.pixelsizeymmSpinner.Value);
            app.numberofpixelszEditField.Value = round(app.zsizemmSpinner.Value/app.pixelsizezmmSpinner.Value);
            SoSValueChanged(app);
            CalculateNumberSensors(app);
        end

        % Button pushed function: LoadTimeSeriesDataButton
        function LoadTimeSeriesDataButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Loading raw data','Indeterminate','on');
            [FileName,PathName] = uigetfile({'*.h5; *.mat'}, 'Select a file');
            if isequal(FileName,0)
                disp('User selected Cancel');
            else
                app.selpathData = PathName;
                app.PAT_ReconstructionInterface.Visible = 'Off';
                app.PAT_ReconstructionInterface.Visible = 'On';
                [~,~,ext] = fileparts(FileName);
                if strcmp(ext, '.h5')
                    imData = h5read(fullfile(PathName,FileName),'/dataset1');
                elseif strcmp(ext, '.mat')
                    x = load(fullfile(PathName,FileName));
                    value = struct2cell(x);
                    imData = value{1};
                end
                app.ImData_input = imData;
                app.AssignButton.BackgroundColor = [0.80,0.80,0.80];
                app.size1EditField.Value = size(imData,1);
                app.size2EditField.Value = size(imData,2);
                app.size3EditField.Value = size(imData,3);
                format longG
                t = now;
                dateVal = datetime(t,'ConvertFrom','datenum');
                cellArrayText{1} = sprintf('%s\n%s','File Full path Name: ', fullfile(PathName,FileName));
                cellArrayText{2} = sprintf('%s','Date and Time: ', dateVal);
                app.PropertiesTextArea.Value = cellArrayText;
                app.LoadTimeSeriesDataButton.BackgroundColor = [0.39,0.83,0.07];
                delete(d);
            end
        end

        % Button pushed function: AssignButton_2
        function AssignButton_2Pushed(app, event)
            % Change the sensorsPositions_input coorfinates to
            % [sensors, coordinates(2 or 3), Frames]
            val1 = app.DropDown_5.Value;
            val2 = app.DropDown_4.Value;
            val3 = app.DropDown_6.Value;
            Loc = app.sensorsPositions_input;
            if isempty(Loc)
                warndlg('Load data first', 'Warning')
                app.AssignButton_2.BackgroundColor = 'r';
            elseif strcmp(val1, val2)||strcmp(val1, val3)||strcmp(val2, val3)
                warndlg('The three dimensions DropDowns should be different', 'Warning')
                app.AssignButton_2.BackgroundColor = 'r';
            else
                if strcmp(val1, 'Detectors')&&strcmp(val2, 'Coordinates')&&strcmp(val3, 'Frames')
                    app.sensorsPositions = [];
                    app.sensorsPositions = Loc;
                elseif strcmp(val1, 'Detectors')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Coordinates')
                    app.sensorsPositions = [];
                    app.sensorsPositions = permute(Loc, [1, 3, 2]);
                elseif strcmp(val1, 'Coordinates')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Frames')
                    app.sensorsPositions = [];
                    app.sensorsPositions = permute(Loc, [2, 1, 3]);
                elseif strcmp(val1, 'Coordinates')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Detectors')
                    app.sensorsPositions = [];
                    app.sensorsPositions = permute(Loc, [2, 3, 1]);
                elseif strcmp(val1, 'Frames')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Coordinates')
                    app.sensorsPositions = [];
                    app.sensorsPositions = permute(Loc, [3, 1, 2]);
                elseif strcmp(val1, 'Frames')&&strcmp(val2, 'Coordinates')&&strcmp(val3, 'Detectors')
                    app.sensorsPositions = [];
                    app.sensorsPositions = permute(Loc, [3, 2, 1]);
                end
                app.AssignButton_2.BackgroundColor = [0.39,0.83,0.07];
                app.lastEditField_3.Value = size(app.sensorsPositions,1);
                app.lastEditField_4.Value = size(app.sensorsPositions,3);
            end
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            %% Assign the input dada dimenssions to the Standard dimenssions
            % the standard dimenssion throughout this software is:[Frames, Samples, Sensors]
            val1 = app.DropDown_2.Value;
            val2 = app.DropDown.Value;
            val3 = app.DropDown_3.Value;
            imData = app.ImData_input;
            
            if isempty(imData)
                warndlg('Load data first', 'Warning')
                app.AssignButton.BackgroundColor = 'r';
            elseif strcmp(val1, val2)||strcmp(val1, val3)||strcmp(val2, val3)
                warndlg('The three assigned dimensions for the DropDowns should be different', 'Warning')
                app.AssignButton.BackgroundColor = 'r';
            else
                if strcmp(val1, 'Frames')&&strcmp(val2, 'Data Samples')&&strcmp(val3, 'Detectors')
                    app.ImData = [];
                    app.ImData = imData;
                elseif strcmp(val1, 'Frames')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Data Samples')
                    app.ImData = [];
                    app.ImData = permute(imData, [1, 3, 2]);
                elseif strcmp(val1, 'Data Samples')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Detectors')
                    app.ImData = [];
                    app.ImData = permute(imData, [2, 1, 3]);
                elseif strcmp(val1, 'Data Samples')&&strcmp(val2, 'Detectors')&&strcmp(val3, 'Frames')
                    app.ImData = [];
                    app.ImData = permute(imData, [3, 1, 2]);
                elseif strcmp(val1, 'Detectors')&&strcmp(val2, 'Data Samples')&&strcmp(val3, 'Frames')
                    app.ImData = [];
                    app.ImData = permute(imData, [3, 2, 1]);
                elseif strcmp(val1, 'Detectors')&&strcmp(val2, 'Frames')&&strcmp(val3, 'Data Samples')
                    app.ImData = [];
                    app.ImData = permute(imData, [2, 3, 1]);
                end
                app.AssignButton.BackgroundColor = [0.39,0.83,0.07];
                %                 app.lastEditField_MF.Value = size(app.ImData,1);
                app.lastEditField_4.Value = size(app.ImData,1);
                app.lastEditField_2.Value = size(app.ImData,1);
                app.lastEditField.Value = size(app.ImData,3);
                app.lastEditField_3.Value = size(app.ImData,3);
                %                 app.lastEditField_2_MF.Value = size(app.ImData,3);
                app.SamplesToEditField.Value = size(app.ImData,2);
                app.SamplesToEditField_2.Value = size(app.ImData,2);
                
            end
        end

        % Button pushed function: LoadDetectorLocationsButton
        function LoadDetectorLocationsButtonPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Loading detector locations file','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_ReconstructionInterface.Visible = 'Off';
                app.PAT_ReconstructionInterface.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    Loc = value{1};
                elseif strcmp(ext, '.txt')
                    Loc =  load(fullfile(PathName,filename));
                end
                
                app.sensorsPositions_input = Loc;
                app.size1EditField_2.Value = size(Loc,1);
                app.size2EditField_2.Value = size(Loc,2);
                app.size3EditField_2.Value = size(Loc,3);
                
                app.AssignButton_2.BackgroundColor = [0.80,0.80,0.80];
                app.DesignDetectorLocationsButton.BackgroundColor = [0.80,0.80,0.80];
                app.LoadDetectorLocationsButton.BackgroundColor = [0.39,0.83,0.07];
                format longG
                t = now;
                dateVal = datetime(t,'ConvertFrom','datenum');
                cellArrayText{1} = sprintf('%s\n%s','File Full path Name: ', fullfile(PathName,filename));
                cellArrayText{2} = sprintf('%s','Date and Time: ', dateVal);
                app.PropertiesTextArea_2.Value = cellArrayText;
                close(d);
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Error');
                return;
            end
        end

        % Button pushed function: DataCleaningButton
        function DataCleaningButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Data Cleaning','Indeterminate','on');
            val = app.InputData_Filter.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.CleanedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Normalized Data'
                    if isempty(app.NormalizedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                        return;
                    end
                    inputData = app.NormalizedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
            %             AverageFluence = max(mean(inputData(:,:,app.SelectedChannelSpinner.Value)));
            %             ShotPower = max(inputData(:,:,app.SelectedChannelSpinner.Value),[], 2);
            %             ShotRatio = repmat(AverageFluence,size(inputData, 1), 1)./ShotPower;
            %% Data Cleaning
            d.Title = 'Remove bad detectors';
            Str = app.DetectorstoremoveEditField.Value;
            Str(strfind(Str, ',')) = ' ';
            badSensor = sscanf(Str,'%u')';
            inputData(:,:,badSensor) = [];                 %Remove bad sensors
            d.Title = 'Remove bad Frames';
            Str = app.FramestoremoveEditField.Value;
            Str(strfind(Str, ',')) = ' ';
            badFrames = sscanf(Str,'%u')';
            inputData(badFrames,:,:) = [];                  %Remove selected Frames
            d.Title = 'Threshold entire time series data';
            thresh = app.ThresholdlevelforentiredataamplitudeEditField.Value;
            if thresh ~=0                                  %Thresholding the signals- Thresh = 0 means no thresholding
                inputData(abs(inputData)<thresh) = 0;
            end
            d.Title = 'Threshold beginning data samples';
            threshX = app.ofdatasamplesatthebeginningtozeroEditField.Value;
            if threshX ~=0
                inputData(:, 1:threshX, :) = 0;            %Set the first threshX samples to zero
            end
            d.Title = 'Threshold end data samples';
            threshX = app.ofdatasamplesattheendtozeroEditField.Value;
            if threshX ~=0
                inputData(:, end-threshX+1:end, :) = 0;            %Set the last threshX samples to zero
            end
            d.Title = 'Remove beginning data samples';
            threshX = app.ofdatasamplesatthebeginningtocutoutEditField.Value;
            if threshX ~=0
                inputData(:, 1:threshX, :) = [];            %Remove the first threshX samples
            end
            d.Title = 'Remove end data samples';
            threshX = app.ofdatasamplesattheendtocutoutEditField.Value;
            if threshX ~=0
                inputData(:, end-threshX+1:end, :) = [];            %Remove the last threshX samples
            end
            app.CleanedData = inputData;
            app.DataCleaningButton.BackgroundColor = [0.39,0.83,0.07];
            delete(d);
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Reconstruction...','Cancelable',"on",'Indeterminate','on');
            vl = app.SlicingButtonGroup.SelectedObject; %Slicing senario: xyz, yzx, zxy
            
            %%%%% image area %%%%%%%%%%%%%%
            % all in mm
            x_size = app.xsizemmSpinner.Value;
            y_size = app.ysizemmSpinner.Value;
            z_size = app.zsizemmSpinner.Value;
            resolutionFactorX = 1/app.pixelsizexmmSpinner.Value;
            resolutionFactorY = 1/app.pixelsizeymmSpinner.Value;
            resolutionFactorZ = 1/app.pixelsizezmmSpinner.Value;
            Npixel_x = round(x_size*resolutionFactorX);
            Npixel_y = round(y_size*resolutionFactorY);
            Npixel_z = round(z_size*resolutionFactorZ);
            app.numberofpixelsxEditField.Value = Npixel_x;
            app.numberofpixelsyEditField.Value = Npixel_y;
            app.numberofpixelszEditField.Value = Npixel_z;
            center_x = app.centerxmmSpinner.Value;
            center_y = app.centerymmSpinner.Value;
            center_z = app.centerzmmSpinner.Value;
            x_range = ((1:Npixel_x)-(Npixel_x+1)/2)*x_size/(Npixel_x)+center_x;       % x axis: low to high
            y_range = ((Npixel_y:-1:1)-(Npixel_y+1)/2)*y_size/(Npixel_y)+center_y;    % y axis: high to low
            z_range = ((1:Npixel_z)-(Npixel_z+1)/2)*z_size/(Npixel_z)+center_z;       % z axis: low to high
            if isempty(x_range); x_range = center_x; end
            if isempty(y_range); y_range = center_y; end
            if isempty(z_range); z_range = center_z; end
%             dt = 1/(app.SamplingrateMHzEditField.Value*1e6);
            fs = app.SamplingrateMHzEditField.Value*1e6; % sampling rate (Hz)
            sosVal = app.SoS.Value*1000;% SoS (mm per second)
            %% Select Data
            val_1 = app.InputdataDropDown.Value;
            switch val_1
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.CleanedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Normalized Data'
                    if isempty(app.NormalizedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                        return;
                    end
                    inputData = app.NormalizedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
            val_2 = app.DatatypeDropDown.Value;
            switch val_2
                case 'Original Data'
                    %Do nothing!
                case 'Inverse'
                    inputData = -1*inputData;
                case 'Envelope'
                    inputData = abs(hilbert(inputData));
                case 'Positive'
                    inputData = (inputData>=0).*inputData + (inputData<0).*0;
                case 'Negative'
                    inputData = (inputData<=0).*inputData + (inputData>0).*0;
                case 'Absolute Value'
                    inputData = abs(inputData);
                case 'Complex-Hilbert'
                    inputData = hilbert(inputData);
            end
            % select Frames
            val = app.SelectFramesButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    Str = app.selectedFramesEditField.Value;
                    Str(strfind(Str, ',')) = ' ';
                    Frames = sscanf(Str,'%u')';
                    if isempty(Frames)
                        disp('Pleas select some Frames')
                    end
                case 'Array'
                    Frames  = app.firstEditField_2.Value:app.intervalEditField_2.Value: app.lastEditField_2.Value;
            end
            if isempty(Frames)
                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                return;
            end
            % select sensors
            val = app.SelectSensorsButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    Str = app.selectedTransducersEditField.Value;
                    Str(strfind(Str, ',')) = ' ';
                    Sensors = sscanf(Str,'%u')';
                    if isempty(Sensors)
                        disp('Pleas select some detectors')
                    end
                case 'Array'
                    Sensors  = app.firstEditField.Value:app.intervalEditField.Value: app.lastEditField.Value;
            end
            if isempty(Sensors)
                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                return;
            end
            % Selected Samples
            Samples = app.firstEditField_6.Value:app.intervalEditField_6.Value:app.SamplesToEditField_2.Value;
            if isempty(Samples)
                uialert(app.PAT_ReconstructionInterface, 'Data Samples field should not be left blank!', 'Error');
                return;
            end
            if length(Samples)>size(inputData,2)
                uialert(app.PAT_ReconstructionInterface, 'Data Samples field must be equal to or less than the number of samples in the input data!', 'Error');
                return;
            end
            if length(Frames)>size(inputData,1)
                uialert(app.PAT_ReconstructionInterface, 'Frames field must be equal to or less than the number of Frames in the input data!', 'Error');
                return;
            end
            if length(Sensors)>size(inputData,3)
                uialert(app.PAT_ReconstructionInterface, 'Detectors field must be equal to or less than the number of detectors in the input data!', 'Error');
                return;
            end
            selectedData = inputData(Frames, Samples, Sensors); %selected data
            if isnan(selectedData)
                selectedData(isnan(selectedData)) = 0;
                uialert(app.PAT_ReconstructionInterface, 'In the signal input, NaN samples have been found and forced to zero', 'Warning');
            end
            %% Prepare Arguments for the Reconstruction functions
            P = struct('inputData', selectedData);
            P.slicing   = vl.Text;
            P.x_range   = x_range;
            P.y_range   = y_range;
            P.z_range   = z_range;
            P.fs        = fs;
            P.V_M       = sosVal;
            P.delay           = app.DelaysEditField.Value;
            %% Reconstruction method selection
            valRec = app.ReconstructionMethodDropDown.Value;
            if ~strcmp(valRec,'Fourier Transform (FT)')
                inputLoc = app.sensorsPositions;                   %selected sensors positions
                inputLoc = inputLoc(Sensors,:,Frames);
                P.DerivativesGain = app.DerivativesEditField.Value;
                P.inputLoc  = inputLoc;
                P.coherentFactor  = app.CoherentfactorCheckBox.Value;
                %P.minimumVariance = app.MinimumVarianceCheckBox.Value;
                P.directivityPower  = app.PowerEditField.Value;
                P.directivityAngle  = app.FullangleofacceptancedegreeEditField.Value;
                P.directivityExample= [app.SelectFrameDirectivityExample.Value app.SelectDetectorDirectivityExample.Value];
                %% Directivity method selection
                P.directivityMethpod = 0;
                if app.DirectivityCheckBox.Value %directivity
                    val_3 = app.MethodButtonGroup.SelectedObject;
                    val_3 = val_3.Text;
                    switch val_3
                        case 'Center of the detectors locations for every frame'
                            P.directivityMethpod = 1;
                        case 'Global focal point'
                            P.directivityMethpod = 2;
                            P.dir = [app.xEditField.Value, app.yEditField.Value,app.zEditField.Value];
                        case 'Global unit vector'
                            P.directivityMethpod = 3;
                            P.dir = [app.xEditField_2.Value, app.yEditField_2.Value,app.zEditField_2.Value];
                        case 'Load focal points'
                            if isempty(app.dirMat)
                                uialert(app.PAT_ReconstructionInterface, 'The directivity must be loaded or a different option must be selected.', 'Error');
                                return;
                            end
                            P.directivityMethpod = 4;
                            P.dir = app.dirMat(Sensors,:,Frames);
                        case 'Load unit vectors'
                            if isempty(app.dirMat)
                                uialert(app.PAT_ReconstructionInterface, 'The directivity must be loaded or a different option must be selected.', 'Error');
                                return;
                            end
                            P.directivityMethpod = 5;
                            P.dir = app.dirMat(Sensors,:,Frames);
                    end
                end
            end
            switch valRec
                case 'Delay and Sum (DAS)'
                    P.recMethod = 1;
                    pa_img = PAT_Reconstruction_3D(P);
                case 'Filtered Back Projection'
                    P.recMethod =2;
                    pa_img = PAT_Reconstruction_3D(P);
                case 'Universal Back Projection (UBP)'
                    P.recMethod = 3;
                    pa_img = PAT_Reconstruction_3D(P);
                case 'Fourier Transform (FT)'
                    selectedButton = app.ConfigurationButtonGroup.SelectedObject;
                    if strcmp(selectedButton.Text, 'Linear array')
                        P.configuration = "line";
                        P.numSensors = app.NumberofavailabledetectorsEditField.Value;
                        P.sensorPitch = app.field2_FT.Value;
                    else
                        P.configuration = "plane";
                        P.numSensors = app.field5_FT.Value; % in length direction
                        P.numSensorsWidth = app.field6_FT.Value;
                        if P.numSensors*P.numSensorsWidth~=app.NumberofavailabledetectorsEditField.Value
                            uialert(app.PAT_ReconstructionInterface, 'Multiplication of the number of sensors in length and width must be equal to all the available sensors', 'Error');
                            return;
                        end
                        P.sensorPitch = app.field2_FT.Value; % in length direction
                        P.sensorPitchWidth = app.field4_FT.Value; 
                    end
                    
                    P.interpolation = app.InterpolationCheckBox.Value;
                    P.interp = app.interpMethod.Value;
                    pa_img = PAT_Reconstruction_FT_3D(P);
                case 'Time Reversal (TR)'
                    SL(1,:)= 1e-3.*(inputLoc(:,2)'-app.centerymmSpinner.Value);
                    SL(2,:)= 1e-3.*(inputLoc(:,1)'-app.centerxmmSpinner.Value);
                    P.inputLoc  = SL;
                    pa_img = PAT_Reconstruction_TR_3D(P);
            end
            X = {'no','yes'};
            x = X(1+app.DirectivityCheckBox.Value);
            formatOut = 'dd_mmm_yyyy';
            tNow = datestr(now,formatOut);
            app.title = append('Method: ',valRec, ', Input Data: ',val_1,', Data Type: ',val_2,', Directivity: ',x, ', ', tNow);
            app.PA_Image = pa_img;
            PlotGUI_2(app,pa_img,app.title);
            close(d)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Plotting Data ...','Cancelable',"on");
                val1 = app.OptionsDropDown.Value;
                switch val1
                    case {'Time Series', 'Sinogram'}
                        valData=app.OptionsDropDownData.Value;
                        switch valData
                            case 'Raw Data'
                                inputData = app.ImData;
                            case 'Cleaned Data'
                                inputData = app.CleanedData;
                            case 'Normalized Data'
                                inputData = app.NormalizedData;
                            case 'Filtered Data'
                                inputData = app.FilteredData;
                            case 'Downsampled Data'
                                inputData = app.DownsampledData;
                            case 'Matched Filtered Data'
                                inputData = app.MatchedFilter;
                        end
                        valType=app.OptionsDropDownType.Value;
                        switch valType
                            case 'Original Data'
                                % do nothing!
                            case 'Inverse'
                                inputData = -1*inputData;
                            case 'Envelope'
                                inputData = abs(hilbert(inputData));
                            case 'Positive'
                                inputData = (inputData>=0).*inputData + (inputData<0).*0;
                            case 'Negative'
                                inputData = (inputData<=0).*inputData + (inputData>0).*0;
                            case 'Absolute Value'
                                inputData = abs(inputData);
                            case 'Complex-Hilbert'
                                inputData = hilbert(inputData);
                        end
                        fineName =  append(val1,', ',valData,', ',valType);
                    case 'Detector Locations'
                        inputData = app.sensorsPositions;
                        fineName =  val1;
                    case 'Reconstructed Image'
                        inputData = app.PA_Image;
                        if isempty(inputData)
                            uialert(app.PAT_ReconstructionInterface, 'No reconstructed image is available!', 'Error');
                            return;
                        end
                        %                     imdisp(inputData)
                        PlotGUI_2(app,inputData, app.title);
                end
                if strcmp(val1,'Time Series')||strcmp(val1,'Sinogram')||strcmp(val1,'Detector Locations')
                    % Selected Time Series
                    Samples = app.firstEditField_5.Value:app.intervalEditField_5.Value:app.SamplesToEditField.Value;
                    
                    %% select Frames
                    val2 = app.SelectFramesButtonGroup.SelectedObject;
                    switch val2.Text
                        case 'Selected'
                            Str = app.selectedFramesEditField_2.Value;
                            Str(strfind(Str, ',')) = ' ';
                            Frames = sscanf(Str,'%u')';
                        case 'Array'
                            Frames  = app.firstEditField_4.Value:app.intervalEditField_4.Value: app.lastEditField_4.Value;
                    end
                    %% select Detectors
                    val2 = app.SelectDetectorsButtonGroup.SelectedObject;
                    switch val2.Text
                        case 'Selected'
                            Str = app.selectedTransducersEditField_2.Value;
                            Str(strfind(Str, ',')) = ' ';
                            Transducers = sscanf(Str,'%u')';
                        case 'Array'
                            Transducers  = app.firstEditField_3.Value:app.intervalEditField_3.Value: app.lastEditField_3.Value;
                    end
                    switch val1
                        case 'Time Series'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Samples)
                                uialert(app.PAT_ReconstructionInterface, 'Data Samples field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            SelectedData = inputData(Frames, Samples, Transducers);
                            
                            figure("Name",'Time Series');
                            for i = 1:length(Transducers)
                                d.Value = i/length(Transducers);      % Progress bar
                                Msg = 'Detector Number: %d ';
                                d.Message = sprintf(Msg,i);
                                for j = 1:length(Frames)
                                    plot(SelectedData(j, :, i))
                                    hold on
                                end
                            end
                            title(fineName);
                            xlabel('Data samples')
                            ylabel('Amplitude')
                        case 'Sinogram'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Samples)
                                uialert(app.PAT_ReconstructionInterface, 'Data Samples field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            SelectedData = inputData(Frames, Samples, Transducers);
                            
                            tempMat = [];
                            for i = 1: size(SelectedData,1)
                                tempMat = cat(2, tempMat, reshape(SelectedData(i,:,:), size(SelectedData,2), []));
                            end
                            figure("Name",'Sinogram');
                            colormap(bone);
                            imagesc(sqrt(abs(hilbert(tempMat))))
                            colormap(bone)
                            title(fineName)
                            xlabel('Channel index')
                            ylabel('Data samples')
                            drawnow;
                        case 'Detector Locations'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            figure("Name",'Detector Locations');
                            if size(inputData,2)==3
                                for i = Frames
                                    scatter3(inputData(Transducers,1, i),inputData(Transducers,2, i),inputData(Transducers,3, i),'Filled','MarkerFaceColor',rand(1,3));
                                    hold on
                                end
                                zlabel('Z axis (mm)', 'FontSize', 12);
                            elseif size(inputData,2)==2
                                scatter(inputData(Transducers,1), inputData(Transducers,2))
                            end
                            title(fineName, 'FontSize', 20);
                            xlabel('X axis (mm)', 'FontSize', 12);
                            ylabel('Y axis (mm)', 'FontSize', 12);
                            set(gca,'FontSize',12)
                            axis equal
                    end
                end
                close(d)
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Error');
                return;
            end
        end

        % Selection changed function: SelectFramesButtonGroup
        function SelectFramesButtonGroupSelectionChanged(app, event)
            val = app.SelectFramesButtonGroup.SelectedObject;
            switch val.Text
                case 'Selected'
                    app.selectedFramesEditField_2.Enable = 'On';
                    app.firstEditField_4.Enable = 'Off';
                    app.intervalEditField_4.Enable = 'Off';
                    app.lastEditField_4.Enable = 'Off';
                case 'Array'
                    app.selectedFramesEditField_2.Enable = 'Off';
                    app.firstEditField_4.Enable = 'On';
                    app.intervalEditField_4.Enable = 'On';
                    app.lastEditField_4.Enable = 'On';
            end
        end

        % Selection changed function: SelectDetectorsButtonGroup
        function SelectDetectorsButtonGroupSelectionChanged(app, event)
            val = app.SelectDetectorsButtonGroup.SelectedObject;
            switch val.Text
                case 'Selected'
                    app.selectedTransducersEditField_2.Enable = 'On';
                    app.firstEditField_3.Enable = 'Off';
                    app.intervalEditField_3.Enable = 'Off';
                    app.lastEditField_3.Enable = 'Off';
                case 'Array'
                    app.selectedTransducersEditField_2.Enable = 'Off';
                    app.firstEditField_3.Enable = 'On';
                    app.intervalEditField_3.Enable = 'On';
                    app.lastEditField_3.Enable = 'On';
            end
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            val = app.InterpolationCheckBox.Value;
            if val
                app.firstEditField_5.Enable = 'Off';
                app.SamplesToEditField.Enable = 'Off';
                app.intervalEditField_5.Enable = 'Off';
            else
                app.firstEditField_5.Enable = 'On';
                app.SamplesToEditField.Enable = 'On';
                app.intervalEditField_5.Enable = 'On';
            end
        end

        % Selection changed function: SelectSensorsButtonGroup_Rec
        function SelectSensorsButtonGroup_RecSelectionChanged(app, event)
            val = app.SelectSensorsButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    app.selectedTransducersEditField.Enable = 'On';
                    app.firstEditField.Enable = 'Off';
                    app.intervalEditField.Enable = 'Off';
                    app.lastEditField.Enable = 'Off';
                case 'Array'
                    app.selectedTransducersEditField.Enable = 'Off';
                    app.firstEditField.Enable = 'On';
                    app.intervalEditField.Enable = 'On';
                    app.lastEditField.Enable = 'On';
            end
            CalculateNumberSensors(app);
        end

        % Selection changed function: SelectFramesButtonGroup_Rec
        function SelectFramesButtonGroup_RecSelectionChanged(app, event)
            val = app.SelectFramesButtonGroup_Rec.SelectedObject;
            switch val.Text
                case 'Selected'
                    app.selectedFramesEditField.Enable = 'On';
                    app.firstEditField_2.Enable = 'Off';
                    app.intervalEditField_2.Enable = 'Off';
                    app.lastEditField_2.Enable = 'Off';
                case 'Array'
                    app.selectedFramesEditField.Enable = 'Off';
                    app.firstEditField_2.Enable = 'On';
                    app.intervalEditField_2.Enable = 'On';
                    app.lastEditField_2.Enable = 'On';
            end
            CalculateNumberSensors(app);
        end

        % Value changed function: MethodDropDown, MethodType_1, 
        % MethodType_2, MethodType_3, MethodType_4
        function MethodDropDownValueChanged(app, event)
            val = app.MethodDropDown.Value;
            switch val
                case 'Z-score'
                    app.MethodType_1.Visible = 'On';
                    app.MethodType_2.Visible = 'Off';
                    app.MethodType_3.Visible = 'Off';
                    app.MethodType_4.Visible = 'Off';
                    app.labelN_1.Visible = 'Off';
                    app.labelN_2.Visible = 'Off';
                    app.valueN_1.Visible = 'Off';
                    app.valueN_2.Visible = 'Off';

                case 'Norm'
                    app.MethodType_1.Visible = 'Off';
                    app.MethodType_2.Visible = 'On';
                    app.MethodType_3.Visible = 'Off';
                    app.MethodType_4.Visible = 'Off';
                    val2 = app.MethodType_2.Value;
                    switch val2
                        case 'Positive numeric scalar'
                            app.labelN_1.Text = 'norm:';
                            app.labelN_1.Visible = 'On';
                            app.labelN_2.Visible = 'Off';
                            app.valueN_1.Visible = 'On';
                            app.valueN_2.Visible = 'Off';
                        case 'Infinity norm'
                            app.labelN_1.Visible = 'Off';
                            app.labelN_2.Visible = 'Off';
                            app.valueN_1.Visible = 'Off';
                            app.valueN_2.Visible = 'Off';
                    end

                case 'Scale'
                    app.MethodType_1.Visible = 'Off';
                    app.MethodType_2.Visible = 'Off';
                    app.MethodType_3.Visible = 'On';
                    app.MethodType_4.Visible = 'Off';
                    val2 = app.MethodType_3.Value;
                    if strcmp(val2, 'Numeric values')
                        app.labelN_1.Text = 'detector:';
                        app.labelN_2.Text = 'frame:';
                        app.labelN_1.Visible = 'On';
                        app.labelN_2.Visible = 'On';
                        app.valueN_1.Visible = 'On';
                        app.valueN_2.Visible = 'On';
                    else
                        app.labelN_1.Visible = 'Off';
                        app.labelN_2.Visible = 'Off';
                        app.valueN_1.Visible = 'Off';
                        app.valueN_2.Visible = 'Off';
                    end
                    
                case 'Range'
                    app.MethodType_1.Visible = 'Off';
                    app.MethodType_2.Visible = 'Off';
                    app.MethodType_3.Visible = 'Off';
                    app.MethodType_4.Visible = 'Off';
                    app.labelN_1.Text = 'a:';
                    app.labelN_2.Text = 'b:';
                    app.labelN_1.Visible = 'On';
                    app.labelN_2.Visible = 'On';
                    app.valueN_1.Visible = 'On';
                    app.valueN_2.Visible = 'On';
                case 'Center'
                    app.MethodType_1.Visible = 'Off';
                    app.MethodType_2.Visible = 'Off';
                    app.MethodType_3.Visible = 'Off';
                    app.MethodType_4.Visible = 'On';
                    val2 = app.MethodType_4.Value;
                    switch val2
                        case {'Mean', 'Median'}
                            app.labelN_1.Visible = 'Off';
                            app.labelN_2.Visible = 'Off';
                            app.valueN_1.Visible = 'Off';
                            app.valueN_2.Visible = 'Off';
                        case 'Numeric array'
                            app.labelN_1.Text = 'detector:';
                            app.labelN_2.Text = 'frame:';
                            app.labelN_1.Visible = 'On';
                            app.labelN_2.Visible = 'On';
                            app.valueN_1.Visible = 'On';
                            app.valueN_2.Visible = 'On';
                    end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Plotting Data ...','Cancelable',"on");
                val1 = app.OptionsDropDown.Value;
                switch val1
                    case {'Time Series', 'Sinogram'}
                        valData=app.OptionsDropDownData.Value;
                        switch valData
                            case 'Raw Data'
                                inputData = app.ImData;
                            case 'Cleaned Data'
                                inputData = app.CleanedData;
                            case 'Normalized Data'
                                inputData = app.NormalizedData;
                            case 'Filtered Data'
                                inputData = app.FilteredData;
                            case 'Downsampled Data'
                                inputData = app.DownsampledData;
                            case 'Matched Filtered Data'
                                %                             inputData = app.CleanedData;
                        end
                        valType=app.OptionsDropDownType.Value;
                        switch valType
                            case 'Original Data'
                                % do nothing!
                            case 'Inverse'
                                inputData = -1*inputData;
                            case 'Envelope'
                                inputData = abs(hilbert(inputData));
                            case 'Positive'
                                inputData = (inputData>=0).*inputData + (inputData<0).*0;
                            case 'Negative'
                                inputData = (inputData<=0).*inputData + (inputData>0).*0;
                            case 'Absolute Value'
                                inputData = abs(inputData);
                            case 'Complex-Hilbert'
                                inputData = hilbert(inputData);
                        end
%                         if strcmp(val1, 'Time Series'); val1 = 'TimeSeries'; end
                        fineName =  append(val1,valData,valType);
                    case 'Detector Locations'
                        inputData = app.sensorsPositions;
                        fineName =  val1;
                    case 'Reconstructed Image'
                        SelectedData = app.PA_Image;
                        if isempty(SelectedData)
                            uialert(app.PAT_ReconstructionInterface, 'No reconstructed image is available!', 'Error');
                            return;
                        end
                        %                         fineName =  append(val1, string(app.title));
                        fineName =  val1;
                        formatOut = 'dd_mmm_yyyy';
                        tNow = datestr(now,formatOut);
                        if strcmp(app.Switch.Value, 'mat')
                            S.(fineName(~isspace(fineName))) = SelectedData;
                            save (fullfile(cd, append(fineName(~isspace(fineName)), tNow,'.mat')), '-struct', 'S', '-v7.3', '-nocompression')
                        else
                            nii = make_nii(SelectedData);
                            save_nii(nii, fullfile(cd, append(fineName(~isspace(fineName)), tNow,'.nii')))
                        end
                        message = sprintf('The file has been successfully saved to the directory: %s', cd);
                        uialert(app.PAT_ReconstructionInterface,message,'Save', 'Icon','success');
                        close(d)
                end
                if strcmp(val1,'Time Series')||strcmp(val1,'Sinogram')||strcmp(val1,'Detector Locations')
                    % Selected Time Series
                    Samples = app.firstEditField_5.Value:app.intervalEditField_5.Value:app.SamplesToEditField.Value;
                    %% select Frames
                    val2 = app.SelectFramesButtonGroup.SelectedObject;
                    switch val2.Text
                        case 'Selected'
                            Str = app.selectedFramesEditField_2.Value;
                            Str(strfind(Str, ',')) = ' ';
                            Frames = sscanf(Str,'%u')';
                        case 'Array'
                            Frames  = app.firstEditField_4.Value:app.intervalEditField_4.Value: app.lastEditField_4.Value;
                    end
                    %% select Detectors
                    val2 = app.SelectDetectorsButtonGroup.SelectedObject;
                    switch val2.Text
                        case 'Selected'
                            Str = app.selectedTransducersEditField_2.Value;
                            Str(strfind(Str, ',')) = ' ';
                            Transducers = sscanf(Str,'%u')';
                        case 'Array'
                            Transducers  = app.firstEditField_3.Value:app.intervalEditField_3.Value: app.lastEditField_3.Value;
                    end
                    
                    switch val1
                        case 'Time Series'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Samples)
                                uialert(app.PAT_ReconstructionInterface, 'Data Samples field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            SelectedData = inputData(Frames, Samples, Transducers);
                        case 'Sinogram'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Samples)
                                uialert(app.PAT_ReconstructionInterface, 'Data Samples field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            SelectedData = inputData(Frames, Samples, Transducers);
                            tempMat = [];
                            for i = 1: size(SelectedData,1)
                                tempMat = cat(2, tempMat, reshape(SelectedData(i,:,:), size(SelectedData,2), []));
                            end
                            SelectedData = tempMat;
                        case 'Detector Locations'
                            if isempty(inputData)
                                uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                                return;
                            end
                            if isempty(Transducers)
                                uialert(app.PAT_ReconstructionInterface, 'Detectors field should not be left blank!', 'Error');
                                return;
                            end
                            if isempty(Frames)
                                uialert(app.PAT_ReconstructionInterface, 'Frames field should not be left blank!', 'Error');
                                return;
                            end
                            SelectedData = inputData(Transducers,:, Frames);
                    end
                end
                S.(fineName(~isspace(fineName))) = SelectedData;
                formatOut = 'dd_mmm_yyyy';
                tNow = datestr(now,formatOut);
                save (fullfile(cd, append(fineName(~isspace(fineName)), tNow,'.mat')), '-struct', 'S', '-v7.3', '-nocompression')
                message = sprintf('The file has been successfully saved to the directory: %s', cd);
                uialert(app.PAT_ReconstructionInterface,message,'Save', 'Icon','success');
                close(d)
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Error');
                return;
            end
        end

        % Value changed function: pixelsizexmmSpinner, 
        % pixelsizeymmSpinner, pixelsizezmmSpinner, xsizemmSpinner, 
        % ysizemmSpinner, zsizemmSpinner
        function ImagesizexmmSpinnerValueChanged(app, event)
            app.numberofpixelsxEditField.Value = round(app.xsizemmSpinner.Value/app.pixelsizexmmSpinner.Value);
            app.numberofpixelsyEditField.Value = round(app.ysizemmSpinner.Value/app.pixelsizeymmSpinner.Value);
            app.numberofpixelszEditField.Value = round(app.zsizemmSpinner.Value/app.pixelsizezmmSpinner.Value);
        end

        % Value changed function: ReconstructionMethodDropDown
        function ReconstructionMethodDropDownValueChanged(app, event)
            val = app.ReconstructionMethodDropDown.Value;
            switch val
                case {'Delay and Sum (DAS)', 'Filtered Back Projection'}
                    app.Panel_BP_options.Visible = 'On';
                    app.Panel_TR_options.Visible = 'Off';
                    app.Panel_FT_options.Visible = 'Off';
                    app.ComputationalGridPanel.Enable = 'On';
                    app.SlicingButtonGroup.Enable = 'On';
                    app.DerivativesgainLabel.Visible = 'Off';
                    app.DerivativesEditField.Visible = 'Off';
                case 'Universal Back Projection (UBP)'
                    app.Panel_BP_options.Visible = 'On';
                    app.Panel_TR_options.Visible = 'Off';
                    app.Panel_FT_options.Visible = 'Off';
                    app.ComputationalGridPanel.Enable = 'On';
                    app.SlicingButtonGroup.Enable = 'On';
                    app.DerivativesgainLabel.Visible = 'On';
                    app.DerivativesEditField.Visible = 'On';
                case 'Fourier Transform (FT)'
                    app.Panel_BP_options.Visible = 'Off';
                    app.Panel_TR_options.Visible = 'Off';
                    app.Panel_FT_options.Visible = 'On';
                    app.ComputationalGridPanel.Enable = 'Off';
                    app.SlicingButtonGroup.Enable = 'Off';
                case 'Time Reversal (TR)'
                    app.Panel_BP_options.Visible = 'Off';
                    app.Panel_TR_options.Visible = 'On';
                    app.Panel_FT_options.Visible = 'Off';
                    app.ComputationalGridPanel.Enable = 'On';
                    app.SlicingButtonGroup.Enable = 'On';
            end
        end

        % Button pushed function: MatchedFilteringButton
        function MatchedFilteringButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Plotting Data ...','Cancelable',"on");
            val = app.InputDataDropDown_MF.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Normalized Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                        return;
                    end
                    inputData = app.NormalizedData;
                case 'Filtered Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
            end
            val2 = app.Method_MF.Value;
            val3 = app.ButtonGroup_MF.SelectedObject;
            if strcmp(val2, 'Load a template')||strcmp(val3.Text, 'Use one template for all time series')
                tem = app.loadedTemplateMF;
            end
            outputData = zeros(size(inputData));
            iter = 0;
            for i = 1:size(inputData,1)
                iter =iter+1;
                parfor j = 1:size(inputData,3)
                    
                    pa_data_temp = reshape(inputData(i,:,j), [],1);
                switch val2
                    case 'Select a range of the data samples as template'
                        tem = flip(pa_data_temp(app.numberSamplesBeforeMax.Value:app.numberSamplesAfterMax.Value,1)); %template signal
                    case 'Select a part of the data samples containing the global maximum as template'
                        [~ , II] = max(pa_data_temp);
                        tem = flip(pa_data_temp((II-app.numberSamplesBeforeMax.Value):II+app.numberSamplesAfterMax.Value,1)); %template signal
                    case 'Load a 1D template (time domain)'
                        tem = flip(app.loadedTemplateMF);
                    case 'Load a 2D template (time domain)'
                        tem = flip(app.loadedTemplateMF(:,sensor));
                end
                    r = conv(pa_data_temp, tem, 'same');
                    level =app.LevelEditField.Value;
                maxVal = max(r);
                r(r<(maxVal*level))=0;
%                     outputData(i,:,j) = r;
                    rLocalMax = islocalmax(r);
                    r(~rLocalMax)=0;
                    outputData(i,:,j) =conv(r, flip(tem), 'same');
                    %                     tem   = (pa_data_temp((II-app.numberSamplesBeforeMax.Value):II+app.numberSamplesAfterMax.Value,1));
                    %                     filter = phased.MatchedFilter('Coefficients',tem);
                    %                     outputData(i,:,j) = real(filter(pa_data_temp));
                    %                     d.Value = iter/((size(inputData,1))*length(size(inputData,3)));      % Progress bar
                    
                end
                Msg = 'Iteration: %d ';
                    d.Message = sprintf(Msg,iter);
            end
            app.MatchedFilter = outputData;
            app.MatchedFilteringButton.BackgroundColor = [0.39,0.83,0.07];
            close (d)
        end

        % Callback function
        function SelectSensorsButtonGroup_MFSelectionChanged(app, event)
            val = app.SelectSensorsButtonGroup_MF.SelectedObject;
            switch val.Text
                case 'Selected'
                    app.selectedTransducersEditField_MF.Enable = 'On';
                    app.firstEditField_2_MF.Enable = 'Off';
                    app.intervalEditField_2_MF.Enable = 'Off';
                    app.lastEditField_2_MF.Enable = 'Off';
                case 'Array'
                    app.selectedTransducersEditField_MF.Enable = 'Off';
                    app.firstEditField_2_MF.Enable = 'On';
                    app.intervalEditField_2_MF.Enable = 'On';
                    app.lastEditField_2_MF.Enable = 'On';
            end
        end

        % Value changed function: DropDown, DropDown_2, DropDown_3
        function DropDown_2ValueChanged(app, event)
            app.AssignButton.BackgroundColor = [0.80,0.80,0.80];
        end

        % Value changed function: DropDown_4, DropDown_5, DropDown_6
        function DropDown_5ValueChanged(app, event)
            app.AssignButton_2.BackgroundColor = [0.80,0.80,0.80];
        end

        % Button pushed function: SimulateTimeSeriesDataButton
        function SimulateTimeSeriesDataButtonPushed(app, event)
            if isempty(app.sensorsPositions)
                uialert(app.PAT_ReconstructionInterface, 'Detector locations do not exist or are assigned!', 'Error');
            else
                app.SimulationDataApp = SimulatePAsignal(app,app.sensorsPositions);
                app.LoadTimeSeriesDataButton.BackgroundColor = [0.80,0.80,0.80];
                app.SimulateTimeSeriesDataButton.BackgroundColor = [0.39,0.83,0.07];
                app.AssignButton.BackgroundColor = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: DesignDetectorLocationsButton
        function DesignDetectorLocationsButtonPushed(app, event)
            %             app.DesignDetectorLocationsButton.Enable = 'Off';
            app.DialogApp = DesignTransducerConfiguration(app);
        end

        % Button pushed function: TestthefilterButton
        function TestthefilterButtonPushed(app, event)
            FilterTypr_FilterValueChanged(app);
            %% Design filter
            Fs = app.SignalSamplingRateMHzEditField.Value;
            FIR_order = app.filterOrder.Value;
            val1 = app.Method_Filter.Value;
            switch val1
                case 'FIR'
                    val2 = app.Method_FIR.Value;
                    switch val2
                        case 'Equiripple'
                            app.optionsLabel.Text = 'Density Factor:';
                            dens = app.densOption_Filter.Value;
                            b  = firpm(FIR_order, app.freqWin_Filter2, app.amp_Filter2, app.weight_Filter2, {dens});
                            app.filterDesigned = dfilt.dffir(b);
                        case 'Least-squares'
                            app.optionsLabel.Text = 'Density Factor:';
                            b  = firls(FIR_order, app.freqWin_Filter2, app.amp_Filter2, app.weight_Filter2);
                            app.filterDesigned = dfilt.dffir(b);
                        case 'Window'
                            Window_FilterValueChanged(app);
                            FIR_win = app.freqWin_Filter;
                            app.filterDesigned = fir1(FIR_order, FIR_win/(Fs/2), app.filterType, app.window);
                        case 'Constrained-least-squares'
                            b  = fircls(FIR_order, app.freqWin_Filter3, app.amp_Filter3, app.upperBandLimit_Filter, app.lowerBandLimit_Filter);
                            app.filterDesigned = dfilt.dffir(b);
                    end
                case 'IIR'
                    val4 = app.FilterTypr_Filter.Value;
                    val5 = app.Method_IIR.Value;
                    switch val5
                        case 'Butterworth'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,F3dB', FIR_order, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,F3dB', FIR_order, app.lowFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,F3dB1,F3dB2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,F3dB1,F3dB2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                            end
                        case 'Chebyshev Type I'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fp,Ap', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fp,Ap', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fp1,Fp2,Ap', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fp1,Fp2,Ap', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                            end
                        case 'Chebyshev Type II'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fst,Ast', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1_2.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fst,Ast', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1_2.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fst1,Fst2,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1_2.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fst1,Fst2,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1_2.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                            end
                        case 'Elliptic'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fp,Ap,Ast', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2_2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fp,Ast,Ap', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1_2.Value, app.IRR_Option2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fp1,Fp2,Ast1,Ap,Ast2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1_2.Value, app.IRR_Option2.Value,app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fp1,Fp2,Ap,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2_2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                            end
                    end
            end
            fvtool(app.filterDesigned) % display the designed filter
            %% Load a sample data
            %% select Frames for reconstruction
            Frames = app.SelectFrameEditField.Value;
            %% select sensors for reconstruction
            Sensors = app.SelectDetectorEditField.Value;
            %% select input data
            val = app.InputData_Filter.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Normalized Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                        return;
                    end
                    inputData = app.NormalizedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
            inputData = inputData(Frames,:,Sensors);
            if strcmp(val1, 'FIR')&&strcmp(val2, 'Window')
                outputData = conv2(inputData,app.filterDesigned,'same');
                %                 outputData = filter(app.filterDesigned, 1, inputData);
            else
                %             outputData = filter(app.filterDesigned,inputData);
                outputData =filtfilthd(app.filterDesigned,inputData);
            end
            %             delay = mean(grpdelay(app.filterDesigned));
            %             inputData = inputData(1:end-delay);
            %             outputData(1:delay) = [];
            figure('Name','Example: time domain & frequency domain','Renderer', 'painters', 'Position', [10 10 500 600]);
            ax1 = subplot(411);  plot(inputData);
            title('input, time');
            xlabel('samples');
            ax2 = subplot(412);  plot(outputData);
            title('output, time');
            xlabel('samples');
            grid on
            linkaxes([ax1 ax2], 'xy');
            
            Ns = length(inputData);
            inFFT = abs(fft(inputData, Ns));
            inFFT_half = inFFT(1:floor(Ns/2));
            fsamples = [1:length(inFFT_half)]*Fs/Ns;
            
            outFFT = abs(fft(outputData, Ns));
            outFFT_half = outFFT(1:floor(Ns/2));
            %             [P_in,f_in] = periodogram(inputData,[],[],Fs,'power');
            %             [P_out,f_out] = periodogram(outputData,[],[],Fs,'power');
            
            %             figure('Name','Example: frequency domain');
            ax3 = subplot(413);  plot(fsamples, inFFT_half);
            title('input, spectrum')
            xlabel('frequency (MHz)')
            ax4 = subplot(414);  plot(fsamples, outFFT_half);
            title('output, spectrum')
            xlabel('frequency (MHz)')
            grid on
            linkaxes([ax3 ax4], 'xy')
            
        end

        % Button pushed function: FilteringButton
        function FilteringButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Filtering ...','Cancelable',"on");
            FilterTypr_FilterValueChanged(app);
            %% Design filter
            Fs = app.SignalSamplingRateMHzEditField.Value;
            FIR_order = app.filterOrder.Value;
            val1 = app.Method_Filter.Value;
            switch val1
                case 'FIR'
                    val2 = app.Method_FIR.Value;
                    switch val2
                        case 'Equiripple'
                            app.optionsLabel.Text = 'Density Factor:';
                            dens = app.densOption_Filter.Value;
                            b  = firpm(FIR_order, app.freqWin_Filter2, app.amp_Filter2, app.weight_Filter2, {dens});
                            app.filterDesigned = dfilt.dffir(b);
                        case 'Least-squares'
                            app.optionsLabel.Text = 'Density Factor:';
                            b  = firls(FIR_order, app.freqWin_Filter2, app.amp_Filter2, app.weight_Filter2);
                            app.filterDesigned = dfilt.dffir(b);
                        case 'Window'
                            Window_FilterValueChanged(app);
                            FIR_win = app.freqWin_Filter;
                            app.filterDesigned = fir1(FIR_order, FIR_win/(Fs/2), app.filterType, app.window);
                        case 'Constrained-least-squares'
                            b  = fircls(FIR_order, app.freqWin_Filter3, app.amp_Filter3, app.upperBandLimit_Filter, app.lowerBandLimit_Filter);
                            app.filterDesigned = dfilt.dffir(b);
                    end
                case 'IIR'
                    val4 = app.FilterTypr_Filter.Value;
                    val5 = app.Method_IIR.Value;
                    switch val5
                        case 'Butterworth'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,F3dB', FIR_order, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,F3dB', FIR_order, app.lowFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,F3dB1,F3dB2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,F3dB1,F3dB2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, Fs);
                                    app.filterDesigned = design(h, 'butter');
                            end
                        case 'Chebyshev Type I'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fp,Ap', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fp,Ap', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fp1,Fp2,Ap', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fp1,Fp2,Ap', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby1');
                            end
                        case 'Chebyshev Type II'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fst,Ast', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fst,Ast', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fst1,Fst2,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fst1,Fst2,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'cheby2');
                            end
                        case 'Elliptic'
                            switch val4
                                case 'Lowpass'
                                    h  = fdesign.lowpass('N,Fp,Ap,Ast', FIR_order, app.highFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Highpass'
                                    h  = fdesign.highpass('N,Fp,Ast,Ap', FIR_order, app.lowFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Bandpass'
                                    h  = fdesign.bandpass('N,Fp1,Fp2,Ast1,Ap,Ast2', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2.Value,app.IRR_Option1.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                                case 'Bandstop'
                                    h  = fdesign.bandstop('N,Fp1,Fp2,Ap,Ast', FIR_order, app.lowFreq_Filter.Value, app.highFreq_Filter.Value, app.IRR_Option1.Value, app.IRR_Option2.Value, Fs);
                                    app.filterDesigned = design(h, 'ellip');
                            end
                    end
            end
            %% Load data and apply the designed filter
            val = app.InputData_Filter.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.CleanedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Normalized Data'
                    if isempty(app.NormalizedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                        return;
                    end
                    inputData = app.NormalizedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
            out = zeros(size(inputData));
            for i=1:size(inputData,1)
                in = squeeze(inputData(i,:,:));
                if strcmp(val1, 'FIR')&&strcmp(val2, 'Window')
                    %                     outputData = conv2(inputData,app.filterDesigned,'same');
                    out(i,:,:) = filter(app.filterDesigned, 1, in);
                else
                    %                     out(i,:,:) = filter(app.filterDesigned,in);
                    out(i,:,:) = filtfilthd(app.filterDesigned,in);
                end
                d.Value = i/(size(inputData,1));      % Progress bar
                Msg = 'Iteration: %d ';
                d.Message = sprintf(Msg,i);
            end
            close (d)
            app.FilteredData = out;
            app.FilteringButton.BackgroundColor = [0.39,0.83,0.07];
        end

        % Button pushed function: NormalizationButton
        function NormalizationButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Data Cleaning','Indeterminate','on');
            val = app.InputDataDropDown_Normalization.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.CleanedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
           
            %% Normalization
            d.Title = 'Normalization';
            val = app.MethodDropDown.Value;
            switch val
                case 'Z-score'
                    val2 = app.MethodType_1.Value;
                    switch val2
                        case 'Standard deviation'
                            methodtype = 'std';
                        case 'Robust'
                            methodtype = 'robust';
                    end
                    app.NormalizedData = normalize(inputData,2,'zscore',methodtype);
                case 'Norm'
                    val2 = app.MethodType_2.Value;
                    switch val2
                        case 'Positive numeric scalar'
                            app.NormalizedData = normalize(inputData,2,'norm',app.valueN_1.Value);
                        case 'Infinity norm'
                            app.NormalizedData = normalize(inputData,2,'norm',Inf);
                    end
                    
                case 'Scale'
                    val2 = app.MethodType_3.Value;
                    switch val2
                        case 'Standard deviation'
                            methodtype = 'std';
                            app.NormalizedData = normalize(inputData,2,'scale',methodtype);
                        case 'Median absolute deviation'
                            methodtype = 'mad';
                            app.NormalizedData = normalize(inputData,2,'scale',methodtype);
                        case 'First element of data'
                            methodtype = 'first';
                            app.NormalizedData = normalize(inputData,2,'scale',methodtype);
                        case 'Interquartile range'
                            methodtype = 'iqr';
                            app.NormalizedData = normalize(inputData,2,'scale',methodtype);
                        case 'Numeric values'
                            numericArray = inputData(app.valueN_2.Value, :,app.valueN_1.Value);
                            app.NormalizedData = normalize(inputData,2,'scale',numericArray);
                    end
                    
                case 'Range'
                    if app.valueN_2.Value>app.valueN_1.Value
                        elements = [app.valueN_1.Value app.valueN_2.Value];
                    elseif app.valueN_2.Value<app.valueN_1.Value
                        elements = [app.valueN_2.Value app.valueN_1.Value];
                    else
                        uialert(app.PAT_ReconstructionInterface, 'a and b fields must not have the same value!', 'Error');
                        return;
                    end
                    app.NormalizedData = normalize(inputData,2,'range',elements);

                case 'Center'
                    val2 = app.MethodType_4.Value;
                    switch val2
                        case 'Mean'
                            methodtype = 'mean';
                            app.NormalizedData = normalize(inputData,2,'center',methodtype);
                        case 'Median'
                            methodtype = 'median';
                            app.NormalizedData = normalize(inputData,2,'center',methodtype);
                        case 'Numeric array'
                            numericArray = inputData(app.valueN_2.Value, :,app.valueN_1.Value);
                            app.NormalizedData = normalize(inputData,2,'center',numericArray);
                    end
            end
            app.NormalizationButton.BackgroundColor = [0.39,0.83,0.07];
            delete(d);
        end

        % Value changed function: DirectivityCheckBox
        function DirectivityCheckBoxValueChanged(app, event)
            value = app.DirectivityCheckBox.Value;
            switch value
                case 1
                    app.FullangleofacceptancedegreeEditField.Visible = 'On';
                    app.MethodButtonGroup.Visible = 'On';
                    app.AngleofacceptancedegLabel.Visible = 'On';
                    app.ExampleLabel.Visible = 'On';
                    app.SelectFrameDirectivityExample.Visible = 'On';
                    app.SelectDetectorDirectivityExample.Visible = 'On';
                    app.SelectDetectorLabel_2.Visible = 'On';
                    app.SelectFrameLabel_2.Visible = 'On';
                case 0
                    app.FullangleofacceptancedegreeEditField.Visible = 'Off';
                    app.MethodButtonGroup.Visible = 'Off';
                    app.AngleofacceptancedegLabel.Visible = 'Off';
                    app.ExampleLabel.Visible = 'Off';
                    app.SelectFrameDirectivityExample.Visible = 'Off';
                    app.SelectDetectorDirectivityExample.Visible = 'Off';
                    app.SelectDetectorLabel_2.Visible = 'Off';
                    app.SelectFrameLabel_2.Visible = 'Off';
            end          
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            val = app.OptionsDropDown.Value;
            switch val
                case 'Time Series'
                    app.OptionsDropDownType.Enable = 'On';
                    app.OptionsDropDownData.Enable = 'On';
                    app.SelectDataSamplesButtonGroup.Visible='On';
                    app.SelectDetectorsButtonGroup.Visible='On';
                    app.SelectFramesButtonGroup.Visible='On';
                    app.Switch.Enable = 'Off';
                case 'Sinogram'
                    app.OptionsDropDownType.Enable = 'On';
                    app.OptionsDropDownData.Enable = 'On';
                    app.SelectDataSamplesButtonGroup.Visible='On';
                    app.SelectDetectorsButtonGroup.Visible='On';
                    app.SelectFramesButtonGroup.Visible='On';
                    app.Switch.Enable = 'Off';
                case 'Detector Locations'
                    app.OptionsDropDownType.Enable = 'Off';
                    app.OptionsDropDownData.Enable = 'Off';
                    app.SelectDataSamplesButtonGroup.Visible='Off';
                    app.SelectDetectorsButtonGroup.Visible='On';
                    app.SelectFramesButtonGroup.Visible='On';
                    app.Switch.Enable = 'Off';
                case 'Reconstructed Image'
                    app.OptionsDropDownType.Enable = 'Off';
                    app.OptionsDropDownData.Enable = 'Off';
                    app.SelectDataSamplesButtonGroup.Visible='Off';
                    app.SelectDetectorsButtonGroup.Visible='Off';
                    app.SelectFramesButtonGroup.Visible='Off';
                    app.Switch.Enable = 'On';
            end
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            switch selectedButton.Text
                case {'Load focal points', 'Load unit vectors'}
                    app.BrowseDirectivityDataButton.Enable = 'On';
                    app.xEditField.Enable = 'Off';
                    app.yEditField.Enable = 'Off';
                    app.zEditField.Enable = 'Off';
                    app.xEditField_2.Enable = 'Off';
                    app.yEditField_2.Enable = 'Off';
                    app.zEditField_2.Enable = 'Off';
                case 'Global focal point'
                    app.BrowseDirectivityDataButton.Enable = 'Off';
                    app.xEditField.Enable = 'On';
                    app.yEditField.Enable = 'On';
                    app.zEditField.Enable = 'On';
                    app.xEditField_2.Enable = 'Off';
                    app.yEditField_2.Enable = 'Off';
                    app.zEditField_2.Enable = 'Off';
                case 'Global unit vector'
                    app.BrowseDirectivityDataButton.Enable = 'Off';
                    app.xEditField.Enable = 'Off';
                    app.yEditField.Enable = 'Off';
                    app.zEditField.Enable = 'Off';
                    app.xEditField_2.Enable = 'On';
                    app.yEditField_2.Enable = 'On';
                    app.zEditField_2.Enable = 'On';
                case 'Center of the detector locations for every frame'
                    app.BrowseDirectivityDataButton.Enable = 'Off';
                    app.xEditField.Enable = 'Off';
                    app.yEditField.Enable = 'Off';
                    app.zEditField.Enable = 'Off';
                    app.xEditField_2.Enable = 'Off';
                    app.yEditField_2.Enable = 'Off';
                    app.zEditField_2.Enable = 'Off';
            end
        end

        % Button pushed function: BrowseDirectivityDataButton
        function BrowseDirectivityDataButtonPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Loading CSV file','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_ReconstructionInterface.Visible = 'Off';
                app.PAT_ReconstructionInterface.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    temp = value{1};
                elseif strcmp(ext, '.txt')
                    temp =  load(fullfile(PathName,filename));
                end
                guiHandle = AssignDirectivityApp(app, temp);
                guiFigureHandle = guiHandle.DirectivityAssign;
                uiwait(guiFigureHandle)
                format longG
                app.BrowseDirectivityDataButton.BackgroundColor = [0.39,0.83,0.07];
                close(d);
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Image Error');
                return;
            end
        end

        % Value changed function: Method_Filter
        function Method_FilterValueChanged(app, event)
            FilterTypr_FilterValueChanged(app);
            
            value = app.Method_Filter.Value;
            switch value
                case 'FIR'
                    app.optionsLabel.Text = '';
                    app.optionsLabel2.Text = '';
                    app.IRR_Option1.Visible = 'Off';
                    app.IRR_Option2.Visible = 'Off';
                    app.IRR_Option1_2.Visible = 'Off';
                    app.IRR_Option2_2.Visible = 'Off';
                    app.Method_FIR.Visible = 'On';
                    app.Method_IIR.Visible = 'Off';
                    Method_FIRValueChanged(app);
                case 'IIR'
                    IIROptions(app);
                    app.Method_FIR.Visible = 'Off';
                    app.Method_IIR.Visible = 'On';
                    app.Window_Filter.Visible = 'Off';
                    app.WindowLabel.Visible = 'Off';
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
            end
        end

        % Value changed function: Method_FIR
        function Method_FIRValueChanged(app, event)
            FilterTypr_FilterValueChanged(app);
            value = app.Method_FIR.Value;
            if strcmp(value, 'Window')
                app.Window_Filter.Visible = 'On';
                app.WindowLabel.Visible = 'On';
                Window_FilterValueChanged(app);
            else
                app.Window_Filter.Visible = 'Off';
                app.WindowLabel.Visible = 'Off';
                app.optionsLabel.Text = "";
                app.attOption_Filter.Visible = 'Off';
                app.alphaOption_Filter.Visible = 'Off';
                app.shapeOption_Filter.Visible = 'Off';
                app.cosineOption_Filter.Visible = 'Off';
            end
            WeightingOptions(app);
        end

        % Value changed function: Window_Filter
        function Window_FilterValueChanged(app, event)
            value = app.Window_Filter.Value;
            n = app.filterOrder.Value;
            switch value
                case 'barthannwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = barthannwin(n+1);
                case 'bartlett'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = bartlett(n+1);
                case 'blackman'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = blackman(n+1);
                case 'blackmanharris'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = blackmanharris(n+1);
                case 'bohmanwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = bohmanwin(n+1);
                case 'chebwin'
                    app.optionsLabel.Text ="sidelobe atten. (dB):";
                    app.attOption_Filter.Visible = 'On';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    r = app.attOption_Filter.Value;
                    app.window = chebwin(n+1,r);
                case 'flattopwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = flattopwin(n+1);
                case 'gausswin'
                    app.optionsLabel.Text = "alpha:";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'On';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    alpha = app.alphaOption_Filter.Value;
                    app.window = gausswin(n+1,alpha);
                case 'hamming'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = hamming(n+1);
                case 'hann'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = hann(n+1);
                case 'kaiser'
                    app.optionsLabel.Text = "shape factor:";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'On';
                    app.cosineOption_Filter.Visible = 'Off';
                    beta = app.shapeOption_Filter.Value;
                    app.window = kaiser(n+1,beta);
                case 'nuttallwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = nuttallwin(n+1);
                case 'parzenwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = parzenwin(n+1);
                case 'rectwin'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = rectwin(n+1);
                case 'tukeywin'
                    app.optionsLabel.Text = "cosine fraction:";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'On';
                    r = app.cosineOption_Filter.Value;
                    app.window = tukeywin(n+1,r);
                case 'triang'
                    app.optionsLabel.Text = "";
                    app.attOption_Filter.Visible = 'Off';
                    app.alphaOption_Filter.Visible = 'Off';
                    app.shapeOption_Filter.Visible = 'Off';
                    app.cosineOption_Filter.Visible = 'Off';
                    app.window = triang(n+1);
            end
        end

        % Value changed function: FilterTypr_Filter
        function FilterTypr_FilterValueChanged(app, event)
            Fs = app.SignalSamplingRateMHzEditField.Value;
            val1 = app.FilterTypr_Filter.Value;
            val2 = app.Method_Filter.Value;
            val3 = app.Method_FIR.Value;
            switch val2
                case 'FIR'
                    switch val1
                        case 'Lowpass'
                            app.highFreq_Filter.Visible = 'On';
                            app.lowFreq_Filter.Visible = 'Off';
                            app.highLabel.Visible = 'On';
                            app.lowLabel.Visible = 'Off';
                            app.freqWin_Filter = app.highFreq_Filter.Value;
                            app.filterType = 'low';
                            if strcmp(val3,'Equiripple')||strcmp(val3,'Least-squares')
                                app.highXFreq_Filter.Visible = 'On';
                                app.lowXFreq_Filter.Visible = 'Off';
                                app.highXLabel.Visible = 'On';
                                app.lowXLabel.Visible = 'Off';
                                app.freqWin_Filter2 = [0 app.highFreq_Filter.Value app.highXFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter2 = [1 1 0 0];
                                app.weight_Filter2 = [app.band1Up.Value, app.band2Up.Value];
                            end
                            if strcmp(val3,'Constrained-least-squares')
                                app.upperBandLimit_Filter = [1+app.band1Up.Value app.band2Up.Value];
                                app.lowerBandLimit_Filter = [1-app.band1Lo.Value -1*app.band2Lo.Value];
                                app.freqWin_Filter3 = [0 app.highFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter3 = [1 0];
                            end
                        case 'Highpass'
                            app.highFreq_Filter.Visible = 'Off';
                            app.lowFreq_Filter.Visible = 'On';
                            app.highLabel.Visible = 'Off';
                            app.lowLabel.Visible = 'On';
                            app.freqWin_Filter = app.lowFreq_Filter.Value;
                            app.filterType = 'high';
                            if strcmp(val3,'Equiripple')||strcmp(val3,'Least-squares')
                                app.highXFreq_Filter.Visible = 'Off';
                                app.lowXFreq_Filter.Visible = 'On';
                                app.highXLabel.Visible = 'Off';
                                app.lowXLabel.Visible = 'On';
                                app.freqWin_Filter2 = [0 app.lowXFreq_Filter.Value app.lowFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter2 = [0 0 1 1];
                                app.weight_Filter2 = [app.band1Up.Value, app.band2Up.Value];
                            end
                            if strcmp(val3,'Constrained-least-squares')
                                app.upperBandLimit_Filter = [app.band1Up.Value 1+app.band2Up.Value];
                                app.lowerBandLimit_Filter = [-1*app.band1Lo.Value 1-app.band2Lo.Value];
                                app.freqWin_Filter3 = [0 app.lowFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter3 = [0 1];
                            end
                        case 'Bandpass'
                            app.highFreq_Filter.Visible = 'On';
                            app.lowFreq_Filter.Visible = 'On';
                            app.highLabel.Visible = 'On';
                            app.lowLabel.Visible = 'On';
                            app.freqWin_Filter = [app.lowFreq_Filter.Value app.highFreq_Filter.Value];
                            app.filterType = 'bandpass';
                            if strcmp(val3,'Equiripple')||strcmp(val3,'Least-squares')
                                app.highXFreq_Filter.Visible = 'On';
                                app.lowXFreq_Filter.Visible = 'On';
                                app.highXLabel.Visible = 'On';
                                app.lowXLabel.Visible = 'On';
                                app.freqWin_Filter2 = [0 app.lowXFreq_Filter.Value app.lowFreq_Filter.Value app.highFreq_Filter.Value app.highXFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter2 = [0 0 1 1 0 0];
                                app.weight_Filter2 = [app.band1Up.Value, app.band2Up.Value, app.band3Up.Value];
                            end
                            if strcmp(val3,'Constrained-least-squares')
                                app.upperBandLimit_Filter = [app.band1Up.Value 1+app.band2Up.Value app.band3Up.Value];
                                app.lowerBandLimit_Filter = [-1*app.band1Lo.Value 1-app.band2Lo.Value -1*app.band3Lo.Value];
                                app.freqWin_Filter3 = [0 app.lowFreq_Filter.Value app.highFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter3 = [0 1 0];
                            end
                        case 'Bandstop'
                            app.highFreq_Filter.Visible = 'On';
                            app.lowFreq_Filter.Visible = 'On';
                            app.highLabel.Visible = 'On';
                            app.lowLabel.Visible = 'On';
                            app.freqWin_Filter = [app.lowFreq_Filter.Value app.highFreq_Filter.Value];
                            app.filterType = 'stop';
                            if strcmp(val3,'Equiripple')||strcmp(val3,'Least-squares')
                                app.highXFreq_Filter.Visible = 'On';
                                app.lowXFreq_Filter.Visible = 'On';
                                app.highXLabel.Visible = 'On';
                                app.lowXLabel.Visible = 'On';
                                app.freqWin_Filter2 = [0 app.lowXFreq_Filter.Value app.lowFreq_Filter.Value app.highFreq_Filter.Value app.highXFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter2 = [1 1 0 0 1 1];
                                app.weight_Filter2 = [app.band1Up.Value, app.band2Up.Value, app.band3Up.Value];
                            end
                            if strcmp(val3,'Constrained-least-squares')
                                app.upperBandLimit_Filter = [1+app.band1Up.Value app.band2Up.Value 1+app.band3Up.Value];
                                app.lowerBandLimit_Filter = [1-app.band1Lo.Value -1*app.band2Lo.Value 1-app.band3Lo.Value];
                                app.freqWin_Filter3 = [0 app.lowFreq_Filter.Value app.highFreq_Filter.Value Fs/2]/(Fs/2);
                                app.amp_Filter3 = [1 0 1];
                            end
                    end
                    WeightingOptions(app);
                case 'IIR'
                    IIROptions(app);
            end
            if strcmp(val2,'IIR')||(strcmp(val2,'FIR')&&strcmp(val3,'Window'))||(strcmp(val2,'FIR')&&strcmp(val3,'Constrained-least-squares'))
                app.highXFreq_Filter.Visible = 'Off';
                app.lowXFreq_Filter.Visible = 'Off';
                app.highXLabel.Visible = 'Off';
                app.lowXLabel.Visible = 'Off';
            end
        end

        % Value changed function: Method_IIR
        function Method_IIRValueChanged(app, event)
            value = app.Method_IIR.Value;
            IIROptions(app);
        end

        % Button pushed function: TesttheFilterButton_MF
        function TesttheFilterButton_MFPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Plotting Data ...','Cancelable',"on");
                val = app.InputDataDropDown_MF.Value;
                switch val
                    case 'Raw Data'
                        if isempty(app.ImData)
                            uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                            return;
                        end
                        inputData = app.ImData;
                    case 'Cleaned Data'
                        if isempty(app.ImData)
                            uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                            return;
                        end
                        inputData = app.CleanedData;
                    case 'Normalized Data'
                        if isempty(app.ImData)
                            uialert(app.PAT_ReconstructionInterface, 'There is no "Normalized Data" available!', 'Error');
                            return;
                        end
                        inputData = app.NormalizedData;
                    case 'Filtered Data'
                        if isempty(app.ImData)
                            uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                            return;
                        end
                        inputData = app.FilteredData;
                    case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                end
                Frame = app.SelectFrame_MFExample.Value;
                sensor = app.SelectDetector_MFExample.Value;
                pa_data_temp = reshape(inputData(Frame,:,sensor), [],1);
                
                
                val2 = app.Method_MF.Value;
                switch val2
                    case 'Select a range of the data samples as template'
                        tem = flip(pa_data_temp(app.numberSamplesBeforeMax.Value:app.numberSamplesAfterMax.Value,1)); %template signal
                    case 'Select a part of the data samples containing the global maximum as template'
                        [~ , II] = max(pa_data_temp);
                        tem = flip(pa_data_temp((II-app.numberSamplesBeforeMax.Value):II+app.numberSamplesAfterMax.Value,1)); %template signal
                    case 'Load a 1D template (time domain)'
                        tem = flip(app.loadedTemplateMF);
                    case 'Load a 2D template (time domain)'
                        tem = flip(app.loadedTemplateMF(:,sensor));
                end
                
                r = conv(pa_data_temp, tem, 'same');
                level =app.LevelEditField.Value;
                maxVal = max(r);
                r(r<(maxVal*level))=0;
%                 outputData = r;
                rLocalMax = islocalmax(r);
                r(~rLocalMax) = 0;
                outputData = conv(r, flip(tem), 'same');
                %                     tem   = (pa_data_temp((II-app.numberSamplesBeforeMax.Value):II+app.numberSamplesAfterMax.Value,1));
                %                     filter = phased.MatchedFilter('Coefficients',tem);
                %                     outputData(i,:,j) = real(filter(pa_data_temp));
                figure("Name", 'Test Matched Filter')
                ax1 = subplot(2,2,1); plot(pa_data_temp); title("input data")
                ax2 = subplot(2,2,2); plot(flip(tem)); title("template")
                ax3 = subplot(2,2,3); plot(r); title("local max for conv between input data and template")
                ax4 = subplot(2,2,4); plot(outputData); title("conv between local max and flipped template")
                %             linkaxes([ax1, ax3, ax4], 'xy');
                box("on")
                
                val3 = app.ButtonGroup_MF.SelectedObject;
                if strcmp(val3.Text, 'Use one template for all time series')
                    app.loadedTemplateMF = tem;
                end
                close (d)
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Error');
                return;
            end
        end

        % Value changed function: Method_MF
        function Method_MFValueChanged(app, event)
            value = app.Method_MF.Value;
            switch value
                case 'Select a range of the signal as template'
                    app.LoadButton_MF.Visible = 'Off';
                    app.Label_MF.Text = 'start sample:';
                    app.Label2_MF.Text = 'end sample:';
                    app.numberSamplesBeforeMax.Visible = 'On';
                    app.numberSamplesAfterMax.Visible = 'On';
                    app.ButtonGroup_MF.Visible = 'On';
                case 'Select a part of the signal containing the global maximum as template'
                    app.LoadButton_MF.Visible = 'Off';
                    app.Label_MF.Text = '#of samples before the max:';
                    app.Label2_MF.Text = '#of samples after the max:';
                    app.numberSamplesBeforeMax.Visible = 'On';
                    app.numberSamplesAfterMax.Visible = 'On';
                    app.ButtonGroup_MF.Visible = 'On';
                case {'Load a 1D template (time domain)','Load a 2D template (time domain)'}
                    app.LoadButton_MF.Visible = 'On';
                    app.Label_MF.Text = '';
                    app.Label2_MF.Text = '';
                    app.numberSamplesBeforeMax.Visible = 'Off';
                    app.numberSamplesAfterMax.Visible = 'Off';
                    app.ButtonGroup_MF.Visible = 'Off';
            end
        end

        % Selection changed function: ButtonGroup_MF
        function ButtonGroup_MFSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_MF.SelectedObject;
            switch selectedButton.Text
                case 'Use one template for all time series'
                    app.ExamplePanel_MF.Title = 'Select a Signal:';
                    app.TesttheFilterButton_MF.Text = 'Generate the filter';
                case 'Use a unique template for each time series'
                    app.ExamplePanel_MF.Title = 'Example:';
                    app.TesttheFilterButton_MF.Text = 'Test the Filter';
            end
        end

        % Button pushed function: LoadButton_MF
        function LoadButton_MFPushed(app, event)
            try
                f = app.PAT_ReconstructionInterface;
                d = uiprogressdlg(f,'Title','Loading template data','Indeterminate','on');
                [filename,PathName] = uigetfile('*.txt; *.mat', 'Select the DATA file');
                app.PAT_ReconstructionInterface.Visible = 'Off';
                app.PAT_ReconstructionInterface.Visible = 'On';
                [~,~,ext] = fileparts(filename);
                if strcmp(ext, '.mat')
                    x =  load(fullfile(PathName,filename));
                    value = struct2cell(x);
                    tem = value{1};
                elseif strcmp(ext, '.txt')
                    tem =  load(fullfile(PathName,filename));
                end
                if size(tem,3)~=1
                    uialert(app.PAT_ReconstructionInterface, 'The loaded data must be 1 dimensional', 'Error');
                    return;
                end
                if (size(tem,1)~=1 && size(tem,2)~=1)
                    app.loadedTemplateMF = tem;
                else
                    app.loadedTemplateMF = reshape(tem, [], 1);
                end
                close(d);
            catch ME
                uialert(app.PAT_ReconstructionInterface, ME.message, 'Error');
                return;
            end
        end

        % Value changed function: SpeedofsoundDropDown
        function SpeedofsoundDropDownValueChanged(app, event)
            value = app.SpeedofsoundDropDown.Value;
            switch value 
                case 'Scalar'
                    app.SOSLoadButton.Visible = 'Off';
                    app.SoS.Visible = 'On';
                    app.SOSDesignButton.Visible = 'Off';
                case 'Load a matrix'
                    app.SOSLoadButton.Visible = 'On';
                    app.SoS.Visible = 'Off';
                    app.SOSDesignButton.Visible = 'Off';
                case 'Design a matrix'
                    app.SOSLoadButton.Visible = 'Off';
                    app.SoS.Visible = 'Off';
                    app.SOSDesignButton.Visible = 'On';
            end
        end

        % Value changed function: SamplingrateMHzEditField, SoS, 
        % intervalEditField_6
        function SoSValueChanged(app, event)
            val1 = app.SoS.Value;
            val2 = app.SamplingrateMHzEditField.Value;
            val3 = app.intervalEditField_6.Value;     % selected sampling interval
            pitch = val3*val1/(1000*val2);
            app.SamplingpitchmmEditField.Value = pitch;

        end

        % Value changed function: field1_FT
        function field1_FTValueChanged(app, event)
            SensorsLength = app.field1_FT.Value;
            selectedButton = app.ConfigurationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Linear array')
                CalculateNumberSensors(app);
                numSensor = app.NumberofavailabledetectorsEditField.Value;
            else
                numSensor = app.field5_FT.Value;
                if numSensor==0
                    uialert(app.PAT_ReconstructionInterface, 'Fill the "Number of detectors in length:" field first.', 'Error');
                    return;
                end
            end
            app.field2_FT.Value = SensorsLength/(numSensor-1);
        end

        % Value changed function: field2_FT
        function field2_FTValueChanged(app, event)
            pitch = app.field2_FT.Value;
            selectedButton = app.ConfigurationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Linear array')
                CalculateNumberSensors(app);
                numSensor = app.NumberofavailabledetectorsEditField.Value;
            else
                numSensor = app.field5_FT.Value;
                if numSensor==0
                    uialert(app.PAT_ReconstructionInterface, 'Fill the "Number of detector in length:" field first.', 'Error');
                    return;
                end
            end
            app.field1_FT.Value = (numSensor-1)*pitch;
        end

        % Value changed function: firstEditField, firstEditField_2, 
        % intervalEditField, intervalEditField_2, lastEditField, 
        % lastEditField_2, selectedFramesEditField, 
        % selectedTransducersEditField
        function firstEditField_2ValueChanged(app, event)
            CalculateNumberSensors(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
           CalculateNumberSensors(app);
            
        end

        % Selection changed function: ConfigurationButtonGroup
        function ConfigurationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConfigurationButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Linear array')
                app.label3_FT.Visible = 'Off';
                app.label4_FT.Visible = 'Off';
                app.field3_FT.Visible = 'Off';
                app.field4_FT.Visible = 'Off';
                app.field5_FT.Visible = 'Off';
                app.field6_FT.Visible = 'Off';
                app.label5_FT.Visible = 'Off';
                app.label6_FT.Visible = 'Off';
                app.label2_FT.Text = 'Detectors pitch [mm]:';
            else
                app.label3_FT.Visible = 'On';
                app.label4_FT.Visible = 'On';
                app.field3_FT.Visible = 'On';
                app.field4_FT.Visible = 'On';
                app.field5_FT.Visible = 'On';
                app.field6_FT.Visible = 'On';
                app.label5_FT.Visible = 'On';
                app.label6_FT.Visible = 'On';
                app.label2_FT.Text = 'Pitch in length [mm]:';
            end
        end

        % Value changed function: field3_FT
        function field3_FTValueChanged(app, event)
            SensorsWidth = app.field3_FT.Value;
            numSensor = app.field6_FT.Value;
            if numSensor==0
                uialert(app.PAT_ReconstructionInterface, 'Fill the "Number of detector in width:" field first.', 'Error');
                return;
            end
            app.field4_FT.Value = SensorsWidth/(numSensor-1);
        end

        % Value changed function: field4_FT
        function field4_FTValueChanged(app, event)
            pitch = app.field4_FT.Value;
            numSensor = app.field6_FT.Value;
            if numSensor==0
                uialert(app.PAT_ReconstructionInterface, 'Fill the "Number of detector in width:" field first.', 'Error');
                return;
            end
            app.field3_FT.Value = (numSensor-1)*pitch;
        end

        % Value changed function: InterpolationCheckBox
        function InterpolationCheckBoxValueChanged(app, event)
            value = app.InterpolationCheckBox.Value;
            if value
                app.interpMethod.Visible = 'On';
            else
                app.interpMethod.Visible = 'Off';
            end
        end

        % Button pushed function: DownsamplingButton
        function DownsamplingButtonPushed(app, event)
            f = app.PAT_ReconstructionInterface;
            d = uiprogressdlg(f,'Title','Data Cleaning','Indeterminate','on');
            val = app.InputDataDropDown_Downsampling.Value;
            switch val
                case 'Raw Data'
                    if isempty(app.ImData)
                        uialert(app.PAT_ReconstructionInterface, 'The input data does not exist or assigned!', 'Error');
                        return;
                    end
                    inputData = app.ImData;
                case 'Cleaned Data'
                    if isempty(app.CleanedData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Cleaned Data" available!', 'Error');
                        return;
                    end
                    inputData = app.CleanedData;
                case 'Filtered Data'
                    if isempty(app.FilteredData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.FilteredData;
                case 'Downsampled Data'
                    if isempty(app.DownsampledData)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Downsampled Data" available!', 'Error');
                        return;
                    end
                    inputData = app.DownsampledData;
                case 'Matched Filtered Data'
                    if isempty(app.MatchedFilter)
                        uialert(app.PAT_ReconstructionInterface, 'There is no "Matched Filtered Data" available!', 'Error');
                        return;
                    end
                    inputData = app.MatchedFilter;
            end
            M = app.FactorEditField.Value;
            phase = app.OffsetEditField.Value;
            valM = app.MethodDropDown_Downsampling.Value;
            iter = 0;
            if strcmp(valM, 'Decimation')
                for i = 1:size(inputData,1)
                    iter =iter+1;
                    pa_data_temp = reshape(inputData(i,:,:),size(inputData,2),[]);
                    outputData(i,:,:) = downsample(pa_data_temp, M, phase);
                    Msg = 'Iteration: %d ';
                    d.Message = sprintf(Msg,iter);
                end
            elseif strcmp(valM,'Moving average decimation')
                mask = (1/M).*ones(1,M);
                for i = 1:size(inputData,1)
                    for j = 1:size(inputData,3)
                    iter =iter+1;
                    pa_data_temp = reshape(inputData(i,:,j),1,[]);
                    r = conv(pa_data_temp, mask, 'same');
                    outputData(i,:,j) = downsample(r, M, phase);
                    Msg = 'Iteration: %d ';
                    d.Message = sprintf(Msg,iter);
                    end
                end
            end
            app.DownsampledData = outputData;
            app.DownsamplingButton.BackgroundColor = [0.39,0.83,0.07];
            delete(d);
        end

        % Button pushed function: UserManualButton
        function UserManualButtonPushed(app, event)
            winopen('https://github.com/Lawson-Optics-Lab/PATLAB/blob/main/docs/PATLAB_Manual.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PAT_ReconstructionInterface = uifigure('Visible', 'off');
            app.PAT_ReconstructionInterface.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.PAT_ReconstructionInterface);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadingGeneratingRawDataTab = uitab(app.TabGroup);
            app.LoadingGeneratingRawDataTab.Title = 'Loading/Generating Raw Data';
            app.Panel_7 = uipanel(app.LoadingGeneratingRawDataTab);
            app.LoadTimeSeriesDataButton = uibutton(app.Panel_7, 'push');
            app.LoadTimeSeriesDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTimeSeriesDataButtonPushed, true);
            app.LoadTimeSeriesDataButton.Text = 'Load Time Series Data';
            app.PropertiesTextArea = uitextarea(app.Panel_7);
            app.Panel_2 = uipanel(app.Panel_7);
            app.AssignButton = uibutton(app.Panel_2, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'Data Samples', 'Detectors', 'Frames'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown.Value = 'Data Samples';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'Data Samples', 'Detectors', 'Frames'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Frames';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Items = {'Data Samples', 'Detectors', 'Frames'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_3.Value = 'Detectors';
            app.SizeLabel = uilabel(app.Panel_2);
            app.SizeLabel.Text = 'Size:';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '1:';
            app.size1EditField = uieditfield(app.Panel_2, 'numeric');
            app.size1EditField.Editable = 'off';
            app.size1EditField.Value = 1;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '2:';
            app.size2EditField = uieditfield(app.Panel_2, 'numeric');
            app.size2EditField.Editable = 'off';
            app.size2EditField.Value = 1;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '3:';
            app.size3EditField = uieditfield(app.Panel_2, 'numeric');
            app.size3EditField.Editable = 'off';
            app.size3EditField.Value = 1;
            app.SimulateTimeSeriesDataButton = uibutton(app.Panel_7, 'push');
            app.SimulateTimeSeriesDataButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateTimeSeriesDataButtonPushed, true);
            app.SimulateTimeSeriesDataButton.Text = 'Simulate Time Series Data ';
            app.Panel_8 = uipanel(app.LoadingGeneratingRawDataTab);
            app.PropertiesTextArea_2 = uitextarea(app.Panel_8);
            app.LoadDetectorLocationsButton = uibutton(app.Panel_8, 'push');
            app.LoadDetectorLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDetectorLocationsButtonPushed, true);
            app.LoadDetectorLocationsButton.Tooltip = {''};
            app.LoadDetectorLocationsButton.Text = 'Load Detector Locations';
            app.Panel_3 = uipanel(app.Panel_8);
            app.AssignButton_2 = uibutton(app.Panel_3, 'push');
            app.AssignButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssignButton_2Pushed, true);
            app.AssignButton_2.Text = 'Assign';
            app.DropDown_4 = uidropdown(app.Panel_3);
            app.DropDown_4.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_4.Value = 'Coordinates';
            app.DropDown_5 = uidropdown(app.Panel_3);
            app.DropDown_5.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'Detectors';
            app.DropDown_6 = uidropdown(app.Panel_3);
            app.DropDown_6.Items = {'Coordinates', 'Detectors', 'Frames'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_6.Value = 'Frames';
            app.SizeLabel_2 = uilabel(app.Panel_3);
            app.SizeLabel_2.Text = 'Size:';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '1:';
            app.size1EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.size1EditField_2.Editable = 'off';
            app.size1EditField_2.Value = 1;
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '2:';
            app.size2EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.size2EditField_2.Editable = 'off';
            app.size2EditField_2.Value = 1;
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '3:';
            app.size3EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.size3EditField_2.Editable = 'off';
            app.size3EditField_2.Value = 1;
            app.DesignDetectorLocationsButton = uibutton(app.Panel_8, 'push');
            app.DesignDetectorLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @DesignDetectorLocationsButtonPushed, true);
            app.DesignDetectorLocationsButton.Tooltip = {''};
            app.DesignDetectorLocationsButton.Text = 'Design Detector Locations';
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.Panel_4 = uipanel(app.PreProcessingTab);
            app.DataCleaningPanel = uipanel(app.Panel_4);
            app.DataCleaningPanel.Title = 'Data Cleaning:';
            app.DetectorstoremoveEditFieldLabel = uilabel(app.DataCleaningPanel);
            app.DetectorstoremoveEditFieldLabel.Text = 'Detectors to remove:';
            app.DetectorstoremoveEditField = uieditfield(app.DataCleaningPanel, 'text');
            app.DetectorstoremoveEditField.Tooltip = {'separate numbers with a space or comma'};
            app.ThresholdlevelforentiredataamplitudeEditFieldLabel = uilabel(app.DataCleaningPanel);
            app.ThresholdlevelforentiredataamplitudeEditFieldLabel.Text = 'Threshold level for entire data (amplitude):';
            app.ThresholdlevelforentiredataamplitudeEditField = uieditfield(app.DataCleaningPanel, 'numeric');
            app.ofdatasamplesatthebeginningtozeroEditFieldLabel = uilabel(app.DataCleaningPanel);
            app.ofdatasamplesatthebeginningtozeroEditFieldLabel.Text = '# of data samples at the beginning to zero:';
            app.ofdatasamplesatthebeginningtozeroEditField = uieditfield(app.DataCleaningPanel, 'numeric');
            app.ofdatasamplesatthebeginningtozeroEditField.Tooltip = {'data samples in the time series'};
            app.ofdatasamplesatthebeginningtozeroEditField.Value = 200;
            app.FramestoremoveEditFieldLabel = uilabel(app.DataCleaningPanel);
            app.FramestoremoveEditFieldLabel.Text = 'Frames to remove:';
            app.FramestoremoveEditField = uieditfield(app.DataCleaningPanel, 'text');
            app.FramestoremoveEditField.Tooltip = {'separate numbers with a space or comma'};
            app.ofdatasamplesattheendtozeroEditFieldLabel = uilabel(app.DataCleaningPanel);
            app.ofdatasamplesattheendtozeroEditFieldLabel.Text = '# of data samples at the end to zero:';
            app.ofdatasamplesattheendtozeroEditField = uieditfield(app.DataCleaningPanel, 'numeric');
            app.ofdatasamplesattheendtozeroEditField.Tooltip = {'data samples in the time series'};
            app.ofdatasamplesattheendtozeroEditField.Value = 200;
            app.ofdatasamplesatthebeginningtocutoutLabel = uilabel(app.DataCleaningPanel);
            app.ofdatasamplesatthebeginningtocutoutLabel.Tooltip = {'data samples in the time series'};
            app.ofdatasamplesatthebeginningtocutoutLabel.Text = '# of data samples at the beginning to cut out:';
            app.ofdatasamplesatthebeginningtocutoutEditField = uieditfield(app.DataCleaningPanel, 'numeric');
            app.ofdatasamplesattheendtocutoutLabel = uilabel(app.DataCleaningPanel);
            app.ofdatasamplesattheendtocutoutLabel.Text = '# of data samples at the end to cut out:';
            app.ofdatasamplesattheendtocutoutEditField = uieditfield(app.DataCleaningPanel, 'numeric');
            app.ofdatasamplesattheendtocutoutEditField.Tooltip = {'data samples in the time series'};
            app.InputdataLabel_3 = uilabel(app.DataCleaningPanel);
            app.InputdataLabel_3.Text = 'Input data:';
            app.InputDataDropDown_Cleaning = uidropdown(app.DataCleaningPanel);
            app.InputDataDropDown_Cleaning.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.InputDataDropDown_Cleaning.Value = 'Raw Data';
            app.DataCleaningButton = uibutton(app.Panel_4, 'push');
            app.DataCleaningButton.ButtonPushedFcn = createCallbackFcn(app, @DataCleaningButtonPushed, true);
            app.DataCleaningButton.Tooltip = {'Apply data cleaning '; 'to the input data'; ''};
            app.DataCleaningButton.Text = 'Data Cleaning';
            app.Panel_5 = uipanel(app.PreProcessingTab);
            app.MatchedFilteringButton = uibutton(app.Panel_5, 'push');
            app.MatchedFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @MatchedFilteringButtonPushed, true);
            app.MatchedFilteringButton.Tooltip = {'Apply matched filtering to the input data'; ''};
            app.MatchedFilteringButton.Text = 'Matched Filtering';
            app.MatchedFilteringPanel = uipanel(app.Panel_5);
            app.MatchedFilteringPanel.Title = 'Matched Filtering:';
            app.InputdataLabel_5 = uilabel(app.MatchedFilteringPanel);
            app.InputdataLabel_5.Text = 'Input data:';
            app.InputDataDropDown_MF = uidropdown(app.MatchedFilteringPanel);
            app.InputDataDropDown_MF.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data'};
            app.InputDataDropDown_MF.Value = 'Raw Data';
            app.ExamplePanel_MF = uipanel(app.MatchedFilteringPanel);
            app.ExamplePanel_MF.Title = 'Example:';
            app.SelectFrameLabel = uilabel(app.ExamplePanel_MF);
            app.SelectFrameLabel.Text = 'Select Frame:';
            app.SelectFrame_MFExample = uieditfield(app.ExamplePanel_MF, 'numeric');
            app.SelectFrame_MFExample.Value = 1;
            app.SelectDetectorLabel = uilabel(app.ExamplePanel_MF);
            app.SelectDetectorLabel.Text = 'Select Detector:';
            app.SelectDetector_MFExample = uieditfield(app.ExamplePanel_MF, 'numeric');
            app.SelectDetector_MFExample.Value = 1;
            app.TesttheFilterButton_MF = uibutton(app.MatchedFilteringPanel, 'push');
            app.TesttheFilterButton_MF.ButtonPushedFcn = createCallbackFcn(app, @TesttheFilterButton_MFPushed, true);
            app.TesttheFilterButton_MF.Tooltip = {''};
            app.TesttheFilterButton_MF.Text = 'Test the filter';
            app.MethodLabel_2 = uilabel(app.MatchedFilteringPanel);
            app.MethodLabel_2.Text = 'Method:';
            app.Method_MF = uidropdown(app.MatchedFilteringPanel);
            app.Method_MF.Items = {'Select a range of the signal as template', 'Select a part of the signal containing the global maximum as template', 'Load a 1D template (time domain)', 'Load a 2D template (time domain)'};
            app.Method_MF.ValueChangedFcn = createCallbackFcn(app, @Method_MFValueChanged, true);
            app.Method_MF.Value = 'Select a range of the signal as template';
            app.ButtonGroup_MF = uibuttongroup(app.MatchedFilteringPanel);
            app.ButtonGroup_MF.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_MFSelectionChanged, true);
            app.UseauniquetemplateforeachtimeseriesButton = uiradiobutton(app.ButtonGroup_MF);
            app.UseauniquetemplateforeachtimeseriesButton.Text = 'Use a unique template for each time series';
            app.UseauniquetemplateforeachtimeseriesButton.Value = true;
            app.UseonetemplateforalltimeseriesButton = uiradiobutton(app.ButtonGroup_MF);
            app.UseonetemplateforalltimeseriesButton.Text = 'Use one template for all time series';
            app.numberSamplesBeforeMax = uieditfield(app.MatchedFilteringPanel, 'numeric');
            app.numberSamplesBeforeMax.Value = 800;
            app.Label_MF = uilabel(app.MatchedFilteringPanel);
            app.Label_MF.Text = 'Start data sample:';
            app.numberSamplesAfterMax = uieditfield(app.MatchedFilteringPanel, 'numeric');
            app.numberSamplesAfterMax.Value = 1000;
            app.Label2_MF = uilabel(app.MatchedFilteringPanel);
            app.Label2_MF.Text = 'End data sample:';
            app.LoadButton_MF = uibutton(app.MatchedFilteringPanel, 'push');
            app.LoadButton_MF.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_MFPushed, true);
            app.LoadButton_MF.Visible = 'off';
            app.LoadButton_MF.Text = 'Load';
            app.LevelEditFieldLabel = uilabel(app.MatchedFilteringPanel);
            app.LevelEditFieldLabel.Text = 'Level:';
            app.LevelEditField = uieditfield(app.MatchedFilteringPanel, 'numeric');
            app.LevelEditField.Limits = [0 1];
            app.LevelEditField.Tooltip = {'The number of data samples to represent one sample.'; ''};
            app.LevelEditField.Value = 0.5;
            app.Panel_10 = uipanel(app.PreProcessingTab);
            app.FilteringButton = uibutton(app.Panel_10, 'push');
            app.FilteringButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringButtonPushed, true);
            app.FilteringButton.Tooltip = {'Apply filtering to the input data'; ''};
            app.FilteringButton.Text = 'Filtering';
            app.FilteringPanel = uipanel(app.Panel_10);
            app.FilteringPanel.Tooltip = {'DSP System Toolbox is required'};
            app.FilteringPanel.Title = 'Filtering:';
            app.highFreq_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.highFreq_Filter.Tooltip = {'Maximum value that it can get is equal to the half of the sampling rate'};
            app.highFreq_Filter.Value = 10;
            app.lowFreq_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.lowFreq_Filter.Value = 1;
            app.OrderLabel = uilabel(app.FilteringPanel);
            app.OrderLabel.Text = 'Order:';
            app.filterOrder = uieditfield(app.FilteringPanel, 'numeric');
            app.filterOrder.Value = 100;
            app.highLabel = uilabel(app.FilteringPanel);
            app.highLabel.Text = 'high:';
            app.CutofffreqMHzLabel = uilabel(app.FilteringPanel);
            app.CutofffreqMHzLabel.Text = 'Cutoff freq. (MHz):';
            app.SignalSamplingRateMHzEditFieldLabel = uilabel(app.FilteringPanel);
            app.SignalSamplingRateMHzEditFieldLabel.Text = 'Signal sampling rate (MHz):';
            app.SignalSamplingRateMHzEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.SignalSamplingRateMHzEditField.Value = 50;
            app.lowLabel = uilabel(app.FilteringPanel);
            app.lowLabel.Text = 'low:';
            app.ExamplePanel = uipanel(app.FilteringPanel);
            app.ExamplePanel.Title = 'Example:';
            app.SelectFrameEditFieldLabel = uilabel(app.ExamplePanel);
            app.SelectFrameEditFieldLabel.Text = 'Select Frame:';
            app.SelectFrameEditField = uieditfield(app.ExamplePanel, 'numeric');
            app.SelectFrameEditField.Value = 1;
            app.SelectDetectorEditFieldLabel = uilabel(app.ExamplePanel);
            app.SelectDetectorEditFieldLabel.Text = 'Select Detector:';
            app.SelectDetectorEditField = uieditfield(app.ExamplePanel, 'numeric');
            app.SelectDetectorEditField.Value = 1;
            app.InputdataLabel = uilabel(app.FilteringPanel);
            app.InputdataLabel.Text = 'Input data:';
            app.InputData_Filter = uidropdown(app.FilteringPanel);
            app.InputData_Filter.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.InputData_Filter.Value = 'Raw Data';
            app.FiltertypeLabel = uilabel(app.FilteringPanel);
            app.FiltertypeLabel.Text = 'Filter type:';
            app.FilterTypr_Filter = uidropdown(app.FilteringPanel);
            app.FilterTypr_Filter.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Bandstop'};
            app.FilterTypr_Filter.ValueChangedFcn = createCallbackFcn(app, @FilterTypr_FilterValueChanged, true);
            app.FilterTypr_Filter.Value = 'Bandpass';
            app.MethodLabel = uilabel(app.FilteringPanel);
            app.MethodLabel.Text = 'Method:';
            app.Method_Filter = uidropdown(app.FilteringPanel);
            app.Method_Filter.Items = {'FIR', 'IIR'};
            app.Method_Filter.ValueChangedFcn = createCallbackFcn(app, @Method_FilterValueChanged, true);
            app.Method_Filter.Value = 'FIR';
            app.Method_IIR = uidropdown(app.FilteringPanel);
            app.Method_IIR.Items = {'Butterworth', 'Chebyshev Type I', 'Chebyshev Type II', 'Elliptic'};
            app.Method_IIR.ValueChangedFcn = createCallbackFcn(app, @Method_IIRValueChanged, true);
            app.Method_IIR.Value = 'Butterworth';
            app.Method_FIR = uidropdown(app.FilteringPanel);
            app.Method_FIR.Items = {'Equiripple', 'Least-squares', 'Window', 'Constrained-least-squares'};
            app.Method_FIR.ValueChangedFcn = createCallbackFcn(app, @Method_FIRValueChanged, true);
            app.Method_FIR.Value = 'Window';
            app.TestthefilterButton = uibutton(app.FilteringPanel, 'push');
            app.TestthefilterButton.ButtonPushedFcn = createCallbackFcn(app, @TestthefilterButtonPushed, true);
            app.TestthefilterButton.Tooltip = {''};
            app.TestthefilterButton.Text = 'Test the filter';
            app.Window_Filter = uidropdown(app.FilteringPanel);
            app.Window_Filter.Items = {'barthannwin', 'bartlett', 'blackman', 'blackmanharris', 'bohmanwin', 'chebwin', 'flattopwin', 'gausswin', 'hamming', 'hann', 'kaiser', 'nuttallwin', 'parzenwin', 'rectwin', 'tukeywin', 'triang'};
            app.Window_Filter.ValueChangedFcn = createCallbackFcn(app, @Window_FilterValueChanged, true);
            app.Window_Filter.Value = 'hamming';
            app.attOption_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.attOption_Filter.Visible = 'off';
            app.attOption_Filter.Value = 100;
            app.alphaOption_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.alphaOption_Filter.Limits = [0 Inf];
            app.alphaOption_Filter.Visible = 'off';
            app.alphaOption_Filter.Value = 2.5;
            app.shapeOption_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.shapeOption_Filter.Limits = [0 Inf];
            app.shapeOption_Filter.Visible = 'off';
            app.shapeOption_Filter.Value = 0.5;
            app.optionsLabel = uilabel(app.FilteringPanel);
            app.optionsLabel.Text = '';
            app.WindowLabel = uilabel(app.FilteringPanel);
            app.WindowLabel.Text = 'Window:';
            app.cosineOption_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.cosineOption_Filter.Limits = [0 Inf];
            app.cosineOption_Filter.Visible = 'off';
            app.cosineOption_Filter.Value = 0.5;
            app.highXFreq_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.highXFreq_Filter.Visible = 'off';
            app.highXFreq_Filter.Tooltip = {'Maximum value that it can get is equal to the half of the sampling rate'};
            app.highXFreq_Filter.Value = 11;
            app.highXLabel = uilabel(app.FilteringPanel);
            app.highXLabel.Visible = 'off';
            app.highXLabel.Text = 'highX:';
            app.lowXLabel = uilabel(app.FilteringPanel);
            app.lowXLabel.Visible = 'off';
            app.lowXLabel.Text = 'lowX:';
            app.lowXFreq_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.lowXFreq_Filter.Visible = 'off';
            app.lowXFreq_Filter.Value = 0.8;
            app.densOption_Filter = uieditfield(app.FilteringPanel, 'numeric');
            app.densOption_Filter.Limits = [16 Inf];
            app.densOption_Filter.Visible = 'off';
            app.densOption_Filter.Value = 16;
            app.Bnad1Label = uilabel(app.FilteringPanel);
            app.Bnad1Label.Visible = 'off';
            app.Bnad1Label.Text = 'Bnad1:';
            app.Bnad2Label = uilabel(app.FilteringPanel);
            app.Bnad2Label.Visible = 'off';
            app.Bnad2Label.Text = 'Bnad2:';
            app.Bnad3Label = uilabel(app.FilteringPanel);
            app.Bnad3Label.Visible = 'off';
            app.Bnad3Label.Text = 'Bnad3:';
            app.band1Up = uieditfield(app.FilteringPanel, 'numeric');
            app.band1Up.Visible = 'off';
            app.band1Up.Value = 0.1;
            app.band1Lo = uieditfield(app.FilteringPanel, 'numeric');
            app.band1Lo.Visible = 'off';
            app.band1Lo.Value = 0.1;
            app.band2Up = uieditfield(app.FilteringPanel, 'numeric');
            app.band2Up.Visible = 'off';
            app.band2Up.Value = 0.1;
            app.band2Lo = uieditfield(app.FilteringPanel, 'numeric');
            app.band2Lo.Visible = 'off';
            app.band2Lo.Value = 0.1;
            app.band3Up = uieditfield(app.FilteringPanel, 'numeric');
            app.band3Up.Visible = 'off';
            app.band3Up.Value = 0.1;
            app.band3Lo = uieditfield(app.FilteringPanel, 'numeric');
            app.band3Lo.Visible = 'off';
            app.band3Lo.Value = 0.1;
            app.upperLabel = uilabel(app.FilteringPanel);
            app.upperLabel.Visible = 'off';
            app.upperLabel.Text = 'upper';
            app.lowerLabel = uilabel(app.FilteringPanel);
            app.lowerLabel.Visible = 'off';
            app.lowerLabel.Text = 'lower';
            app.IRR_Option1 = uieditfield(app.FilteringPanel, 'numeric');
            app.IRR_Option1.Limits = [0 Inf];
            app.IRR_Option1.Visible = 'off';
            app.IRR_Option1.Value = 1;
            app.IRR_Option2 = uieditfield(app.FilteringPanel, 'numeric');
            app.IRR_Option2.Limits = [0 Inf];
            app.IRR_Option2.Visible = 'off';
            app.IRR_Option2.Value = 1;
            app.optionsLabel2 = uilabel(app.FilteringPanel);
            app.optionsLabel2.Text = '';
            app.IRR_Option1_2 = uieditfield(app.FilteringPanel, 'numeric');
            app.IRR_Option1_2.Limits = [0 Inf];
            app.IRR_Option1_2.Visible = 'off';
            app.IRR_Option1_2.Value = 80;
            app.IRR_Option2_2 = uieditfield(app.FilteringPanel, 'numeric');
            app.IRR_Option2_2.Limits = [0 Inf];
            app.IRR_Option2_2.Visible = 'off';
            app.IRR_Option2_2.Value = 80;
            app.Panel_11 = uipanel(app.PreProcessingTab);
            app.NormalizationButton = uibutton(app.Panel_11, 'push');
            app.NormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizationButtonPushed, true);
            app.NormalizationButton.Tooltip = {'Apply normalization to the input data'; ''};
            app.NormalizationButton.Text = 'Normalization';
            app.NormalizationPanel = uipanel(app.Panel_11);
            app.NormalizationPanel.Title = 'Normalization:';
            app.MethodDropDownLabel = uilabel(app.NormalizationPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.NormalizationPanel);
            app.MethodDropDown.Items = {'Z-score', 'Norm', 'Scale', 'Range', 'Center'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Z-score';
            app.InputdataLabel_2 = uilabel(app.NormalizationPanel);
            app.InputdataLabel_2.Text = 'Input data:';
            app.InputDataDropDown_Normalization = uidropdown(app.NormalizationPanel);
            app.InputDataDropDown_Normalization.Items = {'Raw Data', 'Cleaned Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.InputDataDropDown_Normalization.Value = 'Raw Data';
            app.MethodtypeoptionsLabel = uilabel(app.NormalizationPanel);
            app.MethodtypeoptionsLabel.Text = 'Method type options:';
            app.valueN_2 = uieditfield(app.NormalizationPanel, 'numeric');
            app.valueN_2.Visible = 'off';
            app.valueN_2.Value = 1;
            app.labelN_2 = uilabel(app.NormalizationPanel);
            app.labelN_2.Visible = 'off';
            app.labelN_2.Text = 'b:';
            app.valueN_1 = uieditfield(app.NormalizationPanel, 'numeric');
            app.valueN_1.Visible = 'off';
            app.valueN_1.Value = 1;
            app.labelN_1 = uilabel(app.NormalizationPanel);
            app.labelN_1.Visible = 'off';
            app.labelN_1.Text = 'a:';
            app.MethodType_1 = uidropdown(app.NormalizationPanel);
            app.MethodType_1.Items = {'Standard deviation', 'Robust'};
            app.MethodType_1.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodType_1.Value = 'Standard deviation';
            app.MethodType_2 = uidropdown(app.NormalizationPanel);
            app.MethodType_2.Items = {'Positive numeric scalar', 'Infinity norm'};
            app.MethodType_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodType_2.Visible = 'off';
            app.MethodType_2.Value = 'Positive numeric scalar';
            app.MethodType_3 = uidropdown(app.NormalizationPanel);
            app.MethodType_3.Items = {'Standard deviation', 'Median absolute deviation', 'First element of data', 'Interquartile range', 'Numeric values'};
            app.MethodType_3.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodType_3.Visible = 'off';
            app.MethodType_3.Value = 'Standard deviation';
            app.MethodType_4 = uidropdown(app.NormalizationPanel);
            app.MethodType_4.Items = {'Mean', 'Median', 'Numeric array'};
            app.MethodType_4.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodType_4.Visible = 'off';
            app.MethodType_4.Value = 'Mean';
            app.Panel_12 = uipanel(app.PreProcessingTab);
            app.DownsamplingButton = uibutton(app.Panel_12, 'push');
            app.DownsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @DownsamplingButtonPushed, true);
            app.DownsamplingButton.Tooltip = {'Apply downsampling to the input data'; ''};
            app.DownsamplingButton.Text = 'Downsampling';
            app.DownsamplingPanel = uipanel(app.Panel_12);
            app.DownsamplingPanel.Title = 'Downsampling:';
            app.MethodDropDown_2Label = uilabel(app.DownsamplingPanel);
            app.MethodDropDown_2Label.Text = 'Method:';
            app.MethodDropDown_Downsampling = uidropdown(app.DownsamplingPanel);
            app.MethodDropDown_Downsampling.Items = {'Decimation', 'Moving average decimation'};
            app.MethodDropDown_Downsampling.Value = 'Decimation';
            app.InputdataLabel_4 = uilabel(app.DownsamplingPanel);
            app.InputdataLabel_4.Text = 'Input data:';
            app.InputDataDropDown_Downsampling = uidropdown(app.DownsamplingPanel);
            app.InputDataDropDown_Downsampling.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.InputDataDropDown_Downsampling.Value = 'Raw Data';
            app.FactorEditFieldLabel = uilabel(app.DownsamplingPanel);
            app.FactorEditFieldLabel.Text = 'Factor:';
            app.FactorEditField = uieditfield(app.DownsamplingPanel, 'numeric');
            app.FactorEditField.Tooltip = {'The number of data samples to represent one sample.'; ''};
            app.FactorEditField.Value = 2;
            app.OffsetEditFieldLabel = uilabel(app.DownsamplingPanel);
            app.OffsetEditFieldLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.DownsamplingPanel, 'numeric');
            app.OffsetEditField.Limits = [0 Inf];
            app.OffsetEditField.Tooltip = {'The number of data samples to represent one sample.'; ''};
            app.ImageReconstructionTab = uitab(app.TabGroup);
            app.ImageReconstructionTab.Title = 'Image Reconstruction';
            app.ReconstructionButton = uibutton(app.ImageReconstructionTab, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.SelectSensorsButtonGroup_Rec = uibuttongroup(app.ImageReconstructionTab);
            app.SelectSensorsButtonGroup_Rec.SelectionChangedFcn = createCallbackFcn(app, @SelectSensorsButtonGroup_RecSelectionChanged, true);
            app.SelectSensorsButtonGroup_Rec.Title = 'Select Detectors:';
            app.SelectedButton_3 = uiradiobutton(app.SelectSensorsButtonGroup_Rec);
            app.SelectedButton_3.Text = 'Selected';
            app.selectedTransducersEditField = uieditfield(app.SelectSensorsButtonGroup_Rec, 'text');
            app.selectedTransducersEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.selectedTransducersEditField.Enable = 'off';
            app.selectedTransducersEditField.Tooltip = {'separate numbers with a space or comma'};
            app.selectedTransducersEditField.Value = '1';
            app.ArrayButton_3 = uiradiobutton(app.SelectSensorsButtonGroup_Rec);
            app.ArrayButton_3.Text = 'Array';
            app.ArrayButton_3.Value = true;
            app.firstEditFieldLabel = uilabel(app.SelectSensorsButtonGroup_Rec);
            app.firstEditFieldLabel.Text = 'first';
            app.firstEditField = uieditfield(app.SelectSensorsButtonGroup_Rec, 'numeric');
            app.firstEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.firstEditField.Value = 1;
            app.intervalEditFieldLabel = uilabel(app.SelectSensorsButtonGroup_Rec);
            app.intervalEditFieldLabel.Text = 'interval';
            app.intervalEditField = uieditfield(app.SelectSensorsButtonGroup_Rec, 'numeric');
            app.intervalEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.intervalEditField.Value = 1;
            app.lastEditFieldLabel = uilabel(app.SelectSensorsButtonGroup_Rec);
            app.lastEditFieldLabel.Text = 'last';
            app.lastEditField = uieditfield(app.SelectSensorsButtonGroup_Rec, 'numeric');
            app.lastEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.lastEditField.Value = 64;
            app.SelectFramesButtonGroup_Rec = uibuttongroup(app.ImageReconstructionTab);
            app.SelectFramesButtonGroup_Rec.SelectionChangedFcn = createCallbackFcn(app, @SelectFramesButtonGroup_RecSelectionChanged, true);
            app.SelectFramesButtonGroup_Rec.Title = 'Select Frames:';
            app.SelectedButton_4 = uiradiobutton(app.SelectFramesButtonGroup_Rec);
            app.SelectedButton_4.Text = 'Selected';
            app.SelectedButton_4.Value = true;
            app.selectedFramesEditField = uieditfield(app.SelectFramesButtonGroup_Rec, 'text');
            app.selectedFramesEditField.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.selectedFramesEditField.Tooltip = {'separate numbers with a space or comma'; ''};
            app.selectedFramesEditField.Value = '1';
            app.ArrayButton_4 = uiradiobutton(app.SelectFramesButtonGroup_Rec);
            app.ArrayButton_4.Text = 'Array';
            app.firstEditField_2Label = uilabel(app.SelectFramesButtonGroup_Rec);
            app.firstEditField_2Label.Text = 'first';
            app.firstEditField_2 = uieditfield(app.SelectFramesButtonGroup_Rec, 'numeric');
            app.firstEditField_2.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.firstEditField_2.Enable = 'off';
            app.firstEditField_2.Value = 1;
            app.intervalEditField_2Label = uilabel(app.SelectFramesButtonGroup_Rec);
            app.intervalEditField_2Label.Text = 'interval';
            app.intervalEditField_2 = uieditfield(app.SelectFramesButtonGroup_Rec, 'numeric');
            app.intervalEditField_2.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.intervalEditField_2.Enable = 'off';
            app.intervalEditField_2.Value = 1;
            app.lastEditField_2Label = uilabel(app.SelectFramesButtonGroup_Rec);
            app.lastEditField_2Label.Text = 'last';
            app.lastEditField_2 = uieditfield(app.SelectFramesButtonGroup_Rec, 'numeric');
            app.lastEditField_2.ValueChangedFcn = createCallbackFcn(app, @firstEditField_2ValueChanged, true);
            app.lastEditField_2.Enable = 'off';
            app.lastEditField_2.Value = 1000;
            app.Panel_Rec = uipanel(app.ImageReconstructionTab);
            app.InputdataDropDownLabel = uilabel(app.Panel_Rec);
            app.InputdataDropDownLabel.Text = 'Input data:';
            app.InputdataDropDown = uidropdown(app.Panel_Rec);
            app.InputdataDropDown.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.InputdataDropDown.Value = 'Raw Data';
            app.DatatypeDropDownLabel = uilabel(app.Panel_Rec);
            app.DatatypeDropDownLabel.Text = 'Data type:';
            app.DatatypeDropDown = uidropdown(app.Panel_Rec);
            app.DatatypeDropDown.Items = {'Original Data', 'Inverse', 'Envelope', 'Positive', 'Negative', 'Absolute Value', 'Complex-Hilbert'};
            app.DatatypeDropDown.Value = 'Original Data';
            app.SlicingButtonGroup = uibuttongroup(app.Panel_Rec);
            app.SlicingButtonGroup.Title = 'Slicing:';
            app.xyzButton = uitogglebutton(app.SlicingButtonGroup);
            app.xyzButton.Icon = 'xyz.png';
            app.xyzButton.Text = 'xyz';
            app.xyzButton.Value = true;
            app.yzxButton = uitogglebutton(app.SlicingButtonGroup);
            app.yzxButton.Icon = 'yzx.png';
            app.yzxButton.Text = ' yzx';
            app.zxyButton = uitogglebutton(app.SlicingButtonGroup);
            app.zxyButton.Icon = 'xzy.png';
            app.zxyButton.Text = '  zxy';
            app.SoS = uispinner(app.Panel_Rec);
            app.SoS.Limits = [0 Inf];
            app.SoS.ValueChangedFcn = createCallbackFcn(app, @SoSValueChanged, true);
            app.SoS.Tooltip = {'[m/s]'};
            app.SoS.Value = 1500;
            app.SOSLoadButton = uibutton(app.Panel_Rec, 'push');
            app.SOSLoadButton.Visible = 'off';
            app.SOSLoadButton.Text = 'Load';
            app.SOSDesignButton = uibutton(app.Panel_Rec, 'push');
            app.SOSDesignButton.Visible = 'off';
            app.SOSDesignButton.Tooltip = {'(m/s)'};
            app.SOSDesignButton.Text = 'Design';
            app.SpeedofsoundDropDown = uidropdown(app.Panel_Rec);
            app.SpeedofsoundDropDown.Items = {'Scalar', 'Load a matrix', 'Design a matrix'};
            app.SpeedofsoundDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedofsoundDropDownValueChanged, true);
            app.SpeedofsoundDropDown.Tooltip = {'sound speed distribution within the medium'};
            app.SpeedofsoundDropDown.Value = 'Scalar';
            app.SamplingrateMHzLabel = uilabel(app.Panel_Rec);
            app.SamplingrateMHzLabel.Text = 'Sampling rate [MHz]:';
            app.SamplingrateMHzEditField = uispinner(app.Panel_Rec);
            app.SamplingrateMHzEditField.Limits = [0 Inf];
            app.SamplingrateMHzEditField.ValueChangedFcn = createCallbackFcn(app, @SoSValueChanged, true);
            app.SamplingrateMHzEditField.Value = 50;
            app.DelaysLabel = uilabel(app.Panel_Rec);
            app.DelaysLabel.Text = 'Delay [μs]:';
            app.DelaysEditField = uispinner(app.Panel_Rec);
            app.Panel_TR_options = uipanel(app.Panel_Rec);
            app.Panel_TR_options.Visible = 'off';
            app.SpeedofsoundLabel = uilabel(app.Panel_Rec);
            app.SpeedofsoundLabel.Text = 'Speed of sound:';
            app.Panel_FT_options = uipanel(app.Panel_Rec);
            app.Panel_FT_options.Visible = 'off';
            app.SamplingpitchmmEditFieldLabel = uilabel(app.Panel_FT_options);
            app.SamplingpitchmmEditFieldLabel.Text = 'Sampling pitch [mm]:';
            app.SamplingpitchmmEditField = uieditfield(app.Panel_FT_options, 'numeric');
            app.SamplingpitchmmEditField.Editable = 'off';
            app.SamplingpitchmmEditField.Tooltip = {'Indicates the computational grid '};
            app.SamplingpitchmmEditField.Value = 0.03;
            app.InterpolationCheckBox = uicheckbox(app.Panel_FT_options);
            app.InterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolationCheckBoxValueChanged, true);
            app.InterpolationCheckBox.Tooltip = {'Interpolation is needed if the sensor''s pitch is larger than the sampling pitch.'};
            app.InterpolationCheckBox.Text = 'Interpolation';
            app.field2_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field2_FT.ValueChangedFcn = createCallbackFcn(app, @field2_FTValueChanged, true);
            app.field2_FT.Tooltip = {'Indicates the computational grid '};
            app.field1_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field1_FT.ValueChangedFcn = createCallbackFcn(app, @field1_FTValueChanged, true);
            app.field1_FT.Tooltip = {'Indicates the computational grid '};
            app.NumberofavailabledetectorsEditFieldLabel = uilabel(app.Panel_FT_options);
            app.NumberofavailabledetectorsEditFieldLabel.Text = 'Number of available detectors:';
            app.NumberofavailabledetectorsEditField = uieditfield(app.Panel_FT_options, 'numeric');
            app.NumberofavailabledetectorsEditField.Editable = 'off';
            app.NumberofavailabledetectorsEditField.Tooltip = {'Indicates the computational grid '};
            app.NumberofavailabledetectorsEditField.Value = 64;
            app.ConfigurationButtonGroup = uibuttongroup(app.Panel_FT_options);
            app.ConfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfigurationButtonGroupSelectionChanged, true);
            app.ConfigurationButtonGroup.Title = 'Configuration:';
            app.LineararrayButton = uitogglebutton(app.ConfigurationButtonGroup);
            app.LineararrayButton.Text = 'Linear array';
            app.LineararrayButton.Value = true;
            app.PlanararrayButton = uitogglebutton(app.ConfigurationButtonGroup);
            app.PlanararrayButton.Text = 'Planar array';
            app.label1_FT = uilabel(app.Panel_FT_options);
            app.label1_FT.Text = 'Length of the array [mm]:';
            app.label2_FT = uilabel(app.Panel_FT_options);
            app.label2_FT.Text = 'Detectors pitch [mm]:';
            app.label3_FT = uilabel(app.Panel_FT_options);
            app.label3_FT.Visible = 'off';
            app.label3_FT.Text = 'Width of the plane [mm]:';
            app.label4_FT = uilabel(app.Panel_FT_options);
            app.label4_FT.Visible = 'off';
            app.label4_FT.Text = 'Pitch in width [mm]:';
            app.field4_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field4_FT.ValueChangedFcn = createCallbackFcn(app, @field4_FTValueChanged, true);
            app.field4_FT.Visible = 'off';
            app.field4_FT.Tooltip = {'Indicates the computational grid '};
            app.field3_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field3_FT.ValueChangedFcn = createCallbackFcn(app, @field3_FTValueChanged, true);
            app.field3_FT.Visible = 'off';
            app.field3_FT.Tooltip = {'Indicates the computational grid '};
            app.field5_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field5_FT.Visible = 'off';
            app.field5_FT.Tooltip = {'Indicates the computational grid '};
            app.field6_FT = uieditfield(app.Panel_FT_options, 'numeric');
            app.field6_FT.Visible = 'off';
            app.field6_FT.Tooltip = {'Indicates the computational grid '};
            app.label5_FT = uilabel(app.Panel_FT_options);
            app.label5_FT.Visible = 'off';
            app.label5_FT.Text = 'Number of detectors in length:';
            app.label6_FT = uilabel(app.Panel_FT_options);
            app.label6_FT.Visible = 'off';
            app.label6_FT.Text = 'Number of detectors in width:';
            app.interpMethod = uidropdown(app.Panel_FT_options);
            app.interpMethod.Items = {'nearest', 'linear'};
            app.interpMethod.Visible = 'off';
            app.interpMethod.Value = 'nearest';
            app.Panel_BP_options = uipanel(app.Panel_Rec);
            app.CoherentfactorCheckBox = uicheckbox(app.Panel_BP_options);
            app.CoherentfactorCheckBox.Text = 'Coherent factor';
            app.WeightingLabel = uilabel(app.Panel_BP_options);
            app.WeightingLabel.Text = 'Weighting:';
            app.MethodButtonGroup = uibuttongroup(app.Panel_BP_options);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method:';
            app.CenterofthedetectorlocationsforeveryframeButton = uiradiobutton(app.MethodButtonGroup);
            app.CenterofthedetectorlocationsforeveryframeButton.Text = 'Center of the detector locations for every frame';
            app.CenterofthedetectorlocationsforeveryframeButton.Value = true;
            app.GlobalfocalpointButton = uiradiobutton(app.MethodButtonGroup);
            app.GlobalfocalpointButton.Text = 'Global focal point';
            app.GlobalunitvectorButton = uiradiobutton(app.MethodButtonGroup);
            app.GlobalunitvectorButton.Text = 'Global unit vector';
            app.LoadfocalpointsButton = uiradiobutton(app.MethodButtonGroup);
            app.LoadfocalpointsButton.Text = 'Load focal points';
            app.BrowseDirectivityDataButton = uibutton(app.MethodButtonGroup, 'push');
            app.BrowseDirectivityDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDirectivityDataButtonPushed, true);
            app.BrowseDirectivityDataButton.Enable = 'off';
            app.BrowseDirectivityDataButton.Text = 'Browse Directivity Data';
            app.xEditFieldLabel = uilabel(app.MethodButtonGroup);
            app.xEditFieldLabel.Text = 'x:';
            app.xEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.MethodButtonGroup);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.MethodButtonGroup);
            app.zEditFieldLabel.Text = 'z:';
            app.zEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.zEditField.Enable = 'off';
            app.xEditField_2Label = uilabel(app.MethodButtonGroup);
            app.xEditField_2Label.Text = 'x:';
            app.xEditField_2 = uieditfield(app.MethodButtonGroup, 'numeric');
            app.xEditField_2.Enable = 'off';
            app.yEditField_2Label = uilabel(app.MethodButtonGroup);
            app.yEditField_2Label.Text = 'y:';
            app.yEditField_2 = uieditfield(app.MethodButtonGroup, 'numeric');
            app.yEditField_2.Enable = 'off';
            app.zEditField_2Label = uilabel(app.MethodButtonGroup);
            app.zEditField_2Label.Text = 'z:';
            app.zEditField_2 = uieditfield(app.MethodButtonGroup, 'numeric');
            app.zEditField_2.Enable = 'off';
            app.LoadunitvectorsButton = uiradiobutton(app.MethodButtonGroup);
            app.LoadunitvectorsButton.Text = 'Load unit vectors';
            app.DirectivityCheckBox = uicheckbox(app.Panel_BP_options);
            app.DirectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectivityCheckBoxValueChanged, true);
            app.DirectivityCheckBox.Text = 'Directivity';
            app.DirectivityCheckBox.Value = true;
            app.PowerEditField = uieditfield(app.Panel_BP_options, 'numeric');
            app.PowerEditField.Value = 2;
            app.FullangleofacceptancedegreeEditField = uieditfield(app.Panel_BP_options, 'numeric');
            app.FullangleofacceptancedegreeEditField.Value = 90;
            app.ExampleLabel = uilabel(app.Panel_BP_options);
            app.ExampleLabel.Text = 'Example:';
            app.SelectFrameDirectivityExample = uieditfield(app.Panel_BP_options, 'numeric');
            app.SelectFrameDirectivityExample.Value = 1;
            app.SelectDetectorDirectivityExample = uieditfield(app.Panel_BP_options, 'numeric');
            app.SelectDetectorDirectivityExample.Value = 1;
            app.AngleofacceptancedegLabel = uilabel(app.Panel_BP_options);
            app.AngleofacceptancedegLabel.Text = 'Angle of acceptance [deg]:';
            app.PowerLabel = uilabel(app.Panel_BP_options);
            app.PowerLabel.Text = 'Power:';
            app.SelectDetectorLabel_2 = uilabel(app.Panel_BP_options);
            app.SelectDetectorLabel_2.Text = 'Select Detector:';
            app.SelectFrameLabel_2 = uilabel(app.Panel_BP_options);
            app.SelectFrameLabel_2.Text = 'Select Frame:';
            app.DerivativesEditField = uieditfield(app.Panel_BP_options, 'numeric');
            app.DerivativesEditField.Visible = 'off';
            app.DerivativesEditField.Value = 2;
            app.DerivativesgainLabel = uilabel(app.Panel_BP_options);
            app.DerivativesgainLabel.Visible = 'off';
            app.DerivativesgainLabel.Text = 'Derivatives gain:';
            app.ReconstructionMethodDropDownLabel = uilabel(app.ImageReconstructionTab);
            app.ReconstructionMethodDropDownLabel.Text = 'Reconstruction Method:';
            app.ReconstructionMethodDropDown = uidropdown(app.ImageReconstructionTab);
            app.ReconstructionMethodDropDown.Items = {'Delay and Sum (DAS)', 'Filtered Back Projection', 'Universal Back Projection (UBP)', 'Fourier Transform (FT)', 'Time Reversal (TR)'};
            app.ReconstructionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ReconstructionMethodDropDownValueChanged, true);
            app.ReconstructionMethodDropDown.Value = 'Delay and Sum (DAS)';
            app.SelectSamplesButtonGroup_Rec = uibuttongroup(app.ImageReconstructionTab);
            app.SelectSamplesButtonGroup_Rec.Title = 'Select Data Samples:';
            app.firstEditField_6Label_2 = uilabel(app.SelectSamplesButtonGroup_Rec);
            app.firstEditField_6Label_2.Text = 'first';
            app.firstEditField_6 = uieditfield(app.SelectSamplesButtonGroup_Rec, 'numeric');
            app.firstEditField_6.Value = 1;
            app.lastLabel_2 = uilabel(app.SelectSamplesButtonGroup_Rec);
            app.lastLabel_2.Text = 'last';
            app.SamplesToEditField_2 = uieditfield(app.SelectSamplesButtonGroup_Rec, 'numeric');
            app.SamplesToEditField_2.Value = 1000;
            app.intervalEditField_6Label_2 = uilabel(app.SelectSamplesButtonGroup_Rec);
            app.intervalEditField_6Label_2.Text = 'interval';
            app.intervalEditField_6 = uieditfield(app.SelectSamplesButtonGroup_Rec, 'numeric');
            app.intervalEditField_6.ValueChangedFcn = createCallbackFcn(app, @SoSValueChanged, true);
            app.intervalEditField_6.Value = 1;
            app.ArrayLabel_2 = uilabel(app.SelectSamplesButtonGroup_Rec);
            app.ArrayLabel_2.Text = 'Array:';
            app.ComputationalGridPanel = uipanel(app.ImageReconstructionTab);
            app.ComputationalGridPanel.Title = 'Computational Grid:';
            app.centerzmmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.centerzmmSpinnerLabel.Text = 'center z [mm]:';
            app.centerzmmSpinner = uispinner(app.ComputationalGridPanel);
            app.centerzmmSpinner.Value = 50.5;
            app.centerymmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.centerymmSpinnerLabel.Text = 'center y [mm]:';
            app.centerymmSpinner = uispinner(app.ComputationalGridPanel);
            app.centerymmSpinner.Value = 460;
            app.centerxmmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.centerxmmSpinnerLabel.Text = 'center x [mm]:';
            app.centerxmmSpinner = uispinner(app.ComputationalGridPanel);
            app.xsizemmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.xsizemmSpinnerLabel.Text = 'x size [mm]:';
            app.xsizemmSpinner = uispinner(app.ComputationalGridPanel);
            app.xsizemmSpinner.Limits = [0 Inf];
            app.xsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.xsizemmSpinner.Value = 100;
            app.ysizemmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.ysizemmSpinnerLabel.Text = 'y size [mm]:';
            app.ysizemmSpinner = uispinner(app.ComputationalGridPanel);
            app.ysizemmSpinner.Limits = [0 Inf];
            app.ysizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.ysizemmSpinner.Value = 100;
            app.zsizemmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.zsizemmSpinnerLabel.Text = 'z size [mm]:';
            app.zsizemmSpinner = uispinner(app.ComputationalGridPanel);
            app.zsizemmSpinner.Limits = [0 Inf];
            app.zsizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.pixelsizexmmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.pixelsizexmmSpinnerLabel.Text = 'pixel size x [mm]:';
            app.pixelsizexmmSpinner = uispinner(app.ComputationalGridPanel);
            app.pixelsizexmmSpinner.Step = 0.05;
            app.pixelsizexmmSpinner.Limits = [1e-09 Inf];
            app.pixelsizexmmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.pixelsizexmmSpinner.Value = 0.5;
            app.pixelsizeymmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.pixelsizeymmSpinnerLabel.Text = 'pixel size y [mm]:';
            app.pixelsizeymmSpinner = uispinner(app.ComputationalGridPanel);
            app.pixelsizeymmSpinner.Step = 0.05;
            app.pixelsizeymmSpinner.Limits = [1e-09 Inf];
            app.pixelsizeymmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.pixelsizeymmSpinner.Value = 0.5;
            app.pixelsizezmmSpinnerLabel = uilabel(app.ComputationalGridPanel);
            app.pixelsizezmmSpinnerLabel.Text = 'pixel size z [mm]:';
            app.pixelsizezmmSpinner = uispinner(app.ComputationalGridPanel);
            app.pixelsizezmmSpinner.Step = 0.05;
            app.pixelsizezmmSpinner.Limits = [1e-09 Inf];
            app.pixelsizezmmSpinner.ValueChangedFcn = createCallbackFcn(app, @ImagesizexmmSpinnerValueChanged, true);
            app.pixelsizezmmSpinner.Value = 0.5;
            app.numberofpixelsxEditFieldLabel = uilabel(app.ComputationalGridPanel);
            app.numberofpixelsxEditFieldLabel.Text = 'number of pixels x:';
            app.numberofpixelsxEditField = uieditfield(app.ComputationalGridPanel, 'numeric');
            app.numberofpixelsxEditField.Editable = 'off';
            app.numberofpixelsyEditFieldLabel = uilabel(app.ComputationalGridPanel);
            app.numberofpixelsyEditFieldLabel.Text = 'number of pixels y:';
            app.numberofpixelsyEditField = uieditfield(app.ComputationalGridPanel, 'numeric');
            app.numberofpixelsyEditField.Editable = 'off';
            app.numberofpixelszEditFieldLabel = uilabel(app.ComputationalGridPanel);
            app.numberofpixelszEditFieldLabel.Text = 'number of pixels z:';
            app.numberofpixelszEditField = uieditfield(app.ComputationalGridPanel, 'numeric');
            app.numberofpixelszEditField.Editable = 'off';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The PATLab stands for photoacoustic computed tomography (PAT) laboratory (Lab), which provides tools for simulation of PAT signals simulation and image reconstruction. '; 'This app is intended to facilitate research by researchers in the field.'; 'Customizations can be made based on the needs of researchers. '; 'Please refer to the user manual for more information about the software''s functionality and the algorithms used by the app.  '};
            app.UserManualButton = uibutton(app.HelpTab, 'push');
            app.UserManualButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualButtonPushed, true);
            app.UserManualButton.Text = {'User Manual'; ''};
            app.Panel = uipanel(app.PAT_ReconstructionInterface);
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SelectDetectorsButtonGroup = uibuttongroup(app.Panel);
            app.SelectDetectorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectDetectorsButtonGroupSelectionChanged, true);
            app.SelectDetectorsButtonGroup.Title = 'Select Detectors:';
            app.SelectedButton_2 = uiradiobutton(app.SelectDetectorsButtonGroup);
            app.SelectedButton_2.Text = 'Selected';
            app.selectedTransducersEditField_2 = uieditfield(app.SelectDetectorsButtonGroup, 'text');
            app.selectedTransducersEditField_2.Enable = 'off';
            app.selectedTransducersEditField_2.Tooltip = {'separate numbers with a space or comma'};
            app.selectedTransducersEditField_2.Value = '1';
            app.ArrayButton_2 = uiradiobutton(app.SelectDetectorsButtonGroup);
            app.ArrayButton_2.Text = 'Array';
            app.ArrayButton_2.Value = true;
            app.firstEditField_3Label = uilabel(app.SelectDetectorsButtonGroup);
            app.firstEditField_3Label.Text = 'first';
            app.firstEditField_3 = uieditfield(app.SelectDetectorsButtonGroup, 'numeric');
            app.firstEditField_3.Value = 1;
            app.intervalEditField_3Label = uilabel(app.SelectDetectorsButtonGroup);
            app.intervalEditField_3Label.Text = 'interval';
            app.intervalEditField_3 = uieditfield(app.SelectDetectorsButtonGroup, 'numeric');
            app.intervalEditField_3.Value = 1;
            app.lastEditField_3Label = uilabel(app.SelectDetectorsButtonGroup);
            app.lastEditField_3Label.Text = 'last';
            app.lastEditField_3 = uieditfield(app.SelectDetectorsButtonGroup, 'numeric');
            app.lastEditField_3.Value = 64;
            app.SelectFramesButtonGroup = uibuttongroup(app.Panel);
            app.SelectFramesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectFramesButtonGroupSelectionChanged, true);
            app.SelectFramesButtonGroup.Title = 'Select Frames:';
            app.SelectedButton = uiradiobutton(app.SelectFramesButtonGroup);
            app.SelectedButton.Text = 'Selected';
            app.SelectedButton.Value = true;
            app.selectedFramesEditField_2 = uieditfield(app.SelectFramesButtonGroup, 'text');
            app.selectedFramesEditField_2.Tooltip = {'separate numbers with a space or comma'};
            app.selectedFramesEditField_2.Value = '1';
            app.ArrayButton = uiradiobutton(app.SelectFramesButtonGroup);
            app.ArrayButton.Text = 'Array';
            app.firstEditField_4Label = uilabel(app.SelectFramesButtonGroup);
            app.firstEditField_4Label.Text = 'first';
            app.firstEditField_4 = uieditfield(app.SelectFramesButtonGroup, 'numeric');
            app.firstEditField_4.Enable = 'off';
            app.firstEditField_4.Value = 1;
            app.intervalEditField_4Label = uilabel(app.SelectFramesButtonGroup);
            app.intervalEditField_4Label.Text = 'interval';
            app.intervalEditField_4 = uieditfield(app.SelectFramesButtonGroup, 'numeric');
            app.intervalEditField_4.Enable = 'off';
            app.intervalEditField_4.Value = 1;
            app.lastEditField_4Label = uilabel(app.SelectFramesButtonGroup);
            app.lastEditField_4Label.Text = 'last';
            app.lastEditField_4 = uieditfield(app.SelectFramesButtonGroup, 'numeric');
            app.lastEditField_4.Enable = 'off';
            app.lastEditField_4.Value = 1000;
            app.SelectDataSamplesButtonGroup = uibuttongroup(app.Panel);
            app.SelectDataSamplesButtonGroup.Title = 'Select Data Samples:';
            app.firstLabel = uilabel(app.SelectDataSamplesButtonGroup);
            app.firstLabel.Text = 'first';
            app.firstEditField_5 = uieditfield(app.SelectDataSamplesButtonGroup, 'numeric');
            app.firstEditField_5.Value = 1;
            app.lastLabel = uilabel(app.SelectDataSamplesButtonGroup);
            app.lastLabel.Text = 'last';
            app.SamplesToEditField = uieditfield(app.SelectDataSamplesButtonGroup, 'numeric');
            app.SamplesToEditField.Value = 1000;
            app.intervalEditField_5Label_2 = uilabel(app.SelectDataSamplesButtonGroup);
            app.intervalEditField_5Label_2.Text = 'interval';
            app.intervalEditField_5 = uieditfield(app.SelectDataSamplesButtonGroup, 'numeric');
            app.intervalEditField_5.Value = 1;
            app.ArrayLabel = uilabel(app.SelectDataSamplesButtonGroup);
            app.ArrayLabel.Text = 'Array:';
            app.OptionsDropDown = uidropdown(app.Panel);
            app.OptionsDropDown.Items = {'Sinogram', 'Time Series', 'Detector Locations', 'Reconstructed Image'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Sinogram';
            app.OptionsDropDownData = uidropdown(app.Panel);
            app.OptionsDropDownData.Items = {'Raw Data', 'Cleaned Data', 'Normalized Data', 'Filtered Data', 'Downsampled Data', 'Matched Filtered Data'};
            app.OptionsDropDownData.Value = 'Raw Data';
            app.OptionsDropDownType = uidropdown(app.Panel);
            app.OptionsDropDownType.Items = {'Original Data', 'Inverse', 'Envelope', 'Positive', 'Negative', 'Absolute Value', 'Complex-Hilbert'};
            app.OptionsDropDownType.Value = 'Original Data';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Switch = uiswitch(app.Panel, 'toggle');
            app.Switch.Items = {'mat', 'nii'};
            app.Switch.Enable = 'off';
            app.Switch.Value = 'mat';
            % Show the figure after all components are created
            app.PAT_ReconstructionInterface.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PAT_ReconstructionInterface)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PAT_ReconstructionInterface)
        end
    end
end